classdef betta_debugged_fixed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QCTool                          matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        clearvariablesconversionMenu    matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        QCTab                           matlab.ui.container.Tab
        RemovalofleadingandtrailingsecondsPanel  matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        CheckBox                        matlab.ui.control.CheckBox
        CreateCopyEditField             matlab.ui.control.EditField
        CreateCopyEditFieldLabel        matlab.ui.control.Label
        CutButton                       matlab.ui.control.Button
        secondsLabel_2                  matlab.ui.control.Label
        trailsecseditfield              matlab.ui.control.NumericEditField
        RemovetrailingsecondsofeachfileCheckBox  matlab.ui.control.CheckBox
        secondsLabel                    matlab.ui.control.Label
        leadsecseditfield               matlab.ui.control.NumericEditField
        RemoveleadingsecondsofeachfileCheckBox  matlab.ui.control.CheckBox
        GetdatetimePanel_2              matlab.ui.container.Panel
        AgreeCheckBox                   matlab.ui.control.CheckBox
        RenametoStandardButton          matlab.ui.control.Button
        FiledateEditField_2             matlab.ui.control.EditField
        FiledateEditField_2Label        matlab.ui.control.Label
        TEXT_2                          matlab.ui.control.Label
        RemoveFlagsButton               matlab.ui.control.Button
        ViewLogFileButton               matlab.ui.control.Button
        FlagFilesButton                 matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        DutyCyclePanel                  matlab.ui.container.Panel
        DC_Text                         matlab.ui.control.Label
        IntervalsecEditField            matlab.ui.control.NumericEditField
        IntervalsecEditFieldLabel       matlab.ui.control.Label
        DutyCycleSwitch                 matlab.ui.control.Switch
        DutyCycleSwitchLabel            matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        DeploymentDatePickerLabel_3     matlab.ui.control.Label
        DeploymentDatePickerLabel_2     matlab.ui.control.Label
        Lamp_7                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        HourSpinner_2                   matlab.ui.control.Spinner
        HourSpinner_2Label              matlab.ui.control.Label
        HourSpinner                     matlab.ui.control.Spinner
        HourSpinnerLabel                matlab.ui.control.Label
        QCfilesButton                   matlab.ui.control.Button
        GetfromfirstfileButton          matlab.ui.control.Button
        FileDurationEditField           matlab.ui.control.NumericEditField
        FileDurationEditFieldLabel      matlab.ui.control.Label
        StationnameDropDown             matlab.ui.control.DropDown
        StationnameDropDownLabel        matlab.ui.control.Label
        RecoveryDatePicker              matlab.ui.control.DatePicker
        RecoveryDatePickerLabel         matlab.ui.control.Label
        DeploymentDatePicker            matlab.ui.control.DatePicker
        DeploymentDatePickerLabel       matlab.ui.control.Label
        RawDataButton                   matlab.ui.control.Button
        Copy2ArchiveTab                 matlab.ui.container.Tab
        ClearoriginaldataPanel          matlab.ui.container.Panel
        SURESwitch                      matlab.ui.control.ToggleSwitch
        SURESwitchLabel                 matlab.ui.control.Label
        Lamp_9                          matlab.ui.control.Lamp
        Lamp_8                          matlab.ui.control.Lamp
        DELETEButton                    matlab.ui.control.Button
        ViewTransferlogButton           matlab.ui.control.Button
        GetdatetimePanel                matlab.ui.container.Panel
        TEXT                            matlab.ui.control.Label
        FiledateEditField               matlab.ui.control.EditField
        FiledateEditFieldLabel          matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        ControlPanel                    matlab.ui.container.Panel
        Lamp_2                          matlab.ui.control.Lamp
        FiledateoffirstfileiscorrectCheckBox  matlab.ui.control.CheckBox
        COPYButton                      matlab.ui.control.Button
        SettingsPanel_2                 matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        TargetDirectoryDropDownLabel_2  matlab.ui.control.Label
        Lamp_5                          matlab.ui.control.Lamp
        TargetFolderDropDown            matlab.ui.control.DropDown
        TargetFolderDropDownLabel       matlab.ui.control.Label
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        SearchButton                    matlab.ui.control.Button
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        StationDropDown                 matlab.ui.control.DropDown
        StationDropDownLabel            matlab.ui.control.Label
        SeaDropDown                     matlab.ui.control.DropDown
        SeaDropDownLabel                matlab.ui.control.Label
        CalibrationTab                  matlab.ui.container.Tab
        SoundPressureinChamberEditField  matlab.ui.control.NumericEditField
        CalibrationFactordBEditField    matlab.ui.control.NumericEditField
        linearCalibrationFactorEditField  matlab.ui.control.NumericEditField
        ResultsPanel                    matlab.ui.container.Panel
        LinearCalibrationFactorVPaLabel  matlab.ui.control.Label
        CalibrationLeveldBre1VPaLabel   matlab.ui.control.Label
        SoundPressureinChamberdBLabel   matlab.ui.control.Label
        linearCalibrationFactorEditFieldLabel  matlab.ui.control.Label
        CalibrationFactordBEditFieldLabel  matlab.ui.control.Label
        SoundPressureinChamberEditFieldLabel  matlab.ui.control.Label
        InputwavandVoltagePanel         matlab.ui.container.Panel
        ChooseandComputeButton          matlab.ui.control.Button
        Lamp_12                         matlab.ui.control.Lamp
        RMSVoltageVEditFieldLabel_2     matlab.ui.control.Label
        Lamp_11                         matlab.ui.control.Lamp
        RMSVoltageVEditField            matlab.ui.control.NumericEditField
        RMSVoltageVEditFieldLabel       matlab.ui.control.Label
        Lamp_10                         matlab.ui.control.Lamp
        TargetDirectoryDropDownLabel_3  matlab.ui.control.Label
        SearchButton_2                  matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        BSoundHorPamguideTab            matlab.ui.container.Tab
        TEXT_9                          matlab.ui.control.Label
        TEXT_8                          matlab.ui.control.Label
        TEXT_7                          matlab.ui.control.Label
        TEXT_6                          matlab.ui.control.Label
        TEXT_5                          matlab.ui.control.Label
        TEXT_4                          matlab.ui.control.Label
        TEXT_3                          matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        OpenBSoundHButton               matlab.ui.control.Button
        h5ConverterTab                  matlab.ui.container.Tab
        savetemplateButton              matlab.ui.control.Button
        Exporthdf5Button                matlab.ui.control.Button
        MovetoWorkspaceButton           matlab.ui.control.Button
        LoadtemplateButton              matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        BSHformatTab                    matlab.ui.container.Tab
        BSHh5formatPanel                matlab.ui.container.Panel
        temporalstatsLabel              matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        Lamp_leq_temporalstats          matlab.ui.control.Lamp
        spectrotemporalstatsLabel       matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Lamp_leq_spectrotemporalstats   matlab.ui.control.Lamp
        dutycycleEditField              matlab.ui.control.EditField
        dutycycleEditField_2Label       matlab.ui.control.Label
        referencefrequencieslevelsEditField2  matlab.ui.control.NumericEditField
        Lamp_reference_frequencies_levels  matlab.ui.control.Lamp
        referencefrequencieslevelsEditField  matlab.ui.control.EditField
        referencefrequencieslevelsEditFieldLabel  matlab.ui.control.Label
        CreateButton                    matlab.ui.control.Button
        Lamp_recorder_type              matlab.ui.control.Lamp
        Lamp_recorder_manufacturer      matlab.ui.control.Lamp
        Lamp_name_measurement_position  matlab.ui.control.Lamp
        Lamp_measurement_setup          matlab.ui.control.Lamp
        Lamp_measurement_height         matlab.ui.control.Lamp
        Lamp_measurement_purpose        matlab.ui.control.Lamp
        Lamp_leq_temporal_values        matlab.ui.control.Lamp
        Lamp_leq_spectro_temporal_values  matlab.ui.control.Lamp
        Lamp_leq_datetime_index         matlab.ui.control.Lamp
        Lamp_leq_count                  matlab.ui.control.Lamp
        Lamp_leq_averaging_time         matlab.ui.control.Lamp
        Lamp_frequency_index            matlab.ui.control.Lamp
        Lamp_frequency_count            matlab.ui.control.Lamp
        Lamp_duty_cycle                 matlab.ui.control.Lamp
        Lamp_hydrophone_type            matlab.ui.control.Lamp
        Lamp_hydrophone_serial_number   matlab.ui.control.Lamp
        Lamp_hydrophone_sensitivity_unit  matlab.ui.control.Lamp
        Lamp_hydrophone_sensitivity     matlab.ui.control.Lamp
        Lamp_hydrophone_manufacturer    matlab.ui.control.Lamp
        Lamp_dataset_version            matlab.ui.control.Lamp
        Lamp_dataset_type               matlab.ui.control.Lamp
        Lamp_coordinates_reference      matlab.ui.control.Lamp
        Lamp_coordinates_measurement_position  matlab.ui.control.Lamp
        Lamp_comments                   matlab.ui.control.Lamp
        Lamp_calibration_procedure      matlab.ui.control.Lamp
        Lamp_calibration_level          matlab.ui.control.Lamp
        Lamp_calibration_factor_unit    matlab.ui.control.Lamp
        Lamp_calibration_factor         matlab.ui.control.Lamp
        Lamp_calibration_date           matlab.ui.control.Lamp
        Lamp_rawdata_uuid               matlab.ui.control.Lamp
        Lamp_rawdata_samplingrate       matlab.ui.control.Lamp
        Lamp_point_of_contact           matlab.ui.control.Lamp
        Lamp_measuring_institution      matlab.ui.control.Lamp
        Lamp_date_of_creation           matlab.ui.control.Lamp
        Lamp_recorder_serial_number     matlab.ui.control.Lamp
        Lamp_author                     matlab.ui.control.Lamp
        recordertypeEditField           matlab.ui.control.EditField
        recordertypeEditFieldLabel      matlab.ui.control.Label
        recorderserialnumberEditField   matlab.ui.control.EditField
        recorderserialnumberEditFieldLabel  matlab.ui.control.Label
        recordermanufacturerEditField   matlab.ui.control.EditField
        recordermanufacturerEditFieldLabel  matlab.ui.control.Label
        namemeasurementpositionEditField  matlab.ui.control.EditField
        namemeasurementpositionEditFieldLabel  matlab.ui.control.Label
        measurementsetupDropDown        matlab.ui.control.DropDown
        measurementsetupDropDownLabel   matlab.ui.control.Label
        measurementheightEditField      matlab.ui.control.NumericEditField
        measurementheightEditFieldLabel  matlab.ui.control.Label
        measurementpurposeDropDown      matlab.ui.control.DropDown
        measurementpurposeDropDownLabel  matlab.ui.control.Label
        leqtemporalvaluesLabel          matlab.ui.control.Label
        GetButton_4                     matlab.ui.control.Button
        leqspectrotemporalvaluesLabel   matlab.ui.control.Label
        GetButton_3                     matlab.ui.control.Button
        leqdatetimeindexLabel           matlab.ui.control.Label
        GetButton_2                     matlab.ui.control.Button
        leqcountEditField               matlab.ui.control.NumericEditField
        leqcountEditFieldLabel          matlab.ui.control.Label
        leqaveragingtimeEditField       matlab.ui.control.NumericEditField
        leqaveragingtimeEditFieldLabel  matlab.ui.control.Label
        frequencycountEditFieldLabel_2  matlab.ui.control.Label
        GetButton                       matlab.ui.control.Button
        frequencycountEditField         matlab.ui.control.NumericEditField
        frequencycountEditFieldLabel    matlab.ui.control.Label
        hydrophonetypeEditField         matlab.ui.control.EditField
        hydrophonetypeEditFieldLabel    matlab.ui.control.Label
        hydrophoneserialnumberEditField  matlab.ui.control.EditField
        hydrophoneserialnumberEditFieldLabel  matlab.ui.control.Label
        hydrophonesensitivityunitEditField  matlab.ui.control.EditField
        hydrophonesensitivityunitEditFieldLabel  matlab.ui.control.Label
        hydrophonesensitivityEditField  matlab.ui.control.NumericEditField
        hydrophonesensitivityEditFieldLabel  matlab.ui.control.Label
        hydrophonemanufacturerEditField  matlab.ui.control.EditField
        hydrophonemanufacturerEditFieldLabel  matlab.ui.control.Label
        datasetversionEditField         matlab.ui.control.EditField
        datasetversionEditFieldLabel    matlab.ui.control.Label
        datasettypeEditField            matlab.ui.control.EditField
        datasettypeEditFieldLabel       matlab.ui.control.Label
        coordinatesreferenceEditField   matlab.ui.control.EditField
        coordinatesreferenceEditFieldLabel  matlab.ui.control.Label
        coordinatesmeasurementpositionEditField  matlab.ui.control.EditField
        coordinatesmeasurementpositionEditFieldLabel  matlab.ui.control.Label
        commentsEditField               matlab.ui.control.EditField
        commentsEditFieldLabel          matlab.ui.control.Label
        calibrationfactorEditField      matlab.ui.control.NumericEditField
        calibrationfactorEditFieldLabel  matlab.ui.control.Label
        calibrationprocedureEditField   matlab.ui.control.EditField
        calibrationprocedureEditFieldLabel  matlab.ui.control.Label
        calibrationlevelEditField       matlab.ui.control.NumericEditField
        calibrationlevelEditFieldLabel  matlab.ui.control.Label
        calibraionfactorunitEditField   matlab.ui.control.EditField
        calibraionfactorunitEditFieldLabel  matlab.ui.control.Label
        calibrationdateDatePicker       matlab.ui.control.DatePicker
        calibrationdateDatePickerLabel  matlab.ui.control.Label
        rawdatauuidEditField            matlab.ui.control.EditField
        rawdatauuidEditFieldLabel       matlab.ui.control.Label
        rawdatasamplingrateEditField    matlab.ui.control.NumericEditField
        rawdatasamplingrateEditFieldLabel  matlab.ui.control.Label
        pointofcontactEditField         matlab.ui.control.EditField
        pointofcontactEditFieldLabel    matlab.ui.control.Label
        measuringinstitutionEditField   matlab.ui.control.EditField
        measuringinstitutionEditFieldLabel  matlab.ui.control.Label
        dateofcreationDatePicker        matlab.ui.control.DatePicker
        dateofcreationDatePickerLabel   matlab.ui.control.Label
        authorEditField                 matlab.ui.control.EditField
        authorEditFieldLabel            matlab.ui.control.Label
        ICESformatTab                   matlab.ui.container.Tab
        ICEShdf5formatPanel             matlab.ui.container.Panel
        ICES_Lamp_LeqMatrix             matlab.ui.control.Lamp
        ICES_SelectLeqMatrixButton      matlab.ui.control.Button
        ICES_LeqMatrixLabel             matlab.ui.control.Label
        ICES_HydrophoneSerialNumberEditField  matlab.ui.control.EditField
        HydrophoneSerialNumberEditFieldLabel_2  matlab.ui.control.Label
        ICES_HydrophoneTypeDropDown     matlab.ui.control.DropDown
        HydrophoneTypeDropDownLabel     matlab.ui.control.Label
        ICES_Lamp_DateTime              matlab.ui.control.Lamp
        ICES_DateTimeLabel              matlab.ui.control.Label
        ICES_SelectDatetimeVectorButton  matlab.ui.control.Button
        ICES_CommentsEditField          matlab.ui.control.TextArea
        CommentsTextAreaLabel           matlab.ui.control.Label
        ICES_TimeEditField_4            matlab.ui.control.EditField
        TimeEditField_4Label            matlab.ui.control.Label
        ICES_CalibrationDatetimeDatePicker  matlab.ui.control.DatePicker
        CalibrationDatetimeDatePickerLabel  matlab.ui.control.Label
        ICES_MeasurementTotalNoEditField  matlab.ui.control.NumericEditField
        MeasurementTotalNoEditFieldLabel  matlab.ui.control.Label
        ICES_CalibrationProcedureDropDown  matlab.ui.control.DropDown
        CalibrationProcedureDropDownLabel  matlab.ui.control.Label
        ICES_DatasetVersionSpinner      matlab.ui.control.Spinner
        DatasetVersionSpinnerLabel      matlab.ui.control.Label
        ICES_DataUUIDEditField          matlab.ui.control.EditField
        DataUUIDEditFieldLabel          matlab.ui.control.Label
        ICES_ProcessingAlgorithmDropDown  matlab.ui.control.DropDown
        ProcessingAlgorithmDropDownLabel  matlab.ui.control.Label
        ICES_AveragingTimeSpinner       matlab.ui.control.Spinner
        AveragingTimeSpinnerLabel       matlab.ui.control.Label
        ICES_MeasurementUnitDropDown    matlab.ui.control.DropDown
        MeasurementUnitDropDownLabel    matlab.ui.control.Label
        ICES_ChannelCountSpinner        matlab.ui.control.Spinner
        ChannelCountSpinnerLabel        matlab.ui.control.Label
        ICES_FrequencyUnitDropDown      matlab.ui.control.DropDown
        FrequencyUnitDropDownLabel      matlab.ui.control.Label
        ICES_fromfileButton_2           matlab.ui.control.Button
        ICES_Lamp_Comments              matlab.ui.control.Lamp
        ICES_Lamp_CalibrationDatetime   matlab.ui.control.Lamp
        ICES_Lamp_CalibrationProcedure  matlab.ui.control.Lamp
        ICES_Lamp_DatasetVersion        matlab.ui.control.Lamp
        ICES_Lamp_DataUUID              matlab.ui.control.Lamp
        ICES_Lamp_ProcessingAlgorithm   matlab.ui.control.Lamp
        ICES_Lamp_AveragingTime         matlab.ui.control.Lamp
        ICES_Lamp_MeasurementUnit       matlab.ui.control.Lamp
        ICES_Lamp_MeasurementTotalNo    matlab.ui.control.Lamp
        ICES_Lamp_ChannelCount          matlab.ui.control.Lamp
        ICES_Lamp_FrequencyUnit         matlab.ui.control.Lamp
        ICES_CreateUUIDButton           matlab.ui.control.Button
        ICES_FrequencyCountSpinner      matlab.ui.control.Spinner
        FrequencyCountSpinnerLabel      matlab.ui.control.Label
        ICES_RigDesignDropDown          matlab.ui.control.DropDown
        RigDesignDropDownLabel          matlab.ui.control.Label
        ICES_MeasurementSetupDropDown   matlab.ui.control.DropDown
        MeasurementSetupDropDownLabel   matlab.ui.control.Label
        ICES_MeasurementPurposeDropDown  matlab.ui.control.DropDown
        MeasurementPurposeDropDownLabel  matlab.ui.control.Label
        ICES_MeasurementHeightEditField  matlab.ui.control.EditField
        MeasurementHeightEditFieldLabel  matlab.ui.control.Label
        ICES_RecorderSerialNumberEditField  matlab.ui.control.EditField
        RecorderSerialNumberEditFieldLabel  matlab.ui.control.Label
        ICES_RecorderTypeDropDown       matlab.ui.control.DropDown
        RecorderTypeDropDownLabel       matlab.ui.control.Label
        ICES_fromfileButton             matlab.ui.control.Button
        ICES_FrequencyIndexPanel        matlab.ui.container.Panel
        fileButton                      matlab.ui.control.Button
        LoadFrequencyBandsfromCheckBox  matlab.ui.control.CheckBox
        FrequencyIndexEditField         matlab.ui.control.EditField
        HighestTOBDropDown              matlab.ui.control.DropDown
        HighestTOBDropDownLabel         matlab.ui.control.Label
        LowestTOBDropDown               matlab.ui.control.DropDown
        LowestTOBDropDownLabel          matlab.ui.control.Label
        consecutiveThirdOctaveBandsCheckBox  matlab.ui.control.CheckBox
        individualFrequencyBandsCheckBox  matlab.ui.control.CheckBox
        ICES_Lamp_FrequencyIndex        matlab.ui.control.Lamp
        ICES_Lamp_FrequencyCount        matlab.ui.control.Lamp
        ICES_Lamp_RigDesign             matlab.ui.control.Lamp
        ICES_Lamp_MeasurementSetup      matlab.ui.control.Lamp
        ICES_Lamp_MeasurementPurpose    matlab.ui.control.Lamp
        ICES_Lamp_MeasurementHeight     matlab.ui.control.Lamp
        ICES_Lamp_RecorderSerialNumber  matlab.ui.control.Lamp
        ICES_Lamp_RecorderType          matlab.ui.control.Lamp
        ICES_Lamp_HydrophoneSerialNumber  matlab.ui.control.Lamp
        ICES_Lamp_HydrophoneType        matlab.ui.control.Lamp
        ICES_EndTimeEditField           matlab.ui.control.EditField
        ICES_StartTimeEditField         matlab.ui.control.EditField
        ICES_CreationTimeEditField      matlab.ui.control.EditField
        ICES_SelectDropDown_StationCode  matlab.ui.control.DropDown
        SelectDropDown_2Label           matlab.ui.control.Label
        ICES_StationCodeEditField       matlab.ui.control.EditField
        StationCodeEditFieldLabel       matlab.ui.control.Label
        ICES_SelectDropDown             matlab.ui.control.DropDown
        SelectDropDownLabel             matlab.ui.control.Label
        ICES_InstitutionEditField       matlab.ui.control.EditField
        InstitutionEditFieldLabel       matlab.ui.control.Label
        ICES_CountryCodeDropDown        matlab.ui.control.DropDown
        CountryCodeDropDownLabel        matlab.ui.control.Label
        ICES_ContactEditField           matlab.ui.control.EditField
        ContactEditFieldLabel           matlab.ui.control.Label
        ICES_EndDateDatePicker          matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        ICES_StartDateDatePicker        matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        ICES_CreationDateDatePicker     matlab.ui.control.DatePicker
        CreationDateDatePickerLabel     matlab.ui.control.Label
        ICES_EmailEditField             matlab.ui.control.EditField
        EmailEditFieldLabel             matlab.ui.control.Label
        ICES_Lamp_StationCode           matlab.ui.control.Lamp
        ICES_Lamp_CountryCode           matlab.ui.control.Lamp
        ICES_Lamp_Contact               matlab.ui.control.Lamp
        ICES_Lamp_Institution           matlab.ui.control.Lamp
        ICES_Lamp_EndDate               matlab.ui.control.Lamp
        ICES_Lamp_StartDate             matlab.ui.control.Lamp
        ICES_Lamp_CreationDate          matlab.ui.control.Lamp
        ICES_Lamp_Email                 matlab.ui.control.Lamp
        ICES_TimeEditField_3Label       matlab.ui.control.Label
        ICES_StartTimeEditField_2Label  matlab.ui.control.Label
        ICES_CreationTimeEditFieldLabel  matlab.ui.control.Label
        ImportBSoundHOutputfilesButton  matlab.ui.control.Button
        SpectralQCTab                   matlab.ui.container.Tab
        RUNButton_2                     matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        figCheckBox                     matlab.ui.control.CheckBox
        matCheckBox                     matlab.ui.control.CheckBox
        pdfCheckBox                     matlab.ui.control.CheckBox
        EditField                       matlab.ui.control.EditField
        SelectOutputpathButton          matlab.ui.control.Button
        QualityChecksPanel              matlab.ui.container.Panel
        selectallCheckBox_2             matlab.ui.control.CheckBox
        LogicalCheckBox                 matlab.ui.control.CheckBox
        PlausibilityCheckBox            matlab.ui.control.CheckBox
        AutoCorrelationCheckBox_2       matlab.ui.control.CheckBox
        NoiseFloorCheckBox_2            matlab.ui.control.CheckBox
        ThresholdsMinMaxCheckBox        matlab.ui.control.CheckBox
        StationarityCheckBox            matlab.ui.control.CheckBox
        SelectAnalysesPanel             matlab.ui.container.Panel
        selectallCheckBox               matlab.ui.control.CheckBox
        AcousticMetricsCheckBox         matlab.ui.control.CheckBox
        TonalsCheckBox                  matlab.ui.control.CheckBox
        NoiseFloorCheckBox              matlab.ui.control.CheckBox
        ViolinsCheckBox                 matlab.ui.control.CheckBox
        FAVCheckBox                     matlab.ui.control.CheckBox
        ATLintermittentCheckBox         matlab.ui.control.CheckBox
        ATLshippingCheckBox             matlab.ui.control.CheckBox
        AutoCorrelationCheckBox         matlab.ui.control.CheckBox
        SpectrogramCheckBox             matlab.ui.control.CheckBox
        PDSCheckBox_2                   matlab.ui.control.CheckBox
        InputPanel                      matlab.ui.container.Panel
        h5formatDropDown                matlab.ui.control.DropDown
        Removehdf5filesButton           matlab.ui.control.Button
        Addhdf5filesButton              matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
    end


    properties (Access = private)
        path = 'X:\Meereskunde\Unterwasserschall-Archiv\Import02\TEST\'% Path to raw .wav files
        qc_path = 'X:\Meereskunde\Unterwasserschall\AMSO23\NORDSEE\ES1\QC\' % path, where qc-log as pdf should be stored
        DateDeployment = datetime(2013,12,1);
        DateDeploymentHour = 0;
        DateRecovery = datetime(now,'ConvertFrom','datenum');
        DateRecoveryHour = 0;
        station = 'ES1';
        filedur = 0;
        DC = 'No';
        interval = 0;
        duration
        meanv
        sr
        stdrms
        ts
        ftime
        QC
        sea = 'NORDSEE' % Description
        channel = 'H1' % Description
        sourcedir % Description
        targetdir = 'Y:\Meereskunde\UWS\Dauer\' % Description
        yy
        mm
        dd% Description
        log % Description
        libpath = addpath('./libs') % adds path to index picture
        TL %Transferlog
        
        Jahr
        Monat
        Tag % Description
        Stunde
        Minute
        Sekunde % Description
        dtf % Description
        cal_signal % Signal of Calibration .wav file
        cal_sr % Description
        cal_start
        PAT % Description
        cal_valsig % valid calibration signal
        cal_volt % Calibration Voltage @125Hz
        cal_path % path to calibration file
        copypath = 'null'% path where copy of files is saved where leading (and/or trailing) seconds are removed
        leadsecs = 0 % Number of seconds to be removed at the start of each .wav file
        trailsecs = 0 % Number of seconds to be removed at the end of each .wav file

    end
    
    properties (Access = public)
                bsh_calibration_factor % BSH format field calibration factor in Pa
        bsh_calibration_level % BSH format field for calibration level in dB
        bsh_frequency_count % BSH format field for frequency count
        bsh_frequency_index % BSH format field for frequency index
        bsh_leq_count % BSH format field for leq count
        bsh_datetime_index % BSH format field for datetime index
        % BSH format fields for spectral temporal percentiles (not included
        % in app
        bsh_spectral_stats_L01
        bsh_spectral_stats_L05
        bsh_spectral_stats_L50
        bsh_spectral_stats_L90
        bsh_spectral_stats_L99
        bsh_spectral_stats_LMax
        bsh_spectral_stats_LMean
        bsh_spectral_stats_LMin 
        bsh_leq_spectro_temporal_values % BSH format field for leq spectro temporal values
        bsh_temporal_stats_L01
        bsh_temporal_stats_L05
        bsh_temporal_stats_L50
        bsh_temporal_stats_L90
        bsh_temporal_stats_L99
        bsh_temporal_stats_LMax
        bsh_temporal_stats_LMean
        bsh_temporal_stats_LMin % BSH format fields for temporal percentiles
        bsh_leq_temporal_values % BSH format fields for temporal levels
        bsh_rawdata_samplingrate % Description
        %% fields that need to be entered manually or loaded from template
        bsh_author % BSH format field for creator of hdf5 file
        bsh_date_of_creation % BSH format field for date of creation
        bsh_measuring_institution % BSH format field for measuring institution
        bsh_point_of_contact % BSH format field for point of contact
        bsh_rawdata_uuid % BSH format field
        bsh_calibration_date % BSH format field
        bsh_calibration_factor_unit % BSH format field
        bsh_calibration_procedure % BSH format field
        bsh_reference_frequencies_levels = nan(1,2); % BSH format field
        bsh_comments % BSH format field
        bsh_datasettype % BSH format field
        bsh_coordinatesreference % BSH format field
        bsh_coordinatesmeasurementposition % BSH format field
        bsh_datasetversion % BSH format field
        bsh_hydrophone_manufacturer % BSH format field
        bsh_hydrophone_sensitivity % BSH format field
        bsh_hydrophone_sensitivity_unit % BSH format field
        bsh_hydrophone_serial_number % BSH format field
        bsh_hydrophone_type % BSH format field
        bsh_duty_cycle % BSH format field
        bsh_leq_averaging_time % BSH format field
        bsh_measurement_purpose % BSH format field
        bsh_measurement_setup % BSH format field
        bsh_name_measurement_position % BSH format field
        bsh_recorder_manufacturer % BSH format field
        bsh_recorder_serialnumber % BSH format field
        bsh_recorder_type % BSH format field
        bsh_measurement_height % BSH format field
        ICES_StartDate % ICES format field
        ICES_EndDate % ICES format field
        ICES_Institution % ICES format field
        ICES_CountryCode % ICES format field
        ICES_ChannelCount % ICES format field
        ICES_MeasurementUnit % ICES format field
        ICES_ProcessingAlgorithm % ICES format field
        ICES_DataUUID % ICES format field
        ICES_DatasetVersion % ICES format field
        ICES_CalibrationProcedure % ICES format field
        ICES_FrequencyUnit % ICES format field
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RawDataButton
        function RawDataButtonPushed(app, event)
            filepath = uigetdir('X:\Meereskunde\Unterwasserschall-Archiv\');
            drawnow;
            figure(app.QCTool)
            if filepath(end) ~= '\'
                filepath = append(filepath,'\');
            end
            %             assignin('base',"path",path)
            app.path = filepath;
            set(app.RemoveFlagsButton, 'Enable', 'on')
            app.Lamp_6.Color = 'g';
            
        end

        % Value changed function: DeploymentDatePicker
        function DeploymentDatePickerValueChanged(app, event)
            app.DateDeployment = app.DeploymentDatePicker.Value;
            %             assignin('base',"DateDeployment",DateDeployment)
            %             app.DateDeployment = DateDeployment;
        end

        % Value changed function: StationnameDropDown
        function StationnameDropDownValueChanged(app, event)
            station = app.StationnameDropDown.Value;
            %             assignin('base',"station",station)
            app.station = station;
        end

        % Button pushed function: QCfilesButton
        function QCfilesButtonPushed(app, event)
            qc_path = uigetdir('X:\Meereskunde\Unterwasserschall\AMSO23\');
            drawnow;
            figure(app.QCTool)
            %            assignin('base',"qc_path",qc_path)
            if qc_path(end) ~= '\'
                qc_path = append(qc_path,'\');
            end
            app.qc_path = qc_path;
            app.Lamp_7.Color = 'g';
        end

        % Callback function
        function PathEditFieldValueChanged(app, event)
            filepath = app.PathEditField.Value;
            %                 assignin('base',"path",path);
            if filepath(end) ~= '\'
                filepath = append(filepath,'\');
            end
            app.path = path;
        end

        % Callback function
        function QClogpathEditFieldValueChanged(app, event)
            qc_path = app.QClogpathEditField.Value;
            %             assignin('base',"qc_path",qc_path)
            if qc_path(end) ~= '\'
                qc_path = append(qc_path,'\');
            end
            app.qc_path = qc_path;
        end

        % Value changed function: RecoveryDatePicker
        function RecoveryDatePickerValueChanged(app, event)
            DateRecovery = app.RecoveryDatePicker.Value;
            %             assignin('base',"DateDeployment",DateDeployment)
            app.DateRecovery = DateRecovery;
        end

        % Value changed function: FileDurationEditField
        function FileDurationEditFieldValueChanged(app, event)
            filedur = app.FileDurationEditField.Value;
            %             assignin('base',"filedur",filedur)
            app.filedur = filedur;
        end

        % Button pushed function: GetfromfirstfileButton
        function GetfromfirstfileButtonPushed(app, event)
%             flist = dir([app.path '*.wav']);
            % update 22.03.24 also accept files truncated in subdirectories
            % (Sylence)
            flist = dir(fullfile(app.path, '**\*.wav'));  %get list of files and folders in any subfolder
            flist = flist(~[flist.isdir]); 
            for ii = 1:length(flist)
                filedur = audioinfo([flist(ii).folder '\' flist(ii).name]).Duration;
                if filedur < 540
                    continue
                else
                    app.filedur = filedur;
                    break
                end
            end
            %           filedur = audioinfo([app.path flist(1).name]).Duration;
            %             assignin('base',"filedur",filedur)
            
            app.FileDurationEditField.Value = filedur;
            %             app.filedur=filedur;
            %             filedur = app.FileDurationEditField.Value;
        end

        % Value changed function: HourSpinner
        function HourSpinnerValueChanged(app, event)
            value = app.HourSpinner.Value;
            app.DateDeploymentHour = value;
        end

        % Value changed function: HourSpinner_2
        function HourSpinner_2ValueChanged(app, event)
            value = app.HourSpinner_2.Value;
            app.DateRecoveryHour = value;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            DateD = datetime(app.DateDeployment)+hours(app.DateDeploymentHour);
            DateR = datetime(app.DateRecovery)+hours(app.DateRecoveryHour);
            disp('Now performing Quality Control ...')
            [app.QC,app.duration,app.meanv,app.sr,app.stdrms,app.ts,app.ftime] = QualityChecks(app.station,app.path,app.qc_path,DateD,DateR,app.filedur,app.interval,app.Jahr,app.Monat,app.Tag,app.Stunde,app.Minute,app.Sekunde,app.dtf);
            
            disp('Writing results to output pdf ... ')
            [app.log] = qc2pdf(app.QC,app.duration,app.meanv,app.sr,app.stdrms,app.ts,app.ftime,app.path,app.qc_path,app.station,DateD,DateR,app.filedur,app.interval)
            set(app.ViewLogFileButton, 'Enable', 'on')
            set(app.FlagFilesButton, 'Enable', 'on')
            
        end

        % Value changed function: DutyCycleSwitch
        function DutyCycleSwitchValueChanged(app, event)
            value = app.DutyCycleSwitch.Value;
            app.DC = value;
            if ( strcmpi(value,'Yes') )
                app.IntervalsecEditField.Enable = 'On';
                app.IntervalsecEditField.Editable = 'On';
            else
                app.IntervalsecEditField.Enable = 'Off';
                app.IntervalsecEditField.Editable = 'Off';
            end
        end

        % Callback function
        function ProcessButtonPushed(app, event)
            
        end

        % Callback function
        function FileDurationEditField_2ValueChanged(app, event)
            value = app.FileDurationEditField_2.Value;
            app.filedur = value;
        end

        % Value changed function: IntervalsecEditField
        function IntervalsecEditFieldValueChanged(app, event)
            value = app.IntervalsecEditField.Value;
            if value <= app.filedur
                errordlg('Interval must be larger than individual file duration')
            else
                app.interval = value;
                app.DC_Text.Text = ['Record for a period of ' num2str(app.filedur/60) ' min. once every ' num2str(app.interval/60)  ' min.']
            end
            
        end

        % Value changed function: SeaDropDown
        function SeaDropDownValueChanged(app, event)
            value = app.SeaDropDown.Value;
            if strcmp(value,'Select Sea') == 0
                app.sea = value
                app.Lamp_3.Color = 'g';
            end
        end

        % Value changed function: StationDropDown
        function StationDropDownValueChanged(app, event)
            value = app.StationDropDown.Value;
            if strcmp(value,'Select Station') == 0
                app.station = value;
                app.Lamp_4.Color = 'g';
            end
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            if strcmp(value,'Select Channel') == 0
                app.channel = value;
                app.Lamp_5.Color = 'g';
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.sourcedir = uigetdir('X:\Meereskunde\Unterwasserschall-Archiv\Import02\');
            app.sourcedir = [app.sourcedir '\'];
            drawnow;
            figure(app.QCTool)
            app.Lamp.Color = 'g';
             %% enable
            app.FiledateEditField.Enable = 'On';
            app.FiledateEditField.Editable = 'On';
        end

        % Callback function
        function SettargetdirectoryButtonPushed(app, event)
            f=warndlg('If in any doubt please use the SPACESNIFFER to check if enough memory is available in the target directory!')
            waitfor(f);
            app.targetdir = uigetdir('X:\Meereskunde\Unterwasserschall-Archiv\Data10\');
            drawnow;
            figure(app.QCTool)
            app.Lamp2.Color = 'g';
            %% enable
            app.FiledateEditField.Enable = 'On';
            app.FiledateEditField.Editable = 'On';
        end

        % Value changed function: FiledateEditField
        function FiledateEditFieldValueChanged(app, event)
            value = app.FiledateEditField.Value;
            y = strfind(value,'yy');
            m = strfind(value,'mm');
            d = strfind(value,'dd');
            flist = dir([app.sourcedir '*.wav']);
            yy = flist(1).name(y:y+1);
            mm = flist(1).name(m:m+1);
            dd = flist(1).name(d:d+1);
            textLabel = [{'The data of the first file in the source directory is:'},...
                {[dd,'.',mm,'.20',yy]}];
            app.TEXT.Text = textLabel;
            app.yy = y;
            app.mm = m;
            app.dd = d;
            
        end

        % Button pushed function: COPYButton
        function COPYButtonPushed(app, event)
            f = warndlg('Are you really sure you want to start copying ??? This might impair your network performance!');
            waitfor(f)
            drawnow;
            figure(app.QCTool)
            status = copycheck(app.sourcedir,app.targetdir,app.sea,app.station,app.channel);
            if status == 1
                app.TL = C2A(app.sea,app.station,app.channel,app.sourcedir,app.targetdir,app.yy,app.mm,app.dd)
                app.ViewTransferlogButton.Enable = 'On';
            else
%                 h = warndlg('One or more files are allreay existent in target directory!')
%                 waitfor(h)
                choice = questdlg('One or more files are allreay existent in target directory! Please make sure which files cause this problem!', ...
                  'Warning', ...
                  'Cancel', 'Proceed anyway (files will be suffixed (1))','Skip existent files','Cancel' );
                switch choice
                    case 'Cancel'
                        disp('Kopiervorgang abgebrochen');
                        % nothing happens
                    case 'Proceed anyway (files will be suffixed (1))'
                        disp('Files are copied although files with same filename exist in target directory. All new files will be marked as XXX_yymmdddHHMMSS(1).wav or .wavx');
                        % call new C2A script, that appends (1) to files,
                        % showing that these files were added to the
                        % archive later
                        app.TL = C2A_1(app.sea,app.station,app.channel,app.sourcedir,app.targetdir,app.yy,app.mm,app.dd) %new function
                        app.ViewTransferlogButton.Enable = 'On';
                    case 'Skip existent files'
                        disp('Files that already exist in the archive are skipped - all remaining files will be copied.');
                        % call new C2A script, that skips gleichnamige
                        % files
                        app.TL = C2A_2(app.sea,app.station,app.channel,app.sourcedir,app.targetdir,app.yy,app.mm,app.dd); %new function
                        app.ViewTransferlogButton.Enable = 'On';
                end

            end
            
        end

        % Value changed function: FiledateoffirstfileiscorrectCheckBox
        function FiledateoffirstfileiscorrectCheckBoxValueChanged(app, event)
            value = app.FiledateoffirstfileiscorrectCheckBox.Value;
            if value
                app.Lamp_2.Color = 'g';
                app.COPYButton.Enable = 'On';
            else
                app.Lamp_2.Color = 'r';
                app.COPYButton.Enable = 'Off';
            end
        end

        % Button pushed function: ViewLogFileButton
        function ViewLogFileButtonPushed(app, event)
            %             rptview(app.log,'pdf')
            winopen(app.log)
        end

        % Button pushed function: FlagFilesButton
        function FlagFilesButtonPushed(app, event)
            addQCprefix(app.QC,app.path)
        end

        % Button pushed function: RemoveFlagsButton
        function RemoveFlagsButtonPushed(app, event)
%             removeQCprefix(app.path,app.yy,app.mm,app.dd,app.HH,app.MM,app.SS,app.station)
                umbenenner2(app.path)
        end

        % Image clicked function: Image3
        function SpaceSnifferButtonPushed(app, event)
            ! X:\Meereskunde\Unterwasserschall\Software\SpaceSniffer.exe
        end

        % Value changed function: TargetFolderDropDown
        function TargetFolderDropDownValueChanged(app, event)
            value = app.TargetFolderDropDown.Value;
            app.targetdir = ['X:\Meereskunde\Unterwasserschall-Archiv\' value];
            f = warndlg('Please ensure, that enough memory is available in the target directory. Use the SPACEScniffer to the right.')
            waitfor(f);
            if ~isequal(value,'Select...')
                app.Lamp2.Color = 'g';
            else
                app.Lamp2.Color = 'r';
                app.COPYButton.Enable = 'Off';
                app.FiledateEditField.Enable = 'Off';
            end
            app.FiledateEditField.Enable = 'On';
            app.FiledateEditField.Editable = 'On';
        end

        % Button pushed function: ViewTransferlogButton
        function ViewTransferlogButtonPushed(app, event)
            winopen(app.TL)
            app.Lamp_8.Color = 'g';
            app.SURESwitch.Enable = 'On';
            
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
        end

        % Callback function
        function Switch_4ValueChanged(app, event)
            
        end

        % Value changed function: SURESwitch
        function SURESwitchValueChanged(app, event)
            value = app.SURESwitch.Value;
            if isequal(value,'Yes')
                app.Lamp_9.Color = 'y';
                app.DELETEButton.Enable = 'On';
            else
                app.Lamp_9.Color = 'r';
                app.DELETEButton.Enable = 'Off';
            end
        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
            clear_import(app.sourcedir,app.targetdir,app.sea,app.station,app.channel,app.yy,app.mm,app.dd)
        end

        % Button pushed function: SearchButton_2
        function SearchButton_2Pushed(app, event)
            [file,app.cal_path] = uigetfile({'*wav','Audio Files (*wav)';'*.*','All Files (*.*)'}, ...
                'Select File','X:\Meereskunde\Unterwasserschall\AMSO23');
            drawnow;
            figure(app.QCTool)
            app.Lamp_10.Color = 'g';
            if app.Lamp_11.Color == [0 1 0] & app.Lamp_10.Color == [0 1 0]
                app.ChooseandComputeButton.Enable = 'On'
            end
            app.cal_signal = audioread([app.cal_path file]);
            info = audioinfo([app.cal_path file]);
            app.cal_sr=info.SampleRate;
            xwerte = [1:length(app.cal_signal)]/app.cal_sr;
            plot(app.UIAxes,xwerte(1:100:end),app.cal_signal(1:100:end));
            %             h=msgbox('Please click a valid start end time of the calibation signal in the graphic!');
            %             waitfor(h)
            
            
            %             % Set up figure handle visibility, run ginput, and return state
            %             fhv = app.QCTool.HandleVisibility;        % Current status
            %             app.QCTool.HandleVisibility = 'callback'; % Temp change (or, 'on')
            %             set(0, 'CurrentFigure', app.QCTool)       % Make fig current
            %             [gin_x,~] = ginput(1);
            %             app.PAT = patch(app.UIAxes,[gin_x(1) gin_x(1) gin_x(1)+30 gin_x(1)+30],[-1 1 1 -1],'m','facealpha',.2,'edgecolor','none');
            %             app.cal_start = gin_x(1);
            %             cal_sample_start = gin_x(1)*app.cal_sr;
            %             cal_sample_end = (gin_x(1)+30)*app.cal_sr;
            %             app.cal_valsig = app.cal_signal(cal_sample_start:cal_sample_end);
            %
            %                                  % run ginput
            %             app.QCTool.HandleVisibility = fhv;        % return original state
            %             app.ChooseandComputeButton.Enable = 'On';
            %             sample_t        = [1:length(app.cal_signal)]/cal_sr; %s                  % SampleZeit
            %             start_sample    = floor(gin_x(1)/cal_sr);        % start of valid signal in s
            %             stop_sample     =  ceil(gin_x(2)/cal_sr);       % end of valid signal in s
            %
            %             time_vector=sample_t(start_sample:stop_sample);
            %             data_vector=app.cal_signal(start_sample:stop_sample);
            %
            %             plot(app.UIAxes,time_vector,data_vector,'r');
            
        end

        % Button pushed function: OpenBSoundHButton
        function OpenBSoundHButtonPushed(app, event)
            ! C:\SOUND\env379\Scripts\python.exe C:\SOUND\env\Lib\site-packages\bsoundh\app.py
        end

        % Value changed function: FiledateEditField_2
        function FiledateEditField_2ValueChanged(app, event)
            value = app.FiledateEditField_2.Value;
            y = strfind(value,'yy');
            m = strfind(value,'MM');
            d = strfind(value,'dd');
            H = strfind(value,'HH');
            M = strfind(value,'mm');
            S = strfind(value,'ss');
            % update 22.03.24 - accept data truncated in subdirectories
            flist = dir(fullfile(app.path, '**\*.wav'));  %get list of files and folders in any subfolder
            flist = flist(~[flist.isdir]); 
%             flist = dir([app.path '*.wav']);
            yy = flist(1).name(y:y+1);
            mm = flist(1).name(m:m+1);
            dd = flist(1).name(d:d+1);
            HH = flist(1).name(H:H+1);
            MM = flist(1).name(M:M+1);
            SS = flist(1).name(S:S+1);
            textLabel = [{'The data of the first file in the source directory is:'},...
                {[dd,'.',mm,'.20',yy,' ', HH,':',MM,':',SS '. The renamed file would be:'] }, ...
                {[app.station '_' yy mm dd HH MM SS '.wav']}];
            app.TEXT_2.Text = textLabel;
            app.Jahr = y;
            app.Monat = m;
            app.Tag = d;
            app.Stunde = H;
            app.Minute = M;
            app.Sekunde = S;
            app.dtf = value(y:S+1);
        end

        % Callback function
        function FiledateoffirstfileiscorrectCheckBox_2ValueChanged(app, event)
            value = app.FiledateoffirstfileiscorrectCheckBox_2.Value;
            if value ==1
                app.Lamp_11.Color = 'g';
                app.RUNButton.Enable = 'On';
            else
                app.Lamp_11.Color = 'r';
                app.RUNButton.Enable = 'Off';
            end
        end

        % Button pushed function: RenametoStandardButton
        function RenametoStandardButtonPushed(app, event)
            warndlg(['Renaming will unwiderbringlich change the filenames. Make sure, that the yyMMddHHmmss indices entered above are correct !'])
            umbenenner(app.path,app.Jahr,app.Monat,app.Tag,app.Stunde,app.Minute,app.Sekunde,app.station)
            app.RUNButton.Enable = 'On';
        end

        % Value changed function: AgreeCheckBox
        function AgreeCheckBoxValueChanged(app, event)
            value = app.AgreeCheckBox.Value;
            if value ==1
                app.RenametoStandardButton.Enable = 'On'
                %% activate possibility to remove leading and trailing seconds
                app.RemoveleadingsecondsofeachfileCheckBox.Enable = 'On';
                app.RemovetrailingsecondsofeachfileCheckBox.Enable = 'On';
                app.CheckBox.Enable = 'On';
                app.CreateCopyEditField.Enable = 'On';
                app.BrowseButton.Enable = 'On';
            else
                app.RenametoStandardButton.Enable = 'Off'
            end
        end

        % Button pushed function: ChooseandComputeButton
        function ChooseandComputeButtonPushed(app, event)
            fhv = app.QCTool.HandleVisibility;        % Current status
            app.QCTool.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.QCTool)
            delete(app.PAT)
            [gin_x,~] = ginput(1);
            app.PAT = patch(app.UIAxes,[gin_x(1) gin_x(1) gin_x(1)+30 gin_x(1)+30],[-1 1 1 -1],'m','facealpha',.2,'edgecolor','none');
            app.cal_start = gin_x(1);
            cal_sample_start = gin_x(1)*app.cal_sr;
            cal_sample_end = (gin_x(1)+30)*app.cal_sr;
            app.cal_valsig = app.cal_signal(cal_sample_start:cal_sample_end);
            app.cal_start = gin_x(1);
            app.Lamp_12.Color = 'g'
            
            
            %% calculate
            S_ref = -212.1 ; % Fest Sensitivität vom Kalibrierer (itap)
            U_ref = app.cal_volt;
            U_ref = double(U_ref);
            gain_ref = 40; % Fester Verstärkungsfaktor vom itap Kalibrierer
            L_ref = 20 * log10(U_ref) - gain_ref - S_ref;
            %% display in app
            app.SoundPressureinChamberEditField.Value = double(L_ref);
            %% Calibration dB
            caldb = L_ref - 20 * log10(std(app.cal_valsig));
            %% display in app
            app.CalibrationFactordBEditField.Value = double(caldb);
            callin = 10^(caldb/20);
            %% display in app
            app.linearCalibrationFactorEditField.Value = double(callin);
            
            
            % FFT durchführen, d.h. Funktion fftanalyse aufrufen
            [freq_axis, data_vector_fft] = fftanalyse(2^15, [1:length(app.cal_valsig)]', 1/app.cal_sr, app.cal_valsig);
            
            %% Berechnung des Schalldruckwertes und grafische Ausgabe
            Lp = 20*log10(data_vector_fft./1*10^(-6));
            diffe = L_ref-max(Lp);
            Lp = Lp + diffe;
            fhv = app.QCTool.HandleVisibility;        % Current status
            app.QCTool.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.QCTool)
            
            plot(app.UIAxes2,freq_axis, Lp);
            set(app.UIAxes2, 'XScale', 'log') ;
            set(app.UIAxes2,'YLim',[0 170]);
            app.QCTool.HandleVisibility = fhv;        % return original state
            
            %% mark at which frequency the maximum is found
            [~,f] = max(Lp);
            xline(app.UIAxes2,freq_axis(f),'r')
            %         annotation(app.UIAxes2,'textbox',[.2 .5 .3 .3],'String',['Max SPL @ ' num2str(freq_axis(f)) ' Hz'],'FitBoxToText','on');
            T = text(app.UIAxes2,freq_axis(f)+30,L_ref+1,['Max SPL @ ' num2str(freq_axis(f)) ' Hz'])
            
            %% automatically save figure to path
            h = figure;
            h.Visible = 'off';
            x = app.UIAxes2.XAxis.Parent.Children(3).XData;
            y = app.UIAxes2.XAxis.Parent.Children(3).YData;
            plot(x,y)
            xline(freq_axis(f),'r')
            %         annotation('textbox',[.2 .5 .3 .3],'String',['Max SPL @ ' num2str(freq_axis(f)) ' Hz'],'FitBoxToText','on');
            text(freq_axis(f)+30,L_ref+1,['Max SPL @ ' num2str(freq_axis(f)) ' Hz'])
            h.CurrentAxes.YLabel.String = app.UIAxes2.YLabel.String;
            h.CurrentAxes.YLabel.FontSize = app.UIAxes2.YLabel.FontSize;
            h.CurrentAxes.XLabel.String = app.UIAxes2.XLabel.String;
            h.CurrentAxes.XLabel.FontSize = app.UIAxes2.XLabel.FontSize;
            h.CurrentAxes.XLim = [0 max(x)];
            h.CurrentAxes.YLim = [0 170];
            h.CurrentAxes.XScale = 'log';
            print(h,[app.cal_path datestr(now,'yymmdd') '_Calibration_Spectrum.png'],'-dpng','-r300');
            delete(h)
            
            %% automatically save calibration curve as .mat
            save([app.cal_path datestr(now,'yymmdd') '_Calibration_Curve.mat'],'freq_axis','Lp')
            
            %% automatically save results to .txt
            fp = fopen([app.cal_path datestr(now,'yymmdd') '_Calibration_Results.txt'],'w');
            fprintf(fp, '%s\n\n','Calibration Results');
            fprintf(fp, '%s\n',['The sound pressure level in calibration chamber in dB re 1 µPa: ' num2str(L_ref)]);
            fprintf(fp, '%s\n',['The calibration level in dB/V: ' num2str(caldb) ]);
            fprintf(fp, '%s\n',['The linear calibration level in µPa/V: ' num2str(callin)]);
            fclose(fp)
            
            %% write output to results tab
            
            
        end

        % Value changed function: RMSVoltageVEditField
        function RMSVoltageVEditFieldValueChanged(app, event)
            app.cal_volt = app.RMSVoltageVEditField.Value;
            if app.cal_volt < 0.15 || app.cal_volt > 0.3
                warndlg(['The entered voltage is unrealistic. Please recheck! ... Volltrottel -.-'])
            else
                app.Lamp_11.Color = 'g';
            end
            if app.Lamp_11.Color == [0 1 0] & app.Lamp_10.Color == [0 1 0]
                app.ChooseandComputeButton.Enable = 'On'
            end
        end

        % Callback function
        function ComputeButtonPushed(app, event)
            % Check if all necessary input parameters exist
            
            %% calculate
            S_ref = -212.1 ; % Fest Sensitivität vom Kalibrierer (itap)
            U_ref = app.cal_volt;
            U_ref = double(U_ref);
            gain_ref = 40; % Fester Verstärkungsfaktor vom itap Kalibrierer
            L_ref = 20 * log10(U_ref) - gain_ref - S_ref;
            %% display in app
            app.SoundPressureinChamberEditField.Value = double(L_ref);
            %% Calibration dB
            caldb = L_ref - 20 * log10(std(app.cal_valsig));
            %% display in app
            app.CalibrationFactordBEditField.Value = double(caldb);
            callin = 10^(caldb/20);
            %% display in app
            app.linearCalibrationFactorEditField.Value = double(callin);
            
            
            % FFT durchführen, d.h. Funktion fftanalyse aufrufen
            [freq_axis, data_vector_fft] = fftanalyse(2^15, [1:length(app.cal_valsig)]', 1/app.cal_sr, app.cal_valsig);
            
            %% Berechnung des Schalldruckwertes und grafische Ausgabe
            Lp = 20*log10(data_vector_fft./1*10^(-6));
            diffe = L_ref-max(Lp);
            Lp = Lp + diffe;
            fhv = app.QCTool.HandleVisibility;        % Current status
            app.QCTool.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.QCTool)
            
            plot(app.UIAxes2,freq_axis, Lp);
            set(app.UIAxes2, 'XScale', 'log') ;
            set(app.UIAxes2,'YLim',[0 170]);
            app.QCTool.HandleVisibility = fhv;        % return original state
            
            %% mark at which frequency the maximum is found
            [~,f] = max(Lp);
            xline(app.UIAxes2,freq_axis(f),'r')
            %         annotation(app.UIAxes2,'textbox',[.2 .5 .3 .3],'String',['Max SPL @ ' num2str(freq_axis(f)) ' Hz'],'FitBoxToText','on');
            text(app.UIAxes2,freq_axis(f)+30,L_ref+1,['Max SPL @ ' num2str(freq_axis(f)) ' Hz'])
            
            %% automatically save figure to path
            h = figure;
            h.Visible = 'off';
            x = app.UIAxes2.XAxis.Parent.Children(3).XData;
            y = app.UIAxes2.XAxis.Parent.Children(3).YData;
            plot(x,y)
            xline(freq_axis(f),'r')
            %         annotation('textbox',[.2 .5 .3 .3],'String',['Max SPL @ ' num2str(freq_axis(f)) ' Hz'],'FitBoxToText','on');
            text(freq_axis(f)+30,L_ref+1,['Max SPL @ ' num2str(freq_axis(f)) ' Hz'])
            h.CurrentAxes.YLabel.String = app.UIAxes2.YLabel.String;
            h.CurrentAxes.YLabel.FontSize = app.UIAxes2.YLabel.FontSize;
            h.CurrentAxes.XLabel.String = app.UIAxes2.XLabel.String;
            h.CurrentAxes.XLabel.FontSize = app.UIAxes2.XLabel.FontSize;
            h.CurrentAxes.XLim = [0 max(x)];
            h.CurrentAxes.YLim = [0 170];
            h.CurrentAxes.XScale = 'log';
            print(h,[app.cal_path datestr(now,'yymmdd') '_Calibration_Spectrum.png'],'-dpng','-r300');
            delete(h)
            
            %% automatically save calibration curve as .mat
            save([app.cal_path datestr(now,'yymmdd') '_Calibration_Curve.mat'],'freq_axis','Lp')
            
            %% automatically save results to .txt
            fp = fopen([app.cal_path datestr(now,'yymmdd') '_Calibration_Results.txt'],'w');
            fprintf(fp, '%s\n\n','Calibration Results');
            fprintf(fp, '%s\n',['The sound pressure level in calibration chamber in dB re 1 µPa: ' num2str(L_ref)]);
            fprintf(fp, '%s\n',['The calibration level in dB/V: ' num2str(caldb) ]);
            fprintf(fp, '%s\n',['The linear calibration level in µPa/V: ' num2str(callin)]);
            fclose(fp)
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            %             ! X:\Meereskunde\Unterwasserschall\HDF5_Testdaten_Skripte\Skripte\Matlabskripte\QC_toolbox\libs\Wikilogin.bat
            %             dos ('"C:\Program Files\Internet Explorer\iexplore.exe" -extoff http://linwiki-uschall10.srx.bsh.de/mediawiki/index.php/Quality_Checks');
            dos('explorer http://linwiki-uschall10.srx.bsh.de/mediawiki/index.php/Quality_Checks');
        end

        % Value changed function: RemoveleadingsecondsofeachfileCheckBox
        function RemoveleadingsecondsofeachfileCheckBoxValueChanged(app, event)
            value = app.RemoveleadingsecondsofeachfileCheckBox.Value;
            if value == 1
                app.leadsecseditfield.Enable = 'On';
                app.leadsecseditfield.Editable = 'On';
                app.secondsLabel.Enable = 'On';
            elseif value == 0
                app.leadsecseditfield.Enable = 'Off';
                app.leadsecseditfield.Editable = 'Off';
                app.secondsLabel.Enable = 'Off';
            end
        end

        % Value changed function: RemovetrailingsecondsofeachfileCheckBox
        function RemovetrailingsecondsofeachfileCheckBoxValueChanged(app, event)
            value = app.RemovetrailingsecondsofeachfileCheckBox.Value;
            if value == 1
                app.trailsecseditfield.Enable = 'On';
                app.trailsecseditfield.Editable = 'On';
                app.secondsLabel_2.Enable = 'On';
            elseif value == 0
                app.trailsecseditfield.Enable = 'Off';
                app.trailsecseditfield.Editable = 'Off';
                app.secondsLabel_2.Enable = 'Off';
            end
        end

        % Button pushed function: CutButton
        function CutButtonPushed(app, event)
            %% Check whether parallel computing toolbox is available
            para = license('test','Distrib_Computing_Toolbox');
            flist = dir([app.path,'*.wav']);
            if  (app.leadsecs ~= 0 || app.trailsecs ~= 0)
                if para == 1
                        probar = parfor_progressbar(length(flist),'Removing leading and/or trailing seconds of .wav files ...');  %create the progress bar
                        paracutsec(probar, app.path, flist, app.copypath, app.leadsecs, app.trailsecs)
                        close(probar);
                else
                        probar = uiprogressdlg( app.QCTool, 'Title', 'Progress', 'Message', 'Removing leading and/or trailing seconds of .wav files ...', 'Value', 0);
                        cutsec(probar, app.path, flist, app.copypath, app.leadsecs, app.trailsecs)
                end
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % get path where copy of cut files is saved
            app.copypath = uigetdir('G:\');
            % bring back app to top
            drawnow;
            figure(app.QCTool)
            % write copypath to EditField
            app.CreateCopyEditField.Value = [app.copypath '\'];
            app.CutButton.Enable = 'On';
        end

        % Value changed function: CreateCopyEditField
        function CreateCopyEditFieldValueChanged(app, event)
            app.copypath = app.CreateCopyEditField.Value;        
        end

        % Value changed function: leadsecseditfield
        function leadsecseditfieldValueChanged(app, event)
            app.leadsecs = double(app.leadsecseditfield.Value);
            
        end

        % Value changed function: trailsecseditfield
        function trailsecseditfieldValueChanged(app, event)
            app.trailsecs = double(app.trailsecseditfield.Value);
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 0
                app.CutButton.Enable = 'On';
            elseif value ==1 && strcmp(app.copypath,'null')
                app.CutButton.Enable = 'Off';
            end
        end

        % Button pushed function: ImportBSoundHOutputfilesButton
        function ImportBSoundHOutputfilesButtonPushed(app, event)
            lastdir = 'X:\Meereskunde\Unterwasserschall\AMSO23\'
            [files,path] = uigetfile({'*.h5','BSH-format .h5 files (*.h5)';'*.*','All Files (*.*)'}, ...
            'Select .h5 file in BSH- format',lastdir);   
            drawnow;
            %% fill fields from BSoundH Output h5 file
            app.bsh_calibration_factor = h5read([path files],'/dataset_ambient_noise/calibration/calibration_factor');
            app.calibrationfactorEditField.Value = app.bsh_calibration_factor;
            app.Lamp_calibration_factor.Color = 'g';
            app.bsh_calibration_level = h5read([path files],'/dataset_ambient_noise/calibration/calibration_level');
            app.calibrationlevelEditField.Value = app.bsh_calibration_level;
            app.Lamp_calibration_level.Color = 'g';
            % BSH calibration factor unit - hard coded as defined in
            % BSoundH
            app.bsh_calibration_factor_unit = 'µPa/digital sample value';
            app.Lamp_calibration_factor_unit.Color = 'g';
            app.calibraionfactorunitEditField.Value = 'µPa/digital sample value';
            % BSH calibration procedure - hard coded because always the
            % same
            app.bsh_calibration_procedure = 'CPCa';
            app.Lamp_calibration_procedure.Color = 'g';
            app.calibrationprocedureEditField.Value = 'CPCa'
            % coordinated reference (danke Maria)
            app.bsh_coordinatesreference = 'WGS84';
            app.Lamp_coordinates_reference.Color = 'g';
            app.coordinatesreferenceEditField.Value = 'WGS84';
            % dataset type
            app.bsh_datasettype = 'ambient';
            app.Lamp_dataset_type.Color = 'g';
            app.datasettypeEditField.Value = 'ambient';
            % hydrophone sensitivity
            app.bsh_hydrophone_sensitivity = h5read([path files],'/dataset_ambient_noise/calibration/calibration_level')*-1;
            app.Lamp_hydrophone_sensitivity.Color = 'g';
            app.hydrophonesensitivityEditField.Value = app.bsh_hydrophone_sensitivity;
            % hydrophone sensitivity unit
            app.bsh_hydrophone_sensitivity_unit = 'dB re 1V/uPa';
            app.Lamp_hydrophone_sensitivity_unit.Color = 'g';
            app.hydrophonesensitivityunitEditField.Value = 'dB re 1V/uPa';
            app.bsh_frequency_count = h5read([path files],'/dataset_ambient_noise/measurement_data/frequency_count');
            app.frequencycountEditField.Value = double(app.bsh_frequency_count);
            app.Lamp_frequency_count.Color = 'g';
            app.ICES_FrequencyCountSpinner.Value = double(app.bsh_frequency_count);
            app.ICES_Lamp_FrequencyCount.Color = 'g';
            app.bsh_frequency_index = h5read([path files],'/dataset_ambient_noise/measurement_data/frequency_index');
            app.Lamp_frequency_index.Color = 'g';
            app.ICES_Lamp_FrequencyIndex.Color = 'g';
            app.bsh_leq_count = int64(h5read([path files],'/dataset_ambient_noise/measurement_data/leq_count'));
            app.leqcountEditField.Value = double(app.bsh_leq_count);
            app.Lamp_leq_count.Color = 'g';
            app.ICES_MeasurementTotalNoEditField.Value = double(app.bsh_leq_count);
            app.ICES_Lamp_MeasurementTotalNo.Color = 'g';
            app.bsh_datetime_index = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_datetime_index');
            app.Lamp_leq_datetime_index.Color = 'g';
            app.ICES_Lamp_DateTime.Color = 'g';
            % percentiles are copied from BSoundH output, but are not
            % displayed in the app
            app.bsh_spectral_stats_L01 = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_spectral_stats/L01');
            app.bsh_spectral_stats_L05 = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_spectral_stats/L05');
            app.bsh_spectral_stats_L50 = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_spectral_stats/L50');
            app.bsh_spectral_stats_L90 = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_spectral_stats/L90');
            app.bsh_spectral_stats_L99 = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_spectral_stats/L99');
            app.bsh_spectral_stats_LMax = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_spectral_stats/LMax');
%             app.bsh_spectral_stats_LMean = h5read([path
%             files],'/dataset_ambient_noise/measurement_data/leq_spectral_stats/LMean');
%             % auskommentiert, weil LMean in BSoundH verkehrte Werte
%             outputted
            app.bsh_spectral_stats_LMin = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_spectral_stats/LMin');
            app.Lamp_leq_spectrotemporalstats.Color = 'g';
            %
            app.bsh_leq_spectro_temporal_values = double(h5read([path files],'/dataset_ambient_noise/measurement_data/leq_spectro_temporal_values'));
            app.Lamp_leq_spectro_temporal_values.Color = 'g';
            app.ICES_Lamp_LeqMatrix.Color = 'g';
            app.bsh_spectral_stats_LMean = 10*log10(mean(10.^(app.bsh_leq_spectro_temporal_values/10),2));
            % temporal percentiles are copied from BSoundH output, but are
            % not displayed in the app
            app.bsh_temporal_stats_L01 = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_temporal_stats/L01');
            app.bsh_temporal_stats_L05 = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_temporal_stats/L05');
            app.bsh_temporal_stats_L50 = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_temporal_stats/L50');
            app.bsh_temporal_stats_L90 = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_temporal_stats/L90');
            app.bsh_temporal_stats_L99 = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_temporal_stats/L99');
            app.bsh_temporal_stats_LMax = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_temporal_stats/LMax');
%             app.bsh_temporal_stats_LMean = h5read([path
%             files],'/dataset_ambient_noise/measurement_data/leq_temporal_stats/LMean');
%             auskommentiert, weil Meanberechnung in BSoundH nicht
%             plausibel ist
            app.bsh_temporal_stats_LMin = h5read([path files],'/dataset_ambient_noise/measurement_data/leq_temporal_stats/LMin');
            app.Lamp_leq_temporalstats.Color = 'g';
            %
            app.bsh_leq_temporal_values = double(h5read([path files],'/dataset_ambient_noise/measurement_data/leq_temporal_values'));
            app.bsh_temporal_stats_LMean = 10*log10(mean(10.^(app.bsh_leq_temporal_values/10),2));
            app.Lamp_leq_temporal_values.Color = 'g';
            app.bsh_rawdata_samplingrate = h5read([path files],'/rawdata_samplingrate');
            app.rawdatasamplingrateEditField.Value = double(app.bsh_rawdata_samplingrate);
            app.Lamp_rawdata_samplingrate.Color = 'g';
            
            drawnow;
%             %% automatic averaging to 20 seconds of leq_spectro_temporal_values, leq_temporal_values and leq_datetime_index
%             a=app.bsh_leq_spectro_temporal_values';
%             BB = app.bsh_leq_temporal_values;
%             unten=1;
%             o=0;
%             for i=21:20:floor(length(a)/20)*20
%                 o=o+1;
%                 b2(o,:) = 10*log10(mean(10.^(a(unten:i,:)/10)));
%                 BBB(o) = 10*log10(mean(10.^(BB(unten:i)/10)));
%                 unten=i+1;
%             end
%             app.bsh_leq_spectro_temporal_values=double(b2');
%             app.bsh_leq_temporal_values=double(BBB');
%             %% Schleife, die nur jeden 20. Zeitwert entsprechend stv wegschreibt
%             app.bsh_datetime_index=app.bsh_datetime_index(11:20:floor(length(a)/20)*20);
%             app.bsh_datetime_index(end) = [];
%             app.bsh_leq_count = int64(length(b2));
%             app.leqcountEditField.Value = double(length(b2));
            app.bsh_leq_averaging_time = 20;
            app.leqaveragingtimeEditField.Value = 20;
            app.Lamp_leq_averaging_time.Color = 'g';
            app.ICES_AveragingTimeSpinner.Value = 20;
            app.ICES_Lamp_AveragingTime.Color = 'g';
            %% creation date
            app.ICES_CreationDateDatePicker.Value = datetime(now,'ConvertFrom','datenum','Format','MM/dd/yyyy');
            app.ICES_CreationTimeEditField.Value = string(datetime(now,'ConvertFrom','datenum','Format','hh:mm'));
            app.ICES_Lamp_CreationDate.Color = 'g'
            app.dateofcreationDatePicker.Value = datetime(now,'ConvertFrom','datenum','Format','MM/dd/yyyy');
            app.Lamp_date_of_creation.Color = 'g';
            app.bsh_date_of_creation = posixtime(datetime(now,'ConvertFrom','datenum'));
            %% start date
            app.ICES_StartDateDatePicker.Value = datetime(app.bsh_datetime_index(1),'ConvertFrom','posixtime','Format','MM/dd/yyyy');
            app.ICES_StartTimeEditField.Value = string(datetime(app.bsh_datetime_index(1),'ConvertFrom','posixtime','Format','hh:mm'));
            app.ICES_Lamp_StartDate.Color = 'g';
            app.ICES_StartDate = app.bsh_datetime_index(1);
            %% end date
            app.ICES_EndDateDatePicker.Value = datetime(app.bsh_datetime_index(end),'ConvertFrom','posixtime','Format','MM/dd/yyyy');
            app.ICES_EndTimeEditField.Value = string(datetime(app.bsh_datetime_index(end),'ConvertFrom','posixtime','Format','hh:mm'));
            app.ICES_Lamp_EndDate.Color = 'g';
            app.ICES_EndDate = app.bsh_datetime_index(end);
            %% measuring institution (hard code to always BSH)
            app.measuringinstitutionEditField.Value = 'BSH';
            app.Lamp_measuring_institution.Color = 'g';
            app.bsh_measuring_institution = 'BSH';
            app.ICES_InstitutionEditField.Value = 'BSH';
            app.ICES_Lamp_Institution.Color = 'g';
            app.ICES_Institution = '1850'; % 1850 is ICES code for BSH
            %% country code (ICES) - hard code to DE
            app.ICES_CountryCodeDropDown.Value = 'DE';
            app.ICES_Lamp_CountryCode.Color = 'g';
            app.ICES_CountryCode = 'DE';
            %% frequency unit
            app.ICES_FrequencyUnitDropDown.Value = 'Hertz';
            app.ICES_Lamp_FrequencyUnit.Color = 'g';
            app.ICES_FrequencyUnit = 'Hz';
            %% channel count (set to 1 - for now ignore possibility of multiple channels)
            app.ICES_ChannelCountSpinner.Value = 1;
            app.ICES_Lamp_ChannelCount.Color = 'g';
            app.ICES_ChannelCount = int64(1);
            %% Measurement Unit
            app.ICES_MeasurementUnitDropDown.Value = 'Sound pressure level - dB re 1µPa';
            app.ICES_Lamp_MeasurementUnit.Color = 'g';
            app.ICES_MeasurementUnit = 'SPL';
            %% Processing Algorithm
            app.ICES_ProcessingAlgorithmDropDown.Value = 'Jomopans signal processing standard (L. Wang';
            app.ICES_Lamp_ProcessingAlgorithm.Color = 'g';
            app.ICES_ProcessingAlgorithm = 'JOMO';
            %% Data UUID
            UUID = char(java.util.UUID.randomUUID);
            app.ICES_DataUUIDEditField.Value = UUID;
            app.ICES_DataUUID = UUID;
            app.ICES_Lamp_DataUUID.Color = 'g';
            %% Dataset version
            app.ICES_DatasetVersionSpinner.Value = 1;
            app.ICES_Lamp_DatasetVersion.Color = 'g';
            app.ICES_DatasetVersion = string(1);
            %% Calibration procedure (hard code to pistonphone)
            app.ICES_CalibrationProcedureDropDown.Value = 'Closed pressure chamber/pistonphone (125 Hz)';
            app.ICES_Lamp_CalibrationProcedure.Color = 'g';
            app.ICES_CalibrationProcedure = 'CPCa';
            % UUID
            uuid = ['BSH_' char(java.util.UUID.randomUUID)];
            app.bsh_rawdata_uuid = uuid;
            app.rawdatauuidEditField.Value = uuid;
            app.Lamp_rawdata_uuid.Color = 'g';
            
            
            figure(app.QCTool)
            
        end

        % Value changed function: authorEditField
        function authorEditFieldValueChanged(app, event)
            Email = app.authorEditField.Value;
            p = max(strfind(Email,'.'));
            at = strfind(Email,'@');
            if ~isempty(p) && ~isempty(at) && at<p
                app.bsh_author = Email;
                app.Lamp_author.Color = 'g';
            else
                errordlg('The emailadress, which you have entered is not valid. Please try again')
                app.Lamp_author.Color = 'r';
            end
            if isempty(Email)
                app.bsh_author = [];
                app.Lamp_author.Color = 'r';
            end
        end

        % Value changed function: dateofcreationDatePicker
        function dateofcreationDatePickerValueChanged(app, event)
            app.bsh_date_of_creation = posixtime(app.dateofcreationDatePicker.Value);
            app.Lamp_date_of_creation.Color = 'g';
        end

        % Value changed function: measuringinstitutionEditField
        function measuringinstitutionEditFieldValueChanged(app, event)
            app.bsh_measuring_institution = app.measuringinstitutionEditField.Value;
            app.Lamp_measuring_institution.Color = 'g';
            if isempty(app.measuringinstitutionEditField.Value)
                app.bsh_measuring_institution = [];
                app.Lamp_measuring_institution.Color = 'r';
            end
        end

        % Value changed function: pointofcontactEditField
        function pointofcontactEditFieldValueChanged(app, event)
            app.bsh_point_of_contact = app.pointofcontactEditField.Value;
            app.Lamp_point_of_contact.Color = 'g';
            if isempty(app.pointofcontactEditField.Value)
                app.bsh_point_of_contact  = [];
                app.Lamp_point_of_contact.Color = 'r';
            end
        end

        % Value changed function: rawdatasamplingrateEditField
        function rawdatasamplingrateEditFieldValueChanged(app, event)
            app.bsh_rawdata_samplingrate = int64(app.rawdatasamplingrateEditField.Value);
            app.Lamp_rawdata_samplingrate.Color = 'g';
            if isempty(app.rawdatasamplingrateEditField.Value)
                app.bsh_rawdata_samplingrate  = [];
                app.Lamp_rawdata_samplingrate.Color = 'r';
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            uuid = ['BSH_' char(java.util.UUID.randomUUID)];
            app.bsh_rawdata_uuid = uuid;
            app.rawdatauuidEditField.Value = uuid;
            app.Lamp_rawdata_uuid.Color = 'g';
        end

        % Value changed function: calibrationdateDatePicker
        function calibrationdateDatePickerValueChanged(app, event)
            app.bsh_calibration_date = posixtime(app.calibrationdateDatePicker.Value);
            app.Lamp_calibration_date.Color = 'g';
        end

        % Value changed function: calibrationfactorEditField
        function calibrationfactorEditFieldValueChanged(app, event)
            app.bsh_calibration_factor = app.calibrationfactorEditField.Value;
            app.Lamp_calibration_factor.Color = 'g';
            if isempty(app.calibrationfactorEditField.Value)
                app.bsh_calibration_factor = [];
                app.Lamp_calibration_factor.Color = 'r';
            end
        end

        % Value changed function: calibraionfactorunitEditField
        function calibraionfactorunitEditFieldValueChanged(app, event)
            app.bsh_calibration_factor_unit = app.calibraionfactorunitEditField.Value;
            app.Lamp_calibration_factor_unit.Color = 'g';
            if isempty(app.calibraionfactorunitEditField.Value)
                app.bsh_calibration_factor_unit = [];
                app.Lamp_calibration_factor_unit.Color = 'r';
            end
        end

        % Value changed function: calibrationlevelEditField
        function calibrationlevelEditFieldValueChanged(app, event)
            app.bsh_calibration_level = app.calibrationlevelEditField.Value;
            app.Lamp_calibration_level.Color = 'g';
            if isempty(app.calibrationlevelEditField.Value)
                app.bsh_calibration_level = [];
                app.Lamp_calibration_level.Color = 'r';
            end
        end

        % Value changed function: calibrationprocedureEditField
        function calibrationprocedureEditFieldValueChanged(app, event)
            app.bsh_calibration_procedure = app.calibrationprocedureEditField.Value;
            app.Lamp_calibration_procedure.Color = 'g';
            if isempty(app.calibrationprocedureEditField.Value)
                app.bsh_calibration_procedure =  [];
                app.Lamp_calibration_procedure.Color = 'r';
            end
        end

        % Value changed function: referencefrequencieslevelsEditField
        function referencefrequencieslevelsEditFieldValueChanged(app, event)
            app.bsh_reference_frequencies_levels(1,1) = str2num(app.referencefrequencieslevelsEditField.Value);
            if ~isnan(app.bsh_reference_frequencies_levels(1,2))
                app.Lamp_reference_frequencies_levels.Color = 'g'
            else
                app.Lamp_reference_frequencies_levels.Color = 'r';
            end
            if isempty(app.referencefrequencieslevelsEditField.Value)
                app.bsh_reference_frequencies_levels(1,1) = NaN;
                app.Lamp_reference_frequencies_levels.Color = 'r';
            end
        end

        % Value changed function: referencefrequencieslevelsEditField2
        function referencefrequencieslevelsEditField2ValueChanged(app, event)
            app.bsh_reference_frequencies_levels(1,2) = app.referencefrequencieslevelsEditField2.Value;
            if ~isnan(app.bsh_reference_frequencies_levels(1,1))
                app.Lamp_reference_frequencies_levels.Color = 'g'
            else
                app.Lamp_reference_frequencies_levels.Color = 'r';
            end
            if isempty(app.referencefrequencieslevelsEditField2.Value)
                app.bsh_reference_frequencies_levels(1,2) = NaN;
                app.Lamp_reference_frequencies_levels.Color = 'r';
            end
        end

        % Value changed function: commentsEditField
        function commentsEditFieldValueChanged(app, event)
            app.bsh_comments = app.commentsEditField.Value;
            app.Lamp_comments.Color = 'g'
            if isempty(app.commentsEditField.Value)
                app.bsh_comments = [];
                app.Lamp_comments.Color = 'r'
            end
        end

        % Value changed function: coordinatesmeasurementpositionEditField
        function coordinatesmeasurementpositionEditFieldValueChanged(app, event)
            A = app.coordinatesmeasurementpositionEditField.Value;
            app.bsh_coordinatesmeasurementposition = [];
            app.Lamp_coordinates_measurement_position.Color = 'g';
            B = strsplit(A,',');
            app.bsh_coordinatesmeasurementposition(1) = str2num(B{1});
            app.bsh_coordinatesmeasurementposition(2) = str2num(B{2});
            if isempty(app.coordinatesmeasurementpositionEditField.Value)
                app.bsh_coordinatesmeasurementposition = [];
                app.Lamp_coordinates_measurement_position.Color = 'r';
            end
        end

        % Value changed function: coordinatesreferenceEditField
        function coordinatesreferenceEditFieldValueChanged(app, event)
            app.bsh_coordinatesreference = app.coordinatesreferenceEditField.Value;
            app.Lamp_coordinates_reference.Color = 'g';
            if isempty(app.coordinatesreferenceEditField.Value)
                app.bsh_coordinatesreference = [];
                app.Lamp_coordinates_reference.Color = 'r';
            end
        end

        % Value changed function: datasettypeEditField
        function datasettypeEditFieldValueChanged(app, event)
            app.bsh_datasettype = app.datasettypeEditField.Value;
            app.Lamp_dataset_type.Color = 'g';        
            if isempty(app.datasettypeEditField.Value)
                app.bsh_datasettype = [];
                app.Lamp_dataset_type.Color = 'r';    
            end
        end

        % Value changed function: datasetversionEditField
        function datasetversionEditFieldValueChanged(app, event)
            app.bsh_datasetversion = app.datasetversionEditField.Value;
            app.Lamp_dataset_version.Color = 'g';
            if isempty(app.datasetversionEditField.Value)
                app.bsh_datasetversion = [];
                app.Lamp_dataset_version.Color = 'r';
            end
        end

        % Value changed function: hydrophonemanufacturerEditField
        function hydrophonemanufacturerEditFieldValueChanged(app, event)
            app.bsh_hydrophone_manufacturer = app.hydrophonemanufacturerEditField.Value;
            app.Lamp_hydrophone_manufacturer.Color = 'g';
            if isempty(app.hydrophonemanufacturerEditField.Value)
                app.bsh_hydrophone_manufacturer = [];
                app.Lamp_hydrophone_manufacturer.Color = 'r';
            end
        end

        % Value changed function: hydrophonesensitivityEditField
        function hydrophonesensitivityEditFieldValueChanged(app, event)
            app.bsh_hydrophone_sensitivity = app.hydrophonesensitivityEditField.Value;
            app.Lamp_hydrophone_sensitivity.Color = 'g';
            if isempty(app.hydrophonesensitivityEditField.Value)
                app.bsh_hydrophone_sensitivity = [];
                app.Lamp_hydrophone_sensitivity.Color = 'r';
            end
        end

        % Value changed function: hydrophonesensitivityunitEditField
        function hydrophonesensitivityunitEditFieldValueChanged(app, event)
            app.bsh_hydrophone_sensitivity_unit = app.hydrophonesensitivityunitEditField.Value;
            app.Lamp_hydrophone_sensitivity_unit.Color = 'g';
            if isempty(app.hydrophonesensitivityunitEditField.Value)
                app.bsh_hydrophone_sensitivity_unit = [];
                app.Lamp_hydrophone_sensitivity_unit.Color = 'r';
            end
        end

        % Value changed function: hydrophoneserialnumberEditField
        function hydrophoneserialnumberEditFieldValueChanged(app, event)
            app.bsh_hydrophone_serial_number = app.hydrophoneserialnumberEditField.Value;
            app.Lamp_hydrophone_serial_number.Color = 'g';    
            if isempty(app.hydrophoneserialnumberEditField.Value)
                app.bsh_hydrophone_serial_number = [];
                app.Lamp_hydrophone_serial_number.Color = 'r'; 
            end
        end

        % Value changed function: hydrophonetypeEditField
        function hydrophonetypeEditFieldValueChanged(app, event)
            app.bsh_hydrophone_type = app.hydrophonetypeEditField.Value;
            app.Lamp_hydrophone_type.Color = 'g';
            if isempty(app.hydrophonetypeEditField.Value)
               app.bsh_hydrophone_type= [];
               app.Lamp_hydrophone_type.Color = 'r';
            end
        end

        % Callback function
        function dutycycleEditFieldValueChanged(app, event)
            app.bsh_duty_cycle = app.dutycycleEditField.Value;
            app.Lamp_duty_cycle.Color = 'g';
        end

        % Value changed function: frequencycountEditField
        function frequencycountEditFieldValueChanged(app, event)
            app.bsh_frequency_count = app.frequencycountEditField.Value;
            app.Lamp_frequency_count.Color = 'g';
            if isempty(app.frequencycountEditField.Value)
                app.bsh_frequency_count = [];
                app.Lamp_frequency_count.Color = 'r';
            end
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            lastdir = 'X:\Meereskunde\Unterwasserschall\AMSO23\';
            [file,path] = uigetfile({'*.h5;*.mat;*.csv','Data Files (*.h5,*.mat,*.csv)';'*.*','All Files (*.*)'}, ...
            'Select File',lastdir);   
            drawnow;
            figure(app.QCTool)          
            p = max(strfind(file,'.'));
            type = file(p+1:end);
            if isequal(type,'mat')
                F = load([path file]);
                F = struct2cell(F);
                F = cell2mat(F);
                app.bsh_frequency_index = double(F);
                app.Lamp_frequency_index.Color = 'g';
            elseif isequal(type,'csv')
                F = importdata([path file]);
                app.bsh_frequency_index = double(F);
                app.Lamp_frequency_index.Color = 'g';
            elseif isequal(type,'h5')
                 I=h5info([path file]);
                 %% datasets, die in der ersten Ebene liegen
                 idx=1;
                 for ii =1:length(I.Datasets)
                        name{idx}=I.Datasets(ii).Name;
                        group{idx}=I.Name;
                        idx=idx+1;
                 end
                 %% check wie viele Gruppen gibt es
                 if length(I.Groups)>=1
                    for ii=1:length(I.Groups)
                        for jj=1:length(I.Groups(ii).Datasets)
                            name{idx}= I.Groups(ii).Datasets(jj).Name;
                            group{idx}= I.Groups(ii).Name;
                            idx=idx+1;
                        end
                        if length(I.Groups(ii).Groups)>=1
                            for kk=1:length(I.Groups(ii).Groups)
                                for ll=1:length(I.Groups(ii).Groups(kk).Datasets)
                                    name{idx}= I.Groups(ii).Groups(kk).Datasets(ll).Name;
                                    group{idx}= I.Groups(ii).Groups(kk).Name;
                                    idx=idx+1;
                                end
                            end
                        end
                    end
                 end
                [indx,~] = listdlg('SelectionMode','single','ListString',name);
                drawnow;
                figure(app.QCTool)
                Group = group{indx};
                Dataset = name{indx};
                F = h5read([path file],['/' Group '/' Dataset]);
                app.bsh_frequency_index = double(F);
                drawnow;
                figure(app.QCTool)
                app.Lamp_frequency_index.Color = 'g';
            end
            if isempty(app.bsh_frequency_index)
                app.Lamp_frequency_index.Color = 'r';
            end
        end

        % Value changed function: leqaveragingtimeEditField
        function leqaveragingtimeEditFieldValueChanged(app, event)
            app.bsh_leq_averaging_time = app.leqaveragingtimeEditField.Value;
            app.Lamp_leq_averaging_time.Color = 'g';
            if isempty(app.leqaveragingtimeEditField.Value)
                app.bsh_leq_averaging_time = [];
                app.Lamp_leq_averaging_time.Color = 'r';
            end
        end

        % Value changed function: leqcountEditField
        function leqcountEditFieldValueChanged(app, event)
            app.bsh_leq_count = int64(app.leqcountEditField.Value);
            app.Lamp_leq_count.Color = 'g';
            if isempty(app.leqcountEditField.Value)
                app.bsh_leq_count = [];
                app.Lamp_leq_count.Color = 'r';
            end
        end

        % Button pushed function: GetButton_2
        function GetButton_2Pushed(app, event)
            if app.bsh_leq_averaging_time == 1
                warndlg('Averaging time is set to 1 sec. Please change it to 20 seconds')
            elseif isempty(app.bsh_leq_averaging_time)
                warndlg('Please set an averaging time')
            elseif app.bsh_leq_averaging_time == 20
            
            lastdir = 'X:\Meereskunde\Unterwasserschall\AMSO23\';
            [file,path] = uigetfile({'*.h5;*.mat;*.csv','Data Files (*.h5,*.mat,*.csv)';'*.*','All Files (*.*)'}, ...
            'Select File',lastdir); 
            drawnow;
            figure(app.QCTool)          
            p = max(strfind(file,'.'));
            type = file(p+1:end);
            if isequal(type,'mat')
                F = load([path file]);
                F = struct2cell(F);
                app.bsh_datetime_index = F;
                app.Lamp_leq_datetime_index.Color = 'g';
            elseif isequal(type,'csv')
                F = importdata([path file]);
                app.bsh_datetime_index = F;
                app.Lamp_leq_datetime_index.Color = 'g';
            elseif isequal(type,'h5')
                I=h5info([path file]);
            %% datasets, die in der ersten Ebene liegen
                 idx=1;
                 for ii =1:length(I.Datasets)
                        name{idx}=I.Datasets(ii).Name;
                        group{idx}=I.Name;
                        idx=idx+1;
                 end
                 %% check wie viele Gruppen gibt es
                 if length(I.Groups)>=1
                    for ii=1:length(I.Groups)
                        for jj=1:length(I.Groups(ii).Datasets)
                            name{idx}= I.Groups(ii).Datasets(jj).Name;
                            group{idx}= I.Groups(ii).Name;
                            idx=idx+1;
                        end
                        if length(I.Groups(ii).Groups)>=1
                            for kk=1:length(I.Groups(ii).Groups)
                                for ll=1:length(I.Groups(ii).Groups(kk).Datasets)
                                    name{idx}= I.Groups(ii).Groups(kk).Datasets(ll).Name;
                                    group{idx}= I.Groups(ii).Groups(kk).Name;
                                    idx=idx+1;
                                end
                            end
                        end
                    end
                 end
                [indx,~] = listdlg('SelectionMode','single','ListString',name);
                drawnow;
                figure(app.QCTool)
                Group = group{indx};
                Dataset = name{indx};
                F = h5read([path file],['/' Group '/' Dataset]);
                if datetime(F(1),'ConvertFrom','datenum') > datetime(2010,1,1) && datetime(F(1),'ConvertFrom','datenum') < datetime(2100,1,1)
                    tp = 'datenum';
                    F=cellstr(datetime(F,'ConvertFrom',tp,'Format','yyyy-MM-dd HH:mm:ss'));
                    app.bsh_datetime_index=F;    
                elseif datetime(F(1),'ConvertFrom','juliandate') > datetime(2010,1,1) && datetime(F(1),'ConvertFrom','juliandate') < datetime(2100,1,1)
                    tp = 'juliandate';
                    F=cellstr(datetime(F,'ConvertFrom',tp,'Format','yyyy-MM-dd HH:mm:ss'));
                    app.bsh_datetime_index=F;    
                elseif datetime(F(1),'ConvertFrom','excel') > datetime(2010,1,1) && datetime(F(1),'ConvertFrom','excel') < datetime(2100,1,1)
                    tp = 'excel';
                    F=cellstr(datetime(F,'ConvertFrom',tp,'Format','yyyy-MM-dd HH:mm:ss'));
                    app.bsh_datetime_index=F;    
                elseif datetime(F(1),'ConvertFrom','posixtime') > datetime(2010,1,1) && datetime(F(1),'ConvertFrom','posixtime') < datetime(2100,1,1)
                    tp = 'posixtime';
%                     F=cellstr(datetime(F,'ConvertFrom',tp,'Format','yyyy-MM-dd HH:mm:ss'));
                    app.bsh_datetime_index=F;    
                elseif datetime(num2str(F(1)),'InputFormat','yyyyMMddHHmmss') > datetime(2010,1,1) && datetime(num2str(F(1)),'InputFormat','yyyyMMddHHmmss') < datetime(2100,1,1)
                    F=cellstr(datetime(num2str(F),'InputFormat','yyyyMMddHHmmss','Format','yyyy-MM-dd HH:mm:ss'));
                    app.bsh_datetime_index=F; 
                else
                    errordlg('Datetime type not recognized')
                end         
                drawnow;
                figure(app.QCTool)
                app.Lamp_leq_datetime_index.Color = 'g';
            end
            %nicht nötig bei 20 s files
            %% averaging to 20 secs
%             app.bsh_datetime_index=app.bsh_datetime_index(11:20:floor(length(a)/20)*20);       
            end
        end

        % Button pushed function: GetButton_3
        function GetButton_3Pushed(app, event)
            if app.bsh_leq_averaging_time == 1
                warndlg('Averaging time is set to 1 sec. Please change it to 20 seconds')
            elseif isempty(app.bsh_leq_averaging_time)
                warndlg('Please set an averaging time')
            elseif app.bsh_leq_averaging_time == 20
            
                lastdir = 'X:\Meereskunde\Unterwasserschall\AMSO23\';
                [file,path] = uigetfile({'*.h5;*.mat;*.csv','Data Files (*.h5,*.mat,*.csv)';'*.*','All Files (*.*)'}, ...
                'Select File',lastdir); 
                drawnow;
                figure(app.QCTool)
                p = max(strfind(file,'.'));
                type = file(p+1:end);
                if isequal(type,'mat')
                    F = load([path file]);
                    F = struct2cell(F);
                    F = cell2mat(F);
                    app.bsh_leq_spectro_temporal_values = double(F);
                    app.Lamp_leq_spectro_temporal_values.Color = 'g';
                elseif isequal(type,'csv')
                    F = importdata([path file]);
                    app.bsh_leq_spectro_temporal_values = double(F);
                    app.Lamp_leq_spectro_temporal_values.Color = 'g';
                elseif isequal(type,'h5')
                    I=h5info([path file]); 
                     %% datasets, die in der ersten Ebene liegen
                     idx=1;
                     for ii =1:length(I.Datasets)
                            name{idx}=I.Datasets(ii).Name;
                            group{idx}=I.Name;
                            idx=idx+1;
                     end
                     %% check wie viele Gruppen gibt es
                     if length(I.Groups)>=1
                        for ii=1:length(I.Groups)
                            for jj=1:length(I.Groups(ii).Datasets)
                                name{idx}= I.Groups(ii).Datasets(jj).Name;
                                group{idx}= I.Groups(ii).Name;
                                idx=idx+1;
                            end
                            if length(I.Groups(ii).Groups)>=1
                                for kk=1:length(I.Groups(ii).Groups)
                                    for ll=1:length(I.Groups(ii).Groups(kk).Datasets)
                                        name{idx}= I.Groups(ii).Groups(kk).Datasets(ll).Name;
                                        group{idx}= I.Groups(ii).Groups(kk).Name;
                                        idx=idx+1;
                                    end
                                end
                            end
                        end
                     end
                    [indx,tf] = listdlg('SelectionMode','single','ListString',name);
                    drawnow;
                    figure(app.QCTool)
                    Group = group{indx};
                    Dataset = name{indx};
                    F = h5read([path file],['/' Group '/' Dataset]);
                    if size(F,1)>size(F,2)
                        F=F';
                    end
                    app.bsh_leq_spectro_temporal_values=double(F);
                    drawnow;
                    figure(app.QCTool)
                    app.Lamp_leq_spectro_temporal_values.Color = 'g';
                end 
                %% abfrage nach averaging time
                % if averaging time is not set - warndlg ask user to set avg
                % time first


%             Mittelung nicht mehr nötig, wenn nur noch 20 Sekundenfiles
%             geladen werden
%             %% average to 20 seconds
%             a=app.bsh_leq_spectro_temporal_values';
%             unten=1;
%             o=0;
%             for i=21:20:floor(length(a)/20)*20
%                 o=o+1;
%                 b2(o,:) = 10*log10(mean(10.^(a(unten:i,:)/10)));
%                 unten=i+1;
%             end
%             app.bsh_leq_spectro_temporal_values=b2';      
            end
        end

        % Button pushed function: GetButton_4
        function GetButton_4Pushed(app, event)
            if app.bsh_leq_averaging_time == 1
                warndlg('Averaging time is set to 1 sec. Please change it to 20 seconds')
            elseif isempty(app.bsh_leq_averaging_time)
                warndlg('Please set an averaging time')
            elseif app.bsh_leq_averaging_time == 20
            
                lastdir = 'X:\Meereskunde\Unterwasserschall\AMSO23\';
                [file,path] = uigetfile({'*.h5;*.mat;*.csv','Data Files (*.h5,*.mat,*.csv)';'*.*','All Files (*.*)'}, ...
                'Select File',lastdir); 
                drawnow;
                figure(app.QCTool)
                p = max(strfind(file,'.'));
                type = file(p+1:end);
                if isequal(type,'mat')
                    F = load([path file]);
                    F = struct2cell(F);
                    F = cell2mat(F);
                    app.bsh_leq_temporal_values = double(F);
                    app.Lamp_leq_temporal_values.Color = 'g';
                elseif isequal(type,'csv')
                    F = importdata([path file]);
                    app.bsh_leq_temporal_values = double(F);
                    app.Lamp_leq_temporal_values.Color = 'g';
                elseif isequal(type,'h5')
                    I=h5info([path file]); 
                     %% datasets, die in der ersten Ebene liegen
                     idx=1;
                     for ii =1:length(I.Datasets)
                            name{idx}=I.Datasets(ii).Name;
                            group{idx}=I.Name;
                            idx=idx+1;
                     end
                     %% check wie viele Gruppen gibt es
                     if length(I.Groups)>=1
                        for ii=1:length(I.Groups)
                            for jj=1:length(I.Groups(ii).Datasets)
                                name{idx}= I.Groups(ii).Datasets(jj).Name;
                                group{idx}= I.Groups(ii).Name;
                                idx=idx+1;
                            end
                            if length(I.Groups(ii).Groups)>=1
                                for kk=1:length(I.Groups(ii).Groups)
                                    for ll=1:length(I.Groups(ii).Groups(kk).Datasets)
                                        name{idx}= I.Groups(ii).Groups(kk).Datasets(ll).Name;
                                        group{idx}= I.Groups(ii).Groups(kk).Name;
                                        idx=idx+1;
                                    end
                                end
                            end
                        end
                     end
                    [indx,~] = listdlg('SelectionMode','single','ListString',name);
                    drawnow;
                    figure(app.QCTool)
                    Group = group{indx};
                    Dataset = name{indx};
                    F = h5read([path file],['/' Group '/' Dataset]);
                    if size(F,1)>size(F,2)
                        F=F';
                    end
                    app.bsh_leq_temporal_values=double(F);
                    drawnow;
                    figure(app.QCTool)
                    app.Lamp_leq_temporal_values.Color = 'g';
                end 
               % average to 20 seconds % nicht mehr nötig, wenn wir nur
               % noch 20 Sekunden Daten hier einlesen
%                 BB = app.bsh_leq_temporal_values;
%                 unten=1;
%                 o=0;
%                 for i=21:20:floor(length(a)/20)*20
%                     o=o+1;
%                     BBB(o) = 10*log10(mean(10.^(BB(unten:i)/10)));
%                     unten=i+1;
%                 end
%                 app.bsh_leq_temporal_values=BBB'; 
            end
        end

        % Value changed function: measurementpurposeDropDown
        function measurementpurposeDropDownValueChanged(app, event)
            app.bsh_measurement_purpose = app.measurementpurposeDropDown.Value;
            app.Lamp_measurement_purpose.Color = 'g';
            if strcmp(app.measurementpurposeDropDown.Value,'Select ...')
                app.bsh_measurement_purpose = [];
                app.Lamp_measurement_purpose.Color = 'r';
            end
        end

        % Value changed function: measurementheightEditField
        function measurementheightEditFieldValueChanged(app, event)
            app.bsh_measurement_height = app.measurementheightEditField.Value;
            app.Lamp_measurement_height.Color = 'g';
            if isempty(app.measurementheightEditField.Value)
                app.bsh_measurement_height = [];
                app.Lamp_measurement_height.Color = 'r';
            end
        end

        % Value changed function: measurementsetupDropDown
        function measurementsetupDropDownValueChanged(app, event)
            app.bsh_measurement_setup = app.measurementsetupDropDown.Value;
            app.Lamp_measurement_setup.Color = 'g';
            if strcmp(app.measurementsetupDropDown.Value,'Select ...')
                app.bsh_measurement_setup = [];
                app.Lamp_measurement_setup.Color = 'r';
            end
        end

        % Value changed function: namemeasurementpositionEditField
        function namemeasurementpositionEditFieldValueChanged(app, event)
            app.bsh_name_measurement_position = app.namemeasurementpositionEditField.Value;
            app.Lamp_name_measurement_position.Color = 'g';
            if isempty(app.namemeasurementpositionEditField.Value)
                app.bsh_name_measurement_position = [];
                app.Lamp_name_measurement_position.Color = 'r';
            end
        end

        % Value changed function: recordermanufacturerEditField
        function recordermanufacturerEditFieldValueChanged(app, event)
            app.bsh_recorder_manufacturer = app.recordermanufacturerEditField.Value;
            app.Lamp_recorder_manufacturer.Color = 'g';
            if isempty(app.recordermanufacturerEditField.Value)
                app.bsh_recorder_manufacturer = [];
                app.Lamp_recorder_manufacturer.Color = 'r';
            end
        end

        % Value changed function: recorderserialnumberEditField
        function recorderserialnumberEditFieldValueChanged(app, event)
            app.bsh_recorder_serialnumber = app.recorderserialnumberEditField.Value;
            app.Lamp_recorder_serial_number.Color = 'g';
            if isempty(app.recorderserialnumberEditField.Value)
                app.bsh_recorder_serialnumber = [];
                app.Lamp_recorder_serial_number.Color = 'r';
            end
        end

        % Value changed function: recordertypeEditField
        function recordertypeEditFieldValueChanged(app, event)
            app.bsh_recorder_type = app.recordertypeEditField.Value;
            app.Lamp_recorder_type.Color = 'g';
            if isempty(app.recordertypeEditField.Value)
                app.bsh_recorder_type = [];
                app.Lamp_recorder_type.Color = 'r';
            end
        end

        % Button pushed function: LoadtemplateButton
        function LoadtemplateButtonPushed(app, event)
            %% Select file
            lastdir = 'X:\Meereskunde\Unterwasserschall\AMSO23\';
            [file,path] = uigetfile({'*.h5','BSH h5 files (*.h5)';'*.*','All Files (*.*)'}, ...
            'Select File',lastdir); 
            drawnow;
            figure(app.QCTool)
            %% load BSH file
            [D,~,~] = bshh5readcheck(app,[path file]);
            
        end

        % Button pushed function: MovetoWorkspaceButton
        function MovetoWorkspaceButtonPushed(app, event)
            dset = app2dset(app);
            assignin('base','h5File',dset);
        end

        % Button pushed function: savetemplateButton
        function savetemplateButtonPushed(app, event)
            %% where
            [file,path] = uiputfile('*.h5', 'Save as:');
            drawnow;
            figure(app.QCTool);
            %% write all variables to dset
            dset = app2dset(app);
            %% creates and writes data to hdf5 file (file must not exits at time of function call)
            name = [path file];
            matlab_write_recursive_hdf5(name, '', dset);     
        end

        % Button pushed function: Exporthdf5Button
        function Exporthdf5ButtonPushed(app, event)
            %% where
            [file,path] = uiputfile('*.h5', 'Save as:');
            drawnow;
            figure(app.QCTool);
            %% write all variables to dset
            dset = app2dset(app);
            %% creates and writes data to hdf5 file (file must not exits at time of function call)
            name = [path file];
            matlab_write_recursive_hdf5(name, '', dset); 
            %% export as posixtime
            hdf5write(name,'/dataset_ambient_noise/measurement_data/leq_datetime_index',app.bsh_datetime_index,'WriteMode', 'append');
        end

        % Menu selected function: clearvariablesconversionMenu
        function clearallButtonPushed(app, event)
            clearappvars(app);
        end

        % Value changed function: dutycycleEditField
        function dutycycleEditFieldValueChanged2(app, event)
            charArray = app.dutycycleEditField.Value;
            doubleArray = str2double(strsplit(charArray(2:end-1), ',')); 
            intArray = int64(doubleArray);
            app.bsh_duty_cycle = intArray;
            app.Lamp_duty_cycle.Color = 'g';
            if isempty(app.dutycycleEditField.Value)
                app.bsh_duty_cycle = [];
                app.Lamp_duty_cycle.Color = 'r';
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if isempty(app.bsh_leq_spectro_temporal_values)
                warndlg('Leq_spectro_temporal_values seems to be empty - please set this field first before calculating the spectral statistics!')
                app.Lamp_leq_spectrotemporalstats.Color = 'r';
            else
                app.bsh_spectral_stats_L01 = prctile(app.bsh_leq_spectro_temporal_values',99);
                app.bsh_spectral_stats_L05 = prctile(app.bsh_leq_spectro_temporal_values',95);
                app.bsh_spectral_stats_L50 = prctile(app.bsh_leq_spectro_temporal_values',50);
                app.bsh_spectral_stats_L90 = prctile(app.bsh_leq_spectro_temporal_values',10);
                app.bsh_spectral_stats_L99 = prctile(app.bsh_leq_spectro_temporal_values',1);
                app.bsh_spectral_stats_LMax = max(app.bsh_leq_spectro_temporal_values');
                app.bsh_spectral_stats_LMean = 10*log10(mean(10.^(app.bsh_leq_spectro_temporal_values/10),2));
                app.bsh_spectral_stats_LMin = min(app.bsh_leq_spectro_temporal_values');
                app.Lamp_leq_spectrotemporalstats.Color = 'g';
            end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            if isempty(app.bsh_leq_temporal_values)
                warndlg('Leq_temporal_values seems to be empty - please set this field first before calculating the broadband statistics!')
                app.Lamp_leq_temporalstats.Color = 'r';
            else
                app.bsh_temporal_stats_L01 = prctile(app.bsh_leq_temporal_values,99);
                app.bsh_temporal_stats_L05 = prctile(app.bsh_leq_temporal_values,95);
                app.bsh_temporal_stats_L50 = prctile(app.bsh_leq_temporal_values,50);
                app.bsh_temporal_stats_L90 = prctile(app.bsh_leq_temporal_values,10);
                app.bsh_temporal_stats_L99 = prctile(app.bsh_leq_temporal_values,1);
                app.bsh_temporal_stats_LMax = max(app.bsh_leq_temporal_values);
                app.bsh_temporal_stats_LMean = 10*log10(mean(10.^(app.bsh_leq_temporal_values/10),2));
                app.bsh_temporal_stats_LMin = min(app.bsh_leq_temporal_values);
                app.Lamp_leq_temporalstats.Color = 'g';
            end
        end

        % Value changed function: ICES_EmailEditField
        function ICES_EmailEditFieldValueChanged(app, event)
            Email = app.ICES_EmailEditField.Value;
            p = max(strfind(Email,'.'));
            at = strfind(Email,'@');
            if ~isempty(p) && ~isempty(at) && at<p
                app.bsh_author = Email;
                app.authorEditField.Value = Email;
                app.ICES_Lamp_Email.Color = 'g';
                app.Lamp_author.Color = 'g';
            else
                errordlg('The emailadress, which you have entered is not valid. Please try again')
                app.ICES_Lamp_Email.Color = 'r';
                app.Lamp_author.Color = 'r';
            end
        end

        % Value changed function: ICES_CreationDateDatePicker
        function ICES_CreationDateDatePickerValueChanged(app, event)
            app.bsh_date_of_creation = app.ICES_CreationDateDatePicker.Value;
            app.dateofcreationDatePicker.Value = app.bsh_date_of_creation;
            if ~isempty(app.ICES_CreationTimeEditField.Value)
                creationtime = datetime(app.ICES_CreationTimeEditField.Value,"Format","HH:mm");
                creaHH = hour(creationtime);
                creamm = minute(creationtime);
                app.bsh_date_of_creation = app.ICES_CreationDateDatePicker.Value + hours(creaHH) + minutes(creamm);
                app.bsh_date_of_creation = posixtime(app.bsh_date_of_creation);
            else
                app.bsh_date_of_creation = posixtime(app.bsh_date_of_creation);
            end
            app.ICES_Lamp_CreationDate.Color = 'g';
            app.Lamp_date_of_creation.Color = 'g';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QCTool = uifigure('Visible', 'off');
            app.QCTool.Name = 'QCTool';
            app.QCTool.Icon = 'index2.png';
            app.Menu = uimenu(app.QCTool);
            app.Menu.Text = 'Menu';
            app.HelpMenu = uimenu(app.Menu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.OptionsMenu = uimenu(app.QCTool);
            app.OptionsMenu.Text = 'Options';
            app.clearvariablesconversionMenu = uimenu(app.OptionsMenu);
            app.clearvariablesconversionMenu.MenuSelectedFcn = createCallbackFcn(app, @clearallButtonPushed, true);
            app.clearvariablesconversionMenu.Text = 'clear variables (conversion)';
            app.TabGroup = uitabgroup(app.QCTool);
            app.QCTab = uitab(app.TabGroup);
            app.QCTab.Title = 'QC';
            app.SettingsPanel = uipanel(app.QCTab);
            app.SettingsPanel.Title = 'Settings';
            app.RawDataButton = uibutton(app.SettingsPanel, 'push');
            app.RawDataButton.ButtonPushedFcn = createCallbackFcn(app, @RawDataButtonPushed, true);
            app.RawDataButton.Tooltip = {'select path to raw .wav files'};
            app.RawDataButton.Text = 'Raw Data';
            app.DeploymentDatePickerLabel = uilabel(app.SettingsPanel);
            app.DeploymentDatePickerLabel.Text = 'Deployment';
            app.DeploymentDatePicker = uidatepicker(app.SettingsPanel);
            app.DeploymentDatePicker.Limits = [datetime([2013 1 12]) datetime([2099 12 31])];
            app.DeploymentDatePicker.ValueChangedFcn = createCallbackFcn(app, @DeploymentDatePickerValueChanged, true);
            app.DeploymentDatePicker.Tooltip = {'select date, when hydrophone was deployed'};
            app.RecoveryDatePickerLabel = uilabel(app.SettingsPanel);
            app.RecoveryDatePickerLabel.Text = 'Recovery';
            app.RecoveryDatePicker = uidatepicker(app.SettingsPanel);
            app.RecoveryDatePicker.ValueChangedFcn = createCallbackFcn(app, @RecoveryDatePickerValueChanged, true);
            app.RecoveryDatePicker.Tooltip = {'select date, when hydrophone was recovered'};
            app.StationnameDropDownLabel = uilabel(app.SettingsPanel);
            app.StationnameDropDownLabel.Text = 'Station name';
            app.StationnameDropDown = uidropdown(app.SettingsPanel);
            app.StationnameDropDown.Items = {'Select Station', 'ARK', 'ES1', 'FEB', 'FN1', 'FN3', 'LTK', 'HSN1', 'HSN2'};
            app.StationnameDropDown.ValueChangedFcn = createCallbackFcn(app, @StationnameDropDownValueChanged, true);
            app.StationnameDropDown.Tooltip = {'select hyfrophone station'};
            app.StationnameDropDown.Value = 'Select Station';
            app.FileDurationEditFieldLabel = uilabel(app.SettingsPanel);
            app.FileDurationEditFieldLabel.Text = 'File Duration';
            app.FileDurationEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.FileDurationEditField.Limits = [0 Inf];
            app.FileDurationEditField.ValueDisplayFormat = '%.2f';
            app.FileDurationEditField.ValueChangedFcn = createCallbackFcn(app, @FileDurationEditFieldValueChanged, true);
            app.FileDurationEditField.Tooltip = {'enter duration of each recording in seconds'};
            app.GetfromfirstfileButton = uibutton(app.SettingsPanel, 'push');
            app.GetfromfirstfileButton.ButtonPushedFcn = createCallbackFcn(app, @GetfromfirstfileButtonPushed, true);
            app.GetfromfirstfileButton.Tooltip = {'get duration from first file in Path > 5min - is done automatically if File Duration is set to 0'};
            app.GetfromfirstfileButton.Text = 'Get from first file';
            app.QCfilesButton = uibutton(app.SettingsPanel, 'push');
            app.QCfilesButton.ButtonPushedFcn = createCallbackFcn(app, @QCfilesButtonPushed, true);
            app.QCfilesButton.Tooltip = {'select path, where qc-log  .pdf shall be stored'};
            app.QCfilesButton.Text = 'QC files';
            app.HourSpinnerLabel = uilabel(app.SettingsPanel);
            app.HourSpinnerLabel.Text = 'Hour';
            app.HourSpinner = uispinner(app.SettingsPanel);
            app.HourSpinner.Limits = [0 24];
            app.HourSpinner.ValueChangedFcn = createCallbackFcn(app, @HourSpinnerValueChanged, true);
            app.HourSpinner.Tooltip = {'select hour of the day (0-24), when hydrophone was deployed'};
            app.HourSpinner_2Label = uilabel(app.SettingsPanel);
            app.HourSpinner_2Label.Text = 'Hour';
            app.HourSpinner_2 = uispinner(app.SettingsPanel);
            app.HourSpinner_2.Limits = [0 24];
            app.HourSpinner_2.ValueChangedFcn = createCallbackFcn(app, @HourSpinner_2ValueChanged, true);
            app.HourSpinner_2.Tooltip = {'select hour of the day (0-24), when hydrophone was recovered'};
            app.Lamp_6 = uilamp(app.SettingsPanel);
            app.Lamp_7 = uilamp(app.SettingsPanel);
            app.DeploymentDatePickerLabel_2 = uilabel(app.SettingsPanel);
            app.DeploymentDatePickerLabel_2.Text = 'Select path';
            app.DeploymentDatePickerLabel_3 = uilabel(app.SettingsPanel);
            app.DeploymentDatePickerLabel_3.Text = 'Select path';
            app.DutyCyclePanel = uipanel(app.QCTab);
            app.DutyCyclePanel.Title = 'Duty Cycle';
            app.DutyCycleSwitchLabel = uilabel(app.DutyCyclePanel);
            app.DutyCycleSwitchLabel.Text = 'Duty Cycle';
            app.DutyCycleSwitch = uiswitch(app.DutyCyclePanel, 'slider');
            app.DutyCycleSwitch.Items = {'Yes', 'No'};
            app.DutyCycleSwitch.ValueChangedFcn = createCallbackFcn(app, @DutyCycleSwitchValueChanged, true);
            app.DutyCycleSwitch.Value = 'No';
            app.IntervalsecEditFieldLabel = uilabel(app.DutyCyclePanel);
            app.IntervalsecEditFieldLabel.Text = 'Interval (sec)';
            app.IntervalsecEditField = uieditfield(app.DutyCyclePanel, 'numeric');
            app.IntervalsecEditField.Limits = [0 Inf];
            app.IntervalsecEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalsecEditFieldValueChanged, true);
            app.IntervalsecEditField.Editable = 'off';
            app.IntervalsecEditField.Enable = 'off';
            app.IntervalsecEditField.Tooltip = {'enter measurement interval in seconds'};
            app.DC_Text = uilabel(app.DutyCyclePanel);
            app.DC_Text.Text = 'Record for a period of ... min once every ... min';
            app.Image = uiimage(app.QCTab);
            app.Image.ImageSource = 'index2.png';
            app.RUNButton = uibutton(app.QCTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Tooltip = {'run quality control and save results to pdf'};
            app.RUNButton.Text = 'RUN';
            app.FlagFilesButton = uibutton(app.QCTab, 'push');
            app.FlagFilesButton.ButtonPushedFcn = createCallbackFcn(app, @FlagFilesButtonPushed, true);
            app.FlagFilesButton.Enable = 'off';
            app.FlagFilesButton.Text = 'Flag Files';
            app.ViewLogFileButton = uibutton(app.QCTab, 'push');
            app.ViewLogFileButton.ButtonPushedFcn = createCallbackFcn(app, @ViewLogFileButtonPushed, true);
            app.ViewLogFileButton.Enable = 'off';
            app.ViewLogFileButton.Text = 'View Log File';
            app.RemoveFlagsButton = uibutton(app.QCTab, 'push');
            app.RemoveFlagsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFlagsButtonPushed, true);
            app.RemoveFlagsButton.Enable = 'off';
            app.RemoveFlagsButton.Text = 'Remove Flags';
            app.GetdatetimePanel_2 = uipanel(app.QCTab);
            app.GetdatetimePanel_2.Title = 'Get datetime';
            app.TEXT_2 = uilabel(app.GetdatetimePanel_2);
            app.TEXT_2.Text = {'The data of the first file in the source directory is:'; 'yymmddHHMMSS. The renamed file would be: XXX_yyMMddHHmmss.wav'};
            app.FiledateEditField_2Label = uilabel(app.GetdatetimePanel_2);
            app.FiledateEditField_2Label.Text = 'Filedate';
            app.FiledateEditField_2 = uieditfield(app.GetdatetimePanel_2, 'text');
            app.FiledateEditField_2.ValueChangedFcn = createCallbackFcn(app, @FiledateEditField_2ValueChanged, true);
            app.FiledateEditField_2.Value = '-----yyMMddHHmmss.wav';
            app.RenametoStandardButton = uibutton(app.GetdatetimePanel_2, 'push');
            app.RenametoStandardButton.ButtonPushedFcn = createCallbackFcn(app, @RenametoStandardButtonPushed, true);
            app.RenametoStandardButton.Enable = 'off';
            app.RenametoStandardButton.Text = 'Rename to Standard';
            app.AgreeCheckBox = uicheckbox(app.GetdatetimePanel_2);
            app.AgreeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AgreeCheckBoxValueChanged, true);
            app.AgreeCheckBox.Text = 'Agree!';
            app.RemovalofleadingandtrailingsecondsPanel = uipanel(app.QCTab);
            app.RemovalofleadingandtrailingsecondsPanel.Title = 'Removal of leading and trailing seconds';
            app.RemoveleadingsecondsofeachfileCheckBox = uicheckbox(app.RemovalofleadingandtrailingsecondsPanel);
            app.RemoveleadingsecondsofeachfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveleadingsecondsofeachfileCheckBoxValueChanged, true);
            app.RemoveleadingsecondsofeachfileCheckBox.Enable = 'off';
            app.RemoveleadingsecondsofeachfileCheckBox.Tooltip = {'Removal of first two seconds might be necessary when processing data from ST600 to remove click sounds'};
            app.RemoveleadingsecondsofeachfileCheckBox.Text = 'Remove leading seconds of each file';
            app.leadsecseditfield = uieditfield(app.RemovalofleadingandtrailingsecondsPanel, 'numeric');
            app.leadsecseditfield.ValueChangedFcn = createCallbackFcn(app, @leadsecseditfieldValueChanged, true);
            app.leadsecseditfield.Editable = 'off';
            app.leadsecseditfield.Enable = 'off';
            app.secondsLabel = uilabel(app.RemovalofleadingandtrailingsecondsPanel);
            app.secondsLabel.Enable = 'off';
            app.secondsLabel.Text = 'seconds';
            app.RemovetrailingsecondsofeachfileCheckBox = uicheckbox(app.RemovalofleadingandtrailingsecondsPanel);
            app.RemovetrailingsecondsofeachfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemovetrailingsecondsofeachfileCheckBoxValueChanged, true);
            app.RemovetrailingsecondsofeachfileCheckBox.Enable = 'off';
            app.RemovetrailingsecondsofeachfileCheckBox.Text = 'Remove trailing seconds of each file';
            app.trailsecseditfield = uieditfield(app.RemovalofleadingandtrailingsecondsPanel, 'numeric');
            app.trailsecseditfield.ValueChangedFcn = createCallbackFcn(app, @trailsecseditfieldValueChanged, true);
            app.trailsecseditfield.Editable = 'off';
            app.trailsecseditfield.Enable = 'off';
            app.secondsLabel_2 = uilabel(app.RemovalofleadingandtrailingsecondsPanel);
            app.secondsLabel_2.Enable = 'off';
            app.secondsLabel_2.Text = 'seconds';
            app.CutButton = uibutton(app.RemovalofleadingandtrailingsecondsPanel, 'push');
            app.CutButton.ButtonPushedFcn = createCallbackFcn(app, @CutButtonPushed, true);
            app.CutButton.Enable = 'off';
            app.CutButton.Text = 'Cut';
            app.CreateCopyEditFieldLabel = uilabel(app.RemovalofleadingandtrailingsecondsPanel);
            app.CreateCopyEditFieldLabel.Enable = 'off';
            app.CreateCopyEditFieldLabel.Text = 'Create Copy';
            app.CreateCopyEditField = uieditfield(app.RemovalofleadingandtrailingsecondsPanel, 'text');
            app.CreateCopyEditField.ValueChangedFcn = createCallbackFcn(app, @CreateCopyEditFieldValueChanged, true);
            app.CreateCopyEditField.Editable = 'off';
            app.CreateCopyEditField.Value = 'Select directory';
            app.CheckBox = uicheckbox(app.RemovalofleadingandtrailingsecondsPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.BrowseButton = uibutton(app.RemovalofleadingandtrailingsecondsPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            app.Copy2ArchiveTab = uitab(app.TabGroup);
            app.Copy2ArchiveTab.Title = 'Copy 2 Archive';
            app.SettingsPanel_2 = uipanel(app.Copy2ArchiveTab);
            app.SettingsPanel_2.Title = 'Settings';
            app.SeaDropDownLabel = uilabel(app.SettingsPanel_2);
            app.SeaDropDownLabel.Text = 'Sea';
            app.SeaDropDown = uidropdown(app.SettingsPanel_2);
            app.SeaDropDown.Items = {'Select Sea', 'Nordsee', 'Ostsee', 'Kampagne', 'Import'};
            app.SeaDropDown.ValueChangedFcn = createCallbackFcn(app, @SeaDropDownValueChanged, true);
            app.SeaDropDown.Value = 'Select Sea';
            app.StationDropDownLabel = uilabel(app.SettingsPanel_2);
            app.StationDropDownLabel.Text = 'Station';
            app.StationDropDown = uidropdown(app.SettingsPanel_2);
            app.StationDropDown.Items = {'Select Station', 'ARK', 'ES1', 'FEB', 'FN1', 'FN3', 'HSN', 'LTK'};
            app.StationDropDown.ValueChangedFcn = createCallbackFcn(app, @StationDropDownValueChanged, true);
            app.StationDropDown.Value = 'Select Station';
            app.ChannelDropDownLabel = uilabel(app.SettingsPanel_2);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.SettingsPanel_2);
            app.ChannelDropDown.Items = {'Select Channel', 'H1', 'H2', 'Multi'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'Select Channel';
            app.SearchButton = uibutton(app.SettingsPanel_2, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.Lamp = uilamp(app.SettingsPanel_2);
            app.Lamp2 = uilamp(app.SettingsPanel_2);
            app.Lamp_3 = uilamp(app.SettingsPanel_2);
            app.Lamp_4 = uilamp(app.SettingsPanel_2);
            app.TargetFolderDropDownLabel = uilabel(app.SettingsPanel_2);
            app.TargetFolderDropDownLabel.Enable = 'off';
            app.TargetFolderDropDownLabel.Text = 'Target Folder';
            app.TargetFolderDropDown = uidropdown(app.SettingsPanel_2);
            app.TargetFolderDropDown.Items = {'Select...', 'Data09', 'Data10', 'Data11', 'Data12'};
            app.TargetFolderDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetFolderDropDownValueChanged, true);
            app.TargetFolderDropDown.Enable = 'off';
            app.TargetFolderDropDown.Value = 'Select...';
            app.Lamp_5 = uilamp(app.SettingsPanel_2);
            app.TargetDirectoryDropDownLabel_2 = uilabel(app.SettingsPanel_2);
            app.TargetDirectoryDropDownLabel_2.Text = 'Source Directory';
            app.Image3 = uiimage(app.SettingsPanel_2);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @SpaceSnifferButtonPushed, true);
            app.Image3.Enable = 'off';
            app.Image3.Tooltip = {'SPACESNIFFER'};
            app.Image3.ImageSource = 'snifferdog.png';
            app.COPYButton = uibutton(app.Copy2ArchiveTab, 'push');
            app.COPYButton.ButtonPushedFcn = createCallbackFcn(app, @COPYButtonPushed, true);
            app.COPYButton.Enable = 'off';
            app.COPYButton.Text = 'COPY';
            app.ControlPanel = uipanel(app.Copy2ArchiveTab);
            app.ControlPanel.Title = 'Control';
            app.FiledateoffirstfileiscorrectCheckBox = uicheckbox(app.ControlPanel);
            app.FiledateoffirstfileiscorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @FiledateoffirstfileiscorrectCheckBoxValueChanged, true);
            app.FiledateoffirstfileiscorrectCheckBox.Text = 'Filedate of first file is correct ';
            app.Lamp_2 = uilamp(app.ControlPanel);
            app.Image2 = uiimage(app.Copy2ArchiveTab);
            app.Image2.ImageSource = 'index2.png';
            app.GetdatetimePanel = uipanel(app.Copy2ArchiveTab);
            app.GetdatetimePanel.Title = 'Get datetime';
            app.FiledateEditFieldLabel = uilabel(app.GetdatetimePanel);
            app.FiledateEditFieldLabel.Text = 'Filedate';
            app.FiledateEditField = uieditfield(app.GetdatetimePanel, 'text');
            app.FiledateEditField.ValueChangedFcn = createCallbackFcn(app, @FiledateEditFieldValueChanged, true);
            app.FiledateEditField.Editable = 'off';
            app.FiledateEditField.Enable = 'off';
            app.FiledateEditField.Value = '-----yymmdd------.wav';
            app.TEXT = uilabel(app.GetdatetimePanel);
            app.TEXT.Text = {'The data of the first file in the source directory is:'; 'yymmdd'};
            app.ClearoriginaldataPanel = uipanel(app.Copy2ArchiveTab);
            app.ClearoriginaldataPanel.Title = 'Clear original data';
            app.ViewTransferlogButton = uibutton(app.ClearoriginaldataPanel, 'push');
            app.ViewTransferlogButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTransferlogButtonPushed, true);
            app.ViewTransferlogButton.Enable = 'off';
            app.ViewTransferlogButton.Text = 'View Transferlog';
            app.DELETEButton = uibutton(app.ClearoriginaldataPanel, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Enable = 'off';
            app.DELETEButton.Tooltip = {'THIS WILL DELETE THE ORIGINAL DATA'};
            app.DELETEButton.Text = 'DELETE ';
            app.Lamp_8 = uilamp(app.ClearoriginaldataPanel);
            app.Lamp_9 = uilamp(app.ClearoriginaldataPanel);
            app.SURESwitchLabel = uilabel(app.ClearoriginaldataPanel);
            app.SURESwitchLabel.Text = 'SURE ???';
            app.SURESwitch = uiswitch(app.ClearoriginaldataPanel, 'toggle');
            app.SURESwitch.Items = {'No', 'Yes'};
            app.SURESwitch.ValueChangedFcn = createCallbackFcn(app, @SURESwitchValueChanged, true);
            app.SURESwitch.Value = 'No';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.UIAxes = uiaxes(app.CalibrationTab);
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'amplitude')
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.YTick = [-1 -0.5 0 0.5 1];
            app.UIAxes.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.UIAxes2 = uiaxes(app.CalibrationTab);
            xlabel(app.UIAxes2, 'frequency [Hz]')
            ylabel(app.UIAxes2, 'SPL [dB]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [10 40000];
            app.UIAxes2.YLim = [0 170];
            app.UIAxes2.XTick = [10 100 1000 10000];
            app.UIAxes2.XScale = 'log';
            app.InputwavandVoltagePanel = uipanel(app.CalibrationTab);
            app.InputwavandVoltagePanel.Title = 'Input .wav and Voltage';
            app.SearchButton_2 = uibutton(app.InputwavandVoltagePanel, 'push');
            app.SearchButton_2.ButtonPushedFcn = createCallbackFcn(app, @SearchButton_2Pushed, true);
            app.SearchButton_2.Text = 'Search';
            app.TargetDirectoryDropDownLabel_3 = uilabel(app.InputwavandVoltagePanel);
            app.TargetDirectoryDropDownLabel_3.Text = 'Source Directory';
            app.Lamp_10 = uilamp(app.InputwavandVoltagePanel);
            app.RMSVoltageVEditFieldLabel = uilabel(app.InputwavandVoltagePanel);
            app.RMSVoltageVEditFieldLabel.Text = 'RMS Voltage (V)';
            app.RMSVoltageVEditField = uieditfield(app.InputwavandVoltagePanel, 'numeric');
            app.RMSVoltageVEditField.Limits = [0 0.5];
            app.RMSVoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @RMSVoltageVEditFieldValueChanged, true);
            app.Lamp_11 = uilamp(app.InputwavandVoltagePanel);
            app.RMSVoltageVEditFieldLabel_2 = uilabel(app.InputwavandVoltagePanel);
            app.RMSVoltageVEditFieldLabel_2.Text = 'Choose period';
            app.Lamp_12 = uilamp(app.InputwavandVoltagePanel);
            app.ChooseandComputeButton = uibutton(app.InputwavandVoltagePanel, 'push');
            app.ChooseandComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseandComputeButtonPushed, true);
            app.ChooseandComputeButton.Enable = 'off';
            app.ChooseandComputeButton.Text = 'Choose and Compute';
            app.SoundPressureinChamberEditFieldLabel = uilabel(app.CalibrationTab);
            app.SoundPressureinChamberEditFieldLabel.Text = 'Sound Pressure in Chamber';
            app.CalibrationFactordBEditFieldLabel = uilabel(app.CalibrationTab);
            app.CalibrationFactordBEditFieldLabel.Text = 'Calibration Factor (dB)';
            app.linearCalibrationFactorEditFieldLabel = uilabel(app.CalibrationTab);
            app.linearCalibrationFactorEditFieldLabel.Text = 'linear Calibration Factor';
            app.ResultsPanel = uipanel(app.CalibrationTab);
            app.ResultsPanel.Title = 'Results';
            app.SoundPressureinChamberdBLabel = uilabel(app.ResultsPanel);
            app.SoundPressureinChamberdBLabel.Text = 'Sound Pressure in Chamber dB';
            app.CalibrationLeveldBre1VPaLabel = uilabel(app.ResultsPanel);
            app.CalibrationLeveldBre1VPaLabel.Text = 'Calibration Level dB re 1V/µPa';
            app.LinearCalibrationFactorVPaLabel = uilabel(app.ResultsPanel);
            app.LinearCalibrationFactorVPaLabel.Text = 'Linear Calibration Factor V/µPa';
            app.linearCalibrationFactorEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.CalibrationFactordBEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.SoundPressureinChamberEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.BSoundHorPamguideTab = uitab(app.TabGroup);
            app.BSoundHorPamguideTab.Title = 'BSoundH or Pamguide';
            app.OpenBSoundHButton = uibutton(app.BSoundHorPamguideTab, 'push');
            app.OpenBSoundHButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBSoundHButtonPushed, true);
            app.OpenBSoundHButton.Text = 'Open BSoundH';
            app.Image4 = uiimage(app.BSoundHorPamguideTab);
            app.Image4.ImageSource = 'rolf.png';
            app.TEXT_3 = uilabel(app.BSoundHorPamguideTab);
            app.TEXT_3.Text = 'Processing in BSoundH';
            app.TEXT_4 = uilabel(app.BSoundHorPamguideTab);
            app.TEXT_4.Text = 'Remember the big 5';
            app.TEXT_5 = uilabel(app.BSoundHorPamguideTab);
            app.TEXT_5.Text = '1. Import one month of data by selecting the folder';
            app.TEXT_6 = uilabel(app.BSoundHorPamguideTab);
            app.TEXT_6.Text = '2. Set start date from file name and set files as fixed and alligned';
            app.TEXT_7 = uilabel(app.BSoundHorPamguideTab);
            app.TEXT_7.Text = '3. Set calibration factor';
            app.TEXT_8 = uilabel(app.BSoundHorPamguideTab);
            app.TEXT_8.Text = '4. Start Ambient Evaluation';
            app.TEXT_9 = uilabel(app.BSoundHorPamguideTab);
            app.TEXT_9.Text = '5. Export data (don''t forget to validate)';
            app.h5ConverterTab = uitab(app.TabGroup);
            app.h5ConverterTab.Title = 'h5 Converter';
            app.ImportBSoundHOutputfilesButton = uibutton(app.h5ConverterTab, 'push');
            app.ImportBSoundHOutputfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportBSoundHOutputfilesButtonPushed, true);
            app.ImportBSoundHOutputfilesButton.Text = 'Import BSoundHOutput file(s)';
            app.TabGroup2 = uitabgroup(app.h5ConverterTab);
            app.BSHformatTab = uitab(app.TabGroup2);
            app.BSHformatTab.Title = 'BSH format';
            app.BSHh5formatPanel = uipanel(app.BSHformatTab);
            app.BSHh5formatPanel.Title = 'BSH h5 format';
            app.authorEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.authorEditFieldLabel.Text = 'author';
            app.authorEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.authorEditField.ValueChangedFcn = createCallbackFcn(app, @authorEditFieldValueChanged, true);
            app.authorEditField.Value = 'test@test.com';
            app.dateofcreationDatePickerLabel = uilabel(app.BSHh5formatPanel);
            app.dateofcreationDatePickerLabel.Text = 'date of creation';
            app.dateofcreationDatePicker = uidatepicker(app.BSHh5formatPanel);
            app.dateofcreationDatePicker.Limits = [datetime([2013 1 1]) datetime([2055 4 4])];
            app.dateofcreationDatePicker.ValueChangedFcn = createCallbackFcn(app, @dateofcreationDatePickerValueChanged, true);
            app.measuringinstitutionEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.measuringinstitutionEditFieldLabel.Text = 'measuring institution';
            app.measuringinstitutionEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.measuringinstitutionEditField.ValueChangedFcn = createCallbackFcn(app, @measuringinstitutionEditFieldValueChanged, true);
            app.pointofcontactEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.pointofcontactEditFieldLabel.Text = 'point of contact';
            app.pointofcontactEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.pointofcontactEditField.ValueChangedFcn = createCallbackFcn(app, @pointofcontactEditFieldValueChanged, true);
            app.rawdatasamplingrateEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.rawdatasamplingrateEditFieldLabel.Text = 'rawdata samplingrate';
            app.rawdatasamplingrateEditField = uieditfield(app.BSHh5formatPanel, 'numeric');
            app.rawdatasamplingrateEditField.Limits = [0 Inf];
            app.rawdatasamplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @rawdatasamplingrateEditFieldValueChanged, true);
            app.rawdatauuidEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.rawdatauuidEditFieldLabel.Text = 'rawdata uuid';
            app.rawdatauuidEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.rawdatauuidEditField.Editable = 'off';
            app.calibrationdateDatePickerLabel = uilabel(app.BSHh5formatPanel);
            app.calibrationdateDatePickerLabel.Text = 'calibration date';
            app.calibrationdateDatePicker = uidatepicker(app.BSHh5formatPanel);
            app.calibrationdateDatePicker.Limits = [datetime([2013 1 1]) datetime([2055 4 4])];
            app.calibrationdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @calibrationdateDatePickerValueChanged, true);
            app.calibraionfactorunitEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.calibraionfactorunitEditFieldLabel.Text = 'calibraion factor unit';
            app.calibraionfactorunitEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.calibraionfactorunitEditField.ValueChangedFcn = createCallbackFcn(app, @calibraionfactorunitEditFieldValueChanged, true);
            app.calibrationlevelEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.calibrationlevelEditFieldLabel.Text = 'calibration level';
            app.calibrationlevelEditField = uieditfield(app.BSHh5formatPanel, 'numeric');
            app.calibrationlevelEditField.ValueChangedFcn = createCallbackFcn(app, @calibrationlevelEditFieldValueChanged, true);
            app.calibrationprocedureEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.calibrationprocedureEditFieldLabel.Text = 'calibration procedure ';
            app.calibrationprocedureEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.calibrationprocedureEditField.ValueChangedFcn = createCallbackFcn(app, @calibrationprocedureEditFieldValueChanged, true);
            app.calibrationfactorEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.calibrationfactorEditFieldLabel.Text = 'calibration factor';
            app.calibrationfactorEditField = uieditfield(app.BSHh5formatPanel, 'numeric');
            app.calibrationfactorEditField.ValueChangedFcn = createCallbackFcn(app, @calibrationfactorEditFieldValueChanged, true);
            app.commentsEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.commentsEditFieldLabel.Text = 'comments';
            app.commentsEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.commentsEditField.ValueChangedFcn = createCallbackFcn(app, @commentsEditFieldValueChanged, true);
            app.coordinatesmeasurementpositionEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.coordinatesmeasurementpositionEditFieldLabel.Text = 'coordinates measurement position';
            app.coordinatesmeasurementpositionEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.coordinatesmeasurementpositionEditField.ValueChangedFcn = createCallbackFcn(app, @coordinatesmeasurementpositionEditFieldValueChanged, true);
            app.coordinatesreferenceEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.coordinatesreferenceEditFieldLabel.Text = 'coordinates reference';
            app.coordinatesreferenceEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.coordinatesreferenceEditField.ValueChangedFcn = createCallbackFcn(app, @coordinatesreferenceEditFieldValueChanged, true);
            app.datasettypeEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.datasettypeEditFieldLabel.Text = 'dataset type';
            app.datasettypeEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.datasettypeEditField.ValueChangedFcn = createCallbackFcn(app, @datasettypeEditFieldValueChanged, true);
            app.datasetversionEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.datasetversionEditFieldLabel.Text = 'dataset version';
            app.datasetversionEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.datasetversionEditField.ValueChangedFcn = createCallbackFcn(app, @datasetversionEditFieldValueChanged, true);
            app.hydrophonemanufacturerEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.hydrophonemanufacturerEditFieldLabel.Text = 'hydrophone manufacturer';
            app.hydrophonemanufacturerEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.hydrophonemanufacturerEditField.ValueChangedFcn = createCallbackFcn(app, @hydrophonemanufacturerEditFieldValueChanged, true);
            app.hydrophonesensitivityEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.hydrophonesensitivityEditFieldLabel.Text = 'hydrophone sensitivity';
            app.hydrophonesensitivityEditField = uieditfield(app.BSHh5formatPanel, 'numeric');
            app.hydrophonesensitivityEditField.ValueChangedFcn = createCallbackFcn(app, @hydrophonesensitivityEditFieldValueChanged, true);
            app.hydrophonesensitivityunitEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.hydrophonesensitivityunitEditFieldLabel.Text = 'hydrophone sensitivity unit';
            app.hydrophonesensitivityunitEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.hydrophonesensitivityunitEditField.ValueChangedFcn = createCallbackFcn(app, @hydrophonesensitivityunitEditFieldValueChanged, true);
            app.hydrophoneserialnumberEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.hydrophoneserialnumberEditFieldLabel.Text = 'hydrophone serial number';
            app.hydrophoneserialnumberEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.hydrophoneserialnumberEditField.ValueChangedFcn = createCallbackFcn(app, @hydrophoneserialnumberEditFieldValueChanged, true);
            app.hydrophonetypeEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.hydrophonetypeEditFieldLabel.Text = 'hydrophone type';
            app.hydrophonetypeEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.hydrophonetypeEditField.ValueChangedFcn = createCallbackFcn(app, @hydrophonetypeEditFieldValueChanged, true);
            app.frequencycountEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.frequencycountEditFieldLabel.Text = 'frequency count';
            app.frequencycountEditField = uieditfield(app.BSHh5formatPanel, 'numeric');
            app.frequencycountEditField.ValueChangedFcn = createCallbackFcn(app, @frequencycountEditFieldValueChanged, true);
            app.GetButton = uibutton(app.BSHh5formatPanel, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.frequencycountEditFieldLabel_2 = uilabel(app.BSHh5formatPanel);
            app.frequencycountEditFieldLabel_2.Text = 'frequency index';
            app.leqaveragingtimeEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.leqaveragingtimeEditFieldLabel.Text = 'leq averaging time';
            app.leqaveragingtimeEditField = uieditfield(app.BSHh5formatPanel, 'numeric');
            app.leqaveragingtimeEditField.ValueChangedFcn = createCallbackFcn(app, @leqaveragingtimeEditFieldValueChanged, true);
            app.leqcountEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.leqcountEditFieldLabel.Text = 'leq count';
            app.leqcountEditField = uieditfield(app.BSHh5formatPanel, 'numeric');
            app.leqcountEditField.ValueChangedFcn = createCallbackFcn(app, @leqcountEditFieldValueChanged, true);
            app.GetButton_2 = uibutton(app.BSHh5formatPanel, 'push');
            app.GetButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetButton_2Pushed, true);
            app.GetButton_2.Text = 'Get';
            app.leqdatetimeindexLabel = uilabel(app.BSHh5formatPanel);
            app.leqdatetimeindexLabel.Text = 'leq datetime index';
            app.GetButton_3 = uibutton(app.BSHh5formatPanel, 'push');
            app.GetButton_3.ButtonPushedFcn = createCallbackFcn(app, @GetButton_3Pushed, true);
            app.GetButton_3.Text = 'Get';
            app.leqspectrotemporalvaluesLabel = uilabel(app.BSHh5formatPanel);
            app.leqspectrotemporalvaluesLabel.Text = 'leq spectro temporal values';
            app.GetButton_4 = uibutton(app.BSHh5formatPanel, 'push');
            app.GetButton_4.ButtonPushedFcn = createCallbackFcn(app, @GetButton_4Pushed, true);
            app.GetButton_4.Text = 'Get';
            app.leqtemporalvaluesLabel = uilabel(app.BSHh5formatPanel);
            app.leqtemporalvaluesLabel.Text = 'leq temporal values';
            app.measurementpurposeDropDownLabel = uilabel(app.BSHh5formatPanel);
            app.measurementpurposeDropDownLabel.Text = 'measurement purpose';
            app.measurementpurposeDropDown = uidropdown(app.BSHh5formatPanel);
            app.measurementpurposeDropDown.Items = {'Select ...', 'Commissioned measurements', 'HELCOM monitoring', 'National monitoring', 'OSPAR monitoring', 'Other', 'Project', 'Research'};
            app.measurementpurposeDropDown.ValueChangedFcn = createCallbackFcn(app, @measurementpurposeDropDownValueChanged, true);
            app.measurementpurposeDropDown.Value = 'Select ...';
            app.measurementheightEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.measurementheightEditFieldLabel.Text = 'measurement height';
            app.measurementheightEditField = uieditfield(app.BSHh5formatPanel, 'numeric');
            app.measurementheightEditField.ValueChangedFcn = createCallbackFcn(app, @measurementheightEditFieldValueChanged, true);
            app.measurementsetupDropDownLabel = uilabel(app.BSHh5formatPanel);
            app.measurementsetupDropDownLabel.Text = 'measurement setup';
            app.measurementsetupDropDown = uidropdown(app.BSHh5formatPanel);
            app.measurementsetupDropDown.Items = {'Select ...', 'Autonomous', 'Cable mounted', 'Combined'};
            app.measurementsetupDropDown.ValueChangedFcn = createCallbackFcn(app, @measurementsetupDropDownValueChanged, true);
            app.measurementsetupDropDown.Value = 'Select ...';
            app.namemeasurementpositionEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.namemeasurementpositionEditFieldLabel.Text = 'name measurement position';
            app.namemeasurementpositionEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.namemeasurementpositionEditField.ValueChangedFcn = createCallbackFcn(app, @namemeasurementpositionEditFieldValueChanged, true);
            app.recordermanufacturerEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.recordermanufacturerEditFieldLabel.Text = 'recorder manufacturer';
            app.recordermanufacturerEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.recordermanufacturerEditField.ValueChangedFcn = createCallbackFcn(app, @recordermanufacturerEditFieldValueChanged, true);
            app.recorderserialnumberEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.recorderserialnumberEditFieldLabel.Text = 'recorder serial number';
            app.recorderserialnumberEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.recorderserialnumberEditField.ValueChangedFcn = createCallbackFcn(app, @recorderserialnumberEditFieldValueChanged, true);
            app.recordertypeEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.recordertypeEditFieldLabel.Text = 'recorder type';
            app.recordertypeEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.recordertypeEditField.ValueChangedFcn = createCallbackFcn(app, @recordertypeEditFieldValueChanged, true);
            app.Lamp_author = uilamp(app.BSHh5formatPanel);
            app.Lamp_recorder_serial_number = uilamp(app.BSHh5formatPanel);
            app.Lamp_date_of_creation = uilamp(app.BSHh5formatPanel);
            app.Lamp_measuring_institution = uilamp(app.BSHh5formatPanel);
            app.Lamp_point_of_contact = uilamp(app.BSHh5formatPanel);
            app.Lamp_rawdata_samplingrate = uilamp(app.BSHh5formatPanel);
            app.Lamp_rawdata_uuid = uilamp(app.BSHh5formatPanel);
            app.Lamp_calibration_date = uilamp(app.BSHh5formatPanel);
            app.Lamp_calibration_factor = uilamp(app.BSHh5formatPanel);
            app.Lamp_calibration_factor_unit = uilamp(app.BSHh5formatPanel);
            app.Lamp_calibration_level = uilamp(app.BSHh5formatPanel);
            app.Lamp_calibration_procedure = uilamp(app.BSHh5formatPanel);
            app.Lamp_comments = uilamp(app.BSHh5formatPanel);
            app.Lamp_coordinates_measurement_position = uilamp(app.BSHh5formatPanel);
            app.Lamp_coordinates_reference = uilamp(app.BSHh5formatPanel);
            app.Lamp_dataset_type = uilamp(app.BSHh5formatPanel);
            app.Lamp_dataset_version = uilamp(app.BSHh5formatPanel);
            app.Lamp_hydrophone_manufacturer = uilamp(app.BSHh5formatPanel);
            app.Lamp_hydrophone_sensitivity = uilamp(app.BSHh5formatPanel);
            app.Lamp_hydrophone_sensitivity_unit = uilamp(app.BSHh5formatPanel);
            app.Lamp_hydrophone_serial_number = uilamp(app.BSHh5formatPanel);
            app.Lamp_hydrophone_type = uilamp(app.BSHh5formatPanel);
            app.Lamp_duty_cycle = uilamp(app.BSHh5formatPanel);
            app.Lamp_frequency_count = uilamp(app.BSHh5formatPanel);
            app.Lamp_frequency_index = uilamp(app.BSHh5formatPanel);
            app.Lamp_leq_averaging_time = uilamp(app.BSHh5formatPanel);
            app.Lamp_leq_count = uilamp(app.BSHh5formatPanel);
            app.Lamp_leq_datetime_index = uilamp(app.BSHh5formatPanel);
            app.Lamp_leq_spectro_temporal_values = uilamp(app.BSHh5formatPanel);
            app.Lamp_leq_temporal_values = uilamp(app.BSHh5formatPanel);
            app.Lamp_measurement_purpose = uilamp(app.BSHh5formatPanel);
            app.Lamp_measurement_height = uilamp(app.BSHh5formatPanel);
            app.Lamp_measurement_setup = uilamp(app.BSHh5formatPanel);
            app.Lamp_name_measurement_position = uilamp(app.BSHh5formatPanel);
            app.Lamp_recorder_manufacturer = uilamp(app.BSHh5formatPanel);
            app.Lamp_recorder_type = uilamp(app.BSHh5formatPanel);
            app.CreateButton = uibutton(app.BSHh5formatPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.referencefrequencieslevelsEditFieldLabel = uilabel(app.BSHh5formatPanel);
            app.referencefrequencieslevelsEditFieldLabel.Text = 'reference frequencies levels';
            app.referencefrequencieslevelsEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.referencefrequencieslevelsEditField.ValueChangedFcn = createCallbackFcn(app, @referencefrequencieslevelsEditFieldValueChanged, true);
            app.Lamp_reference_frequencies_levels = uilamp(app.BSHh5formatPanel);
            app.referencefrequencieslevelsEditField2 = uieditfield(app.BSHh5formatPanel, 'numeric');
            app.referencefrequencieslevelsEditField2.ValueChangedFcn = createCallbackFcn(app, @referencefrequencieslevelsEditField2ValueChanged, true);
            app.dutycycleEditField_2Label = uilabel(app.BSHh5formatPanel);
            app.dutycycleEditField_2Label.Text = 'duty cycle';
            app.dutycycleEditField = uieditfield(app.BSHh5formatPanel, 'text');
            app.dutycycleEditField.ValueChangedFcn = createCallbackFcn(app, @dutycycleEditFieldValueChanged2, true);
            app.Lamp_leq_spectrotemporalstats = uilamp(app.BSHh5formatPanel);
            app.CalculateButton = uibutton(app.BSHh5formatPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.spectrotemporalstatsLabel = uilabel(app.BSHh5formatPanel);
            app.spectrotemporalstatsLabel.Text = 'spectrotemporal stats';
            app.Lamp_leq_temporalstats = uilamp(app.BSHh5formatPanel);
            app.CalculateButton_2 = uibutton(app.BSHh5formatPanel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.temporalstatsLabel = uilabel(app.BSHh5formatPanel);
            app.temporalstatsLabel.Text = 'temporal stats';
            app.ICESformatTab = uitab(app.TabGroup2);
            app.ICESformatTab.Title = 'ICES format';
            app.ICEShdf5formatPanel = uipanel(app.ICESformatTab);
            app.ICEShdf5formatPanel.Title = 'ICES hdf5 format';
            app.ICES_CreationTimeEditFieldLabel = uilabel(app.ICEShdf5formatPanel);
            app.ICES_CreationTimeEditFieldLabel.Text = 'Time';
            app.ICES_StartTimeEditField_2Label = uilabel(app.ICEShdf5formatPanel);
            app.ICES_StartTimeEditField_2Label.Text = 'Time';
            app.ICES_TimeEditField_3Label = uilabel(app.ICEShdf5formatPanel);
            app.ICES_TimeEditField_3Label.Text = 'Time';
            app.ICES_Lamp_Email = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_CreationDate = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_StartDate = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_EndDate = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_Institution = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_Contact = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_CountryCode = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_StationCode = uilamp(app.ICEShdf5formatPanel);
            app.EmailEditFieldLabel = uilabel(app.ICEShdf5formatPanel);
            app.EmailEditFieldLabel.Text = 'Email';
            app.ICES_EmailEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.ICES_EmailEditField.ValueChangedFcn = createCallbackFcn(app, @ICES_EmailEditFieldValueChanged, true);
            app.ICES_EmailEditField.Value = 'test@test.com';
            app.CreationDateDatePickerLabel = uilabel(app.ICEShdf5formatPanel);
            app.CreationDateDatePickerLabel.Text = 'Creation Date';
            app.ICES_CreationDateDatePicker = uidatepicker(app.ICEShdf5formatPanel);
            app.ICES_CreationDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @ICES_CreationDateDatePickerValueChanged, true);
            app.StartDateDatePickerLabel = uilabel(app.ICEShdf5formatPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.ICES_StartDateDatePicker = uidatepicker(app.ICEShdf5formatPanel);
            app.EndDateDatePickerLabel = uilabel(app.ICEShdf5formatPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.ICES_EndDateDatePicker = uidatepicker(app.ICEShdf5formatPanel);
            app.ContactEditFieldLabel = uilabel(app.ICEShdf5formatPanel);
            app.ContactEditFieldLabel.Text = 'Contact';
            app.ICES_ContactEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.ICES_ContactEditField.Value = 'test@test.com';
            app.CountryCodeDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.CountryCodeDropDownLabel.Text = 'Country Code';
            app.ICES_CountryCodeDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_CountryCodeDropDown.Items = {'Select ...', 'BE', 'DE', 'DK', 'EE', 'FI', 'LT', 'LV', 'NL', 'NO', 'PL', 'RU', 'SCO', 'SE', 'UKE'};
            app.ICES_CountryCodeDropDown.Value = 'Select ...';
            app.InstitutionEditFieldLabel = uilabel(app.ICEShdf5formatPanel);
            app.InstitutionEditFieldLabel.Text = 'Institution';
            app.ICES_InstitutionEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.ICES_InstitutionEditField.Value = 'Search';
            app.SelectDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.SelectDropDownLabel.Text = 'Select';
            app.ICES_SelectDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_SelectDropDown.Items = {'Search first ...', ''};
            app.ICES_SelectDropDown.Value = 'Search first ...';
            app.StationCodeEditFieldLabel = uilabel(app.ICEShdf5formatPanel);
            app.StationCodeEditFieldLabel.Text = 'Station Code';
            app.ICES_StationCodeEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.ICES_StationCodeEditField.Value = 'Search';
            app.SelectDropDown_2Label = uilabel(app.ICEShdf5formatPanel);
            app.SelectDropDown_2Label.Text = 'Select';
            app.ICES_SelectDropDown_StationCode = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_SelectDropDown_StationCode.Items = {'Search first ...', ''};
            app.ICES_SelectDropDown_StationCode.Value = 'Search first ...';
            app.ICES_CreationTimeEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.ICES_CreationTimeEditField.Value = 'HH:mm';
            app.ICES_StartTimeEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.ICES_StartTimeEditField.Value = 'HH:mm';
            app.ICES_EndTimeEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.ICES_EndTimeEditField.Value = 'HH:mm';
            app.ICES_Lamp_HydrophoneType = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_HydrophoneSerialNumber = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_RecorderType = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_RecorderSerialNumber = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_MeasurementHeight = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_MeasurementPurpose = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_MeasurementSetup = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_RigDesign = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_FrequencyCount = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_FrequencyIndex = uilamp(app.ICEShdf5formatPanel);
            app.ICES_FrequencyIndexPanel = uipanel(app.ICEShdf5formatPanel);
            app.ICES_FrequencyIndexPanel.Title = '             Frequency Index';
            app.individualFrequencyBandsCheckBox = uicheckbox(app.ICES_FrequencyIndexPanel);
            app.individualFrequencyBandsCheckBox.Text = 'individual Frequency- Bands';
            app.consecutiveThirdOctaveBandsCheckBox = uicheckbox(app.ICES_FrequencyIndexPanel);
            app.consecutiveThirdOctaveBandsCheckBox.Text = 'consecutive Third-Octave Bands';
            app.consecutiveThirdOctaveBandsCheckBox.Value = true;
            app.LowestTOBDropDownLabel = uilabel(app.ICES_FrequencyIndexPanel);
            app.LowestTOBDropDownLabel.Text = 'Lowest TOB';
            app.LowestTOBDropDown = uidropdown(app.ICES_FrequencyIndexPanel);
            app.LowestTOBDropDown.Items = {'Select ...', '10', '12.5', '16', '20', '25', '31.5', '40', '50', '63', '80', '100', '125', '160', '200', '250', '315', '400', '500', '630', '800', '1000', '1250', '1600', '2000', '2500', '3150', '4000', '5000', '6300', '8000', '10000', '12500', '16000', '20000', '40000', '80000', '160000', ''};
            app.LowestTOBDropDown.Value = 'Select ...';
            app.HighestTOBDropDownLabel = uilabel(app.ICES_FrequencyIndexPanel);
            app.HighestTOBDropDownLabel.Text = 'Highest TOB';
            app.HighestTOBDropDown = uidropdown(app.ICES_FrequencyIndexPanel);
            app.HighestTOBDropDown.Items = {'Select ...', '10', '12.5', '16', '20', '25', '31.5', '40', '50', '63', '80', '100', '125', '160', '200', '250', '315', '400', '500', '630', '800', '1000', '1250', '1600', '2000', '2500', '3150', '4000', '5000', '6300', '8000', '10000', '12500', '16000', '20000', '40000', '80000', '160000'};
            app.HighestTOBDropDown.Value = 'Select ...';
            app.FrequencyIndexEditField = uieditfield(app.ICES_FrequencyIndexPanel, 'text');
            app.FrequencyIndexEditField.Editable = 'off';
            app.FrequencyIndexEditField.Enable = 'off';
            app.FrequencyIndexEditField.Value = '[63,125,2000]';
            app.LoadFrequencyBandsfromCheckBox = uicheckbox(app.ICES_FrequencyIndexPanel);
            app.LoadFrequencyBandsfromCheckBox.Text = 'Load Frequency Bands from';
            app.fileButton = uibutton(app.ICES_FrequencyIndexPanel, 'push');
            app.fileButton.Enable = 'off';
            app.fileButton.Text = 'file';
            app.ICES_fromfileButton = uibutton(app.ICEShdf5formatPanel, 'push');
            app.ICES_fromfileButton.Text = 'from file';
            app.RecorderTypeDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.RecorderTypeDropDownLabel.Text = 'Recorder Type';
            app.ICES_RecorderTypeDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_RecorderTypeDropDown.Items = {'Select ...', 'Computer', 'JASCO Amar', 'Loggerhead DSG', 'Loggerhead DSG-ST', 'Loggerhead LS1/LS2', 'Loggerhead Medusa Acoustic Drifter', 'Loggerhead SNAP', 'Multi Electronique - Aural-M2', 'Multi Electronique - µAURAL', 'OceanInstruments SOUNDTRAP 300 HF', 'OceanInstruments SOUNDTRAP 300 STD', 'OceanInstruments SOUNDTRAP 500 HF', 'OceanInstruments SOUNDTRAP 500 STD', 'OceanInstruments SOUNDTRAP 600 HF', 'OceanInstruments SOUNDTRAP 600 STD', 'OceanSonics icListen 200kHz', 'RTSys EASDA14 320', 'RTSys Sylence-LP recorder', 'Wildlife SM2M', 'Wildlife SM2M+', 'Wildlife SM3M', 'Wildlife SM3M+', 'Wildlife SM4M'};
            app.ICES_RecorderTypeDropDown.Value = 'Select ...';
            app.RecorderSerialNumberEditFieldLabel = uilabel(app.ICEShdf5formatPanel);
            app.RecorderSerialNumberEditFieldLabel.Text = 'Recorder Serial Number';
            app.ICES_RecorderSerialNumberEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.MeasurementHeightEditFieldLabel = uilabel(app.ICEShdf5formatPanel);
            app.MeasurementHeightEditFieldLabel.Text = 'Measurement Height';
            app.ICES_MeasurementHeightEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.MeasurementPurposeDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.MeasurementPurposeDropDownLabel.Text = 'Measurement Purpose';
            app.ICES_MeasurementPurposeDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_MeasurementPurposeDropDown.Items = {'Select ...', 'Commissioned measurements', 'HELCOM monitoring', 'National monitoring', 'OSPAR monitoring', 'Other', 'Project', 'Research'};
            app.ICES_MeasurementPurposeDropDown.Value = 'Select ...';
            app.MeasurementSetupDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.MeasurementSetupDropDownLabel.Text = 'Measurement Setup';
            app.ICES_MeasurementSetupDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_MeasurementSetupDropDown.Items = {'Select ...', 'Autonomous', 'Cable mounted', 'Combined'};
            app.ICES_MeasurementSetupDropDown.Value = 'Select ...';
            app.RigDesignDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.RigDesignDropDownLabel.Text = 'Rig Design';
            app.ICES_RigDesignDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_RigDesignDropDown.Items = {'Select ...', 'Bottom frame', 'From vessel', 'Glider', 'Mooring with floating buoy', 'Other', 'Supended from surface', 'Vertical measurement chain'};
            app.ICES_RigDesignDropDown.Value = 'Select ...';
            app.FrequencyCountSpinnerLabel = uilabel(app.ICEShdf5formatPanel);
            app.FrequencyCountSpinnerLabel.Text = 'Frequency Count';
            app.ICES_FrequencyCountSpinner = uispinner(app.ICEShdf5formatPanel);
            app.ICES_FrequencyCountSpinner.Limits = [1 48];
            app.ICES_FrequencyCountSpinner.Value = 1;
            app.ICES_CreateUUIDButton = uibutton(app.ICEShdf5formatPanel, 'push');
            app.ICES_CreateUUIDButton.Text = 'Create UUID';
            app.ICES_Lamp_FrequencyUnit = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_ChannelCount = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_MeasurementTotalNo = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_MeasurementUnit = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_AveragingTime = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_ProcessingAlgorithm = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_DataUUID = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_DatasetVersion = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_CalibrationProcedure = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_CalibrationDatetime = uilamp(app.ICEShdf5formatPanel);
            app.ICES_Lamp_Comments = uilamp(app.ICEShdf5formatPanel);
            app.ICES_fromfileButton_2 = uibutton(app.ICEShdf5formatPanel, 'push');
            app.ICES_fromfileButton_2.Text = 'from file';
            app.FrequencyUnitDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.FrequencyUnitDropDownLabel.Text = 'Frequency Unit';
            app.ICES_FrequencyUnitDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_FrequencyUnitDropDown.Items = {'Select ...', 'Hertz'};
            app.ICES_FrequencyUnitDropDown.Value = 'Select ...';
            app.ChannelCountSpinnerLabel = uilabel(app.ICEShdf5formatPanel);
            app.ChannelCountSpinnerLabel.Text = 'Channel Count';
            app.ICES_ChannelCountSpinner = uispinner(app.ICEShdf5formatPanel);
            app.ICES_ChannelCountSpinner.Limits = [0 20];
            app.MeasurementUnitDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.MeasurementUnitDropDownLabel.Text = 'Measurement Unit';
            app.ICES_MeasurementUnitDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_MeasurementUnitDropDown.Items = {'Select ...', 'Sound pressure level - dB re 1µPa'};
            app.ICES_MeasurementUnitDropDown.Value = 'Select ...';
            app.AveragingTimeSpinnerLabel = uilabel(app.ICEShdf5formatPanel);
            app.AveragingTimeSpinnerLabel.Text = 'Averaging Time';
            app.ICES_AveragingTimeSpinner = uispinner(app.ICEShdf5formatPanel);
            app.ICES_AveragingTimeSpinner.Limits = [0 1200];
            app.ProcessingAlgorithmDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.ProcessingAlgorithmDropDownLabel.Text = 'Processing Algorithm';
            app.ICES_ProcessingAlgorithmDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_ProcessingAlgorithmDropDown.Items = {'Select ...', 'AU-BIAS (Tougaard 2019)', 'BIAS (Betke et al 2015)', 'Jomopans signal processing standard (L. Wang', 'Merchant et al (2015)'};
            app.ICES_ProcessingAlgorithmDropDown.Value = 'Select ...';
            app.DataUUIDEditFieldLabel = uilabel(app.ICEShdf5formatPanel);
            app.DataUUIDEditFieldLabel.Text = 'Data UUID';
            app.ICES_DataUUIDEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.ICES_DataUUIDEditField.Editable = 'off';
            app.DatasetVersionSpinnerLabel = uilabel(app.ICEShdf5formatPanel);
            app.DatasetVersionSpinnerLabel.Text = 'Dataset Version';
            app.ICES_DatasetVersionSpinner = uispinner(app.ICEShdf5formatPanel);
            app.ICES_DatasetVersionSpinner.Limits = [0 Inf];
            app.CalibrationProcedureDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.CalibrationProcedureDropDownLabel.Text = 'Calibration Procedure';
            app.ICES_CalibrationProcedureDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_CalibrationProcedureDropDown.Items = {'Select ...', 'Closed pressure chamber/pistonphone (250 Hz)', 'Closed pressure chamber/pistonphone (125 Hz)', 'Closed pressure chamber/pistonphone (multiple frequencies)', 'Electric', 'Free field in air', 'Hydrostatic pressure', 'Other', 'Open water calibration', 'Standing wave tank', 'Tank calibration'};
            app.ICES_CalibrationProcedureDropDown.Value = 'Select ...';
            app.MeasurementTotalNoEditFieldLabel = uilabel(app.ICEShdf5formatPanel);
            app.MeasurementTotalNoEditFieldLabel.Text = 'Measurement Total No';
            app.ICES_MeasurementTotalNoEditField = uieditfield(app.ICEShdf5formatPanel, 'numeric');
            app.ICES_MeasurementTotalNoEditField.RoundFractionalValues = 'on';
            app.CalibrationDatetimeDatePickerLabel = uilabel(app.ICEShdf5formatPanel);
            app.CalibrationDatetimeDatePickerLabel.Text = 'Calibration Datetime';
            app.ICES_CalibrationDatetimeDatePicker = uidatepicker(app.ICEShdf5formatPanel);
            app.TimeEditField_4Label = uilabel(app.ICEShdf5formatPanel);
            app.TimeEditField_4Label.Text = 'Time';
            app.ICES_TimeEditField_4 = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.ICES_TimeEditField_4.Value = 'HH:mm';
            app.CommentsTextAreaLabel = uilabel(app.ICEShdf5formatPanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.ICES_CommentsEditField = uitextarea(app.ICEShdf5formatPanel);
            app.ICES_SelectDatetimeVectorButton = uibutton(app.ICEShdf5formatPanel, 'push');
            app.ICES_SelectDatetimeVectorButton.Text = 'Select Datetime Vector';
            app.ICES_DateTimeLabel = uilabel(app.ICEShdf5formatPanel);
            app.ICES_DateTimeLabel.Text = 'DateTime';
            app.ICES_Lamp_DateTime = uilamp(app.ICEShdf5formatPanel);
            app.HydrophoneTypeDropDownLabel = uilabel(app.ICEShdf5formatPanel);
            app.HydrophoneTypeDropDownLabel.Text = 'Hydrophone Type';
            app.ICES_HydrophoneTypeDropDown = uidropdown(app.ICEShdf5formatPanel);
            app.ICES_HydrophoneTypeDropDown.Items = {'Select ...', 'Brüel & Kjaer 8103', 'Brüel & Kjaer 8104 ', 'Brüel & Kjaer 8105', 'Brüel & Kjaer 8106', 'COLMAR GP1516', 'DE-200', 'DE-600', 'DE-PRO', 'GeoSpectrum M14-360 ', 'GeoSpectrum M14-900', 'GeoSpectrum M15-360', 'GeoSpectrum M15-900', 'GeoSpectrum M36-100', 'GeoSpectrum M36-900', 'GeoSpectrum M5-360', 'GeoSpectrum M5-900', 'HTI-15-Min', 'HTI-90-UReftek 17', 'HTI-92-WB', 'HTI-94-SSQ', 'HTI-96-Min', 'HTI-97-DA', 'HTI-99-HF', 'HTI-99-UHF', 'Integrated in recorder', 'Neptune D/707H', 'Soundtrap external hydrophone', 'Sparton'};
            app.ICES_HydrophoneTypeDropDown.Value = 'Select ...';
            app.HydrophoneSerialNumberEditFieldLabel_2 = uilabel(app.ICEShdf5formatPanel);
            app.HydrophoneSerialNumberEditFieldLabel_2.Text = 'Hydrophone Serial Number';
            app.ICES_HydrophoneSerialNumberEditField = uieditfield(app.ICEShdf5formatPanel, 'text');
            app.ICES_LeqMatrixLabel = uilabel(app.ICEShdf5formatPanel);
            app.ICES_LeqMatrixLabel.Text = 'Leq Measurements';
            app.ICES_SelectLeqMatrixButton = uibutton(app.ICEShdf5formatPanel, 'push');
            app.ICES_SelectLeqMatrixButton.Text = 'Select Leq Matrix';
            app.ICES_Lamp_LeqMatrix = uilamp(app.ICEShdf5formatPanel);
            app.LoadtemplateButton = uibutton(app.h5ConverterTab, 'push');
            app.LoadtemplateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtemplateButtonPushed, true);
            app.LoadtemplateButton.Text = 'Load template';
            app.MovetoWorkspaceButton = uibutton(app.h5ConverterTab, 'push');
            app.MovetoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoWorkspaceButtonPushed, true);
            app.MovetoWorkspaceButton.Text = 'Move to Workspace';
            app.Exporthdf5Button = uibutton(app.h5ConverterTab, 'push');
            app.Exporthdf5Button.ButtonPushedFcn = createCallbackFcn(app, @Exporthdf5ButtonPushed, true);
            app.Exporthdf5Button.Text = 'Export hdf5';
            app.savetemplateButton = uibutton(app.h5ConverterTab, 'push');
            app.savetemplateButton.ButtonPushedFcn = createCallbackFcn(app, @savetemplateButtonPushed, true);
            app.savetemplateButton.Text = 'save template';
            app.SpectralQCTab = uitab(app.TabGroup);
            app.SpectralQCTab.Title = 'SpectralQC';
            app.InputPanel = uipanel(app.SpectralQCTab);
            app.InputPanel.Title = 'Input';
            app.ListBox = uilistbox(app.InputPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.Addhdf5filesButton = uibutton(app.InputPanel, 'push');
            app.Addhdf5filesButton.Text = 'Add .hdf5 file(s)';
            app.Removehdf5filesButton = uibutton(app.InputPanel, 'push');
            app.Removehdf5filesButton.Text = 'Remove .hdf5 file(s)';
            app.h5formatDropDown = uidropdown(app.InputPanel);
            app.h5formatDropDown.Items = {'BSH format', 'ICES format'};
            app.h5formatDropDown.Value = 'BSH format';
            app.SelectAnalysesPanel = uipanel(app.SpectralQCTab);
            app.SelectAnalysesPanel.Title = 'Select Analyses';
            app.PDSCheckBox_2 = uicheckbox(app.SelectAnalysesPanel);
            app.PDSCheckBox_2.Text = 'PDS';
            app.SpectrogramCheckBox = uicheckbox(app.SelectAnalysesPanel);
            app.SpectrogramCheckBox.Text = 'Spectrogram';
            app.AutoCorrelationCheckBox = uicheckbox(app.SelectAnalysesPanel);
            app.AutoCorrelationCheckBox.Text = 'Auto Correlation';
            app.ATLshippingCheckBox = uicheckbox(app.SelectAnalysesPanel);
            app.ATLshippingCheckBox.Text = 'ATL shipping';
            app.ATLintermittentCheckBox = uicheckbox(app.SelectAnalysesPanel);
            app.ATLintermittentCheckBox.Text = 'ATL intermittent';
            app.FAVCheckBox = uicheckbox(app.SelectAnalysesPanel);
            app.FAVCheckBox.Text = 'FAV';
            app.ViolinsCheckBox = uicheckbox(app.SelectAnalysesPanel);
            app.ViolinsCheckBox.Text = 'Violins';
            app.NoiseFloorCheckBox = uicheckbox(app.SelectAnalysesPanel);
            app.NoiseFloorCheckBox.Text = 'Noise Floor';
            app.TonalsCheckBox = uicheckbox(app.SelectAnalysesPanel);
            app.TonalsCheckBox.Text = 'Tonals';
            app.AcousticMetricsCheckBox = uicheckbox(app.SelectAnalysesPanel);
            app.AcousticMetricsCheckBox.Text = 'Acoustic Metrics';
            app.selectallCheckBox = uicheckbox(app.SelectAnalysesPanel);
            app.selectallCheckBox.Text = 'select all';
            app.QualityChecksPanel = uipanel(app.SpectralQCTab);
            app.QualityChecksPanel.Title = 'Quality Checks';
            app.StationarityCheckBox = uicheckbox(app.QualityChecksPanel);
            app.StationarityCheckBox.Text = 'Stationarity';
            app.ThresholdsMinMaxCheckBox = uicheckbox(app.QualityChecksPanel);
            app.ThresholdsMinMaxCheckBox.Text = 'Thresholds Min/Max';
            app.NoiseFloorCheckBox_2 = uicheckbox(app.QualityChecksPanel);
            app.NoiseFloorCheckBox_2.Text = 'Noise Floor';
            app.AutoCorrelationCheckBox_2 = uicheckbox(app.QualityChecksPanel);
            app.AutoCorrelationCheckBox_2.Text = 'Auto Correlation';
            app.PlausibilityCheckBox = uicheckbox(app.QualityChecksPanel);
            app.PlausibilityCheckBox.Text = 'Plausibility';
            app.LogicalCheckBox = uicheckbox(app.QualityChecksPanel);
            app.LogicalCheckBox.Text = 'Logical';
            app.selectallCheckBox_2 = uicheckbox(app.QualityChecksPanel);
            app.selectallCheckBox_2.Text = 'select all';
            app.ExportPanel = uipanel(app.SpectralQCTab);
            app.ExportPanel.Title = 'Export';
            app.SelectOutputpathButton = uibutton(app.ExportPanel, 'push');
            app.SelectOutputpathButton.Text = 'Select Output path';
            app.EditField = uieditfield(app.ExportPanel, 'text');
            app.pdfCheckBox = uicheckbox(app.ExportPanel);
            app.pdfCheckBox.Text = 'pdf';
            app.matCheckBox = uicheckbox(app.ExportPanel);
            app.matCheckBox.Text = 'mat';
            app.figCheckBox = uicheckbox(app.ExportPanel);
            app.figCheckBox.Text = 'fig';
            app.RUNButton_2 = uibutton(app.SpectralQCTab, 'push');
            app.RUNButton_2.Text = 'RUN';
            % Show the figure after all components are created
            app.QCTool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = betta_debugged_fixed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QCTool)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QCTool)
        end
    end
end