classdef test2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        QCTab                           matlab.ui.container.Tab
        SettingsPanel                   matlab.ui.container.Panel
        RawDataButton                   matlab.ui.control.Button
        DeploymentDatePickerLabel       matlab.ui.control.Label
        DeploymentDatePicker            matlab.ui.control.DatePicker
        RecoveryDatePickerLabel         matlab.ui.control.Label
        RecoveryDatePicker              matlab.ui.control.DatePicker
        StationnameDropDownLabel        matlab.ui.control.Label
        StationnameDropDown             matlab.ui.control.DropDown
        FileDurationEditFieldLabel      matlab.ui.control.Label
        FileDurationEditField           matlab.ui.control.NumericEditField
        GetfromfirstfileButton          matlab.ui.control.Button
        QCfilesButton                   matlab.ui.control.Button
        HourSpinnerLabel                matlab.ui.control.Label
        HourSpinner                     matlab.ui.control.Spinner
        HourSpinner_2Label              matlab.ui.control.Label
        HourSpinner_2                   matlab.ui.control.Spinner
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        DeploymentDatePickerLabel_2     matlab.ui.control.Label
        DeploymentDatePickerLabel_3     matlab.ui.control.Label
        DutyCyclePanel                  matlab.ui.container.Panel
        DutyCycleSwitchLabel            matlab.ui.control.Label
        DutyCycleSwitch                 matlab.ui.control.Switch
        IntervalsecEditFieldLabel       matlab.ui.control.Label
        IntervalsecEditField            matlab.ui.control.NumericEditField
        DC_Text                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RUNButton                       matlab.ui.control.Button
        FlagFilesButton                 matlab.ui.control.Button
        ViewLogFileButton               matlab.ui.control.Button
        RemoveFlagsButton               matlab.ui.control.Button
        GetdatetimePanel_2              matlab.ui.container.Panel
        TEXT_2                          matlab.ui.control.Label
        FiledateoffirstfileiscorrectCheckBox_2  matlab.ui.control.CheckBox
        Lamp_11                         matlab.ui.control.Lamp
        FiledateEditField_2Label        matlab.ui.control.Label
        FiledateEditField_2             matlab.ui.control.EditField
        Copy2ArchiveTab                 matlab.ui.container.Tab
        SettingsPanel_2                 matlab.ui.container.Panel
        SeaDropDownLabel                matlab.ui.control.Label
        SeaDropDown                     matlab.ui.control.DropDown
        StationDropDownLabel            matlab.ui.control.Label
        StationDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        SearchButton                    matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        TargetFolderDropDownLabel       matlab.ui.control.Label
        TargetFolderDropDown            matlab.ui.control.DropDown
        Lamp_5                          matlab.ui.control.Lamp
        TargetDirectoryDropDownLabel_2  matlab.ui.control.Label
        COPYButton                      matlab.ui.control.Button
        ControlPanel                    matlab.ui.container.Panel
        FiledateoffirstfileiscorrectCheckBox  matlab.ui.control.CheckBox
        Lamp_2                          matlab.ui.control.Lamp
        Image2                          matlab.ui.control.Image
        GetdatetimePanel                matlab.ui.container.Panel
        FiledateEditFieldLabel          matlab.ui.control.Label
        FiledateEditField               matlab.ui.control.EditField
        TEXT                            matlab.ui.control.Label
        ClearoriginaldataPanel          matlab.ui.container.Panel
        ViewTransferlogButton           matlab.ui.control.Button
        DELETEButton                    matlab.ui.control.Button
        Lamp_8                          matlab.ui.control.Lamp
        Lamp_9                          matlab.ui.control.Lamp
        SURESwitchLabel                 matlab.ui.control.Label
        SURESwitch                      matlab.ui.control.ToggleSwitch
        CalibrationTab                  matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        SearchButton_2                  matlab.ui.control.Button
        TargetDirectoryDropDownLabel_3  matlab.ui.control.Label
        Lamp_10                         matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
        BSoundHTab                      matlab.ui.container.Tab
        OpenBSoundHButton               matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        TEXT_3                          matlab.ui.control.Label
        TEXT_4                          matlab.ui.control.Label
        TEXT_5                          matlab.ui.control.Label
        TEXT_6                          matlab.ui.control.Label
        TEXT_7                          matlab.ui.control.Label
        TEXT_8                          matlab.ui.control.Label
        TEXT_9                          matlab.ui.control.Label
                                        matlab.ui.container.Tab
        SurenessGaugeLabel              matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        path = 'X:\Meereskunde\Unterwasserschall-Archiv\Import02\TEST\'% Path to raw .wav files
        qc_path = 'X:\Meereskunde\Unterwasserschall\AMSO23\NORDSEE\ES1\QC\' % path, where qc-log as pdf should be stored
        DateDeployment = datetime(2013,12,1);
        DateDeploymentHour = 0;
        DateRecovery = datetime(now,'ConvertFrom','datenum');
        DateRecoveryHour = 0;
        station = 'ES1';
        filedur = 0;
        DC = 'No';
        interval = 0;
        duration
        meanv
        sr
        stdrms
        ts
        ftime
        QC
        sea = 'NORDSEE' % Description
        channel = 'H1' % Description
        sourcedir % Description
        targetdir % Description
        yy
        mm
        dd% Description
        log % Description
        libpath = addpath('./libs') % adds path to index picture
        TL %Transferlog
        
        Jahr
        Monat
        Tag % Description
        Stunde
        Minute
        Sekunde % Description
        dtf % Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RawDataButton
        function RawDataButtonPushed(app, event)
            filepath = uigetdir('X:\Meereskunde\Unterwasserschall-Archiv\');
            drawnow;
            figure(app.UIFigure)
            if filepath(end) ~= '\'
                filepath = append(filepath,'\');
            end
%             assignin('base',"path",path)
            app.path = filepath;
            set(app.RemoveFlagsButton, 'Enable', 'on')
            app.Lamp_6.Color = 'g';
        end

        % Value changed function: DeploymentDatePicker
        function DeploymentDatePickerValueChanged(app, event)
            DateDeployment = app.DeploymentDatePicker.Value; 
%             assignin('base',"DateDeployment",DateDeployment)
            app.DateDeployment = DateDeployment;
        end

        % Value changed function: StationnameDropDown
        function StationnameDropDownValueChanged(app, event)
            station = app.StationnameDropDown.Value;
%             assignin('base',"station",station)
            app.station = station;
        end

        % Button pushed function: QCfilesButton
        function QCfilesButtonPushed(app, event)
           qc_path = uigetdir('X:\Meereskunde\Unterwasserschall\AMSO23\');
           drawnow;
           figure(app.UIFigure)
%            assignin('base',"qc_path",qc_path)
            if qc_path(end) ~= '\'
                qc_path = append(qc_path,'\');
            end
            app.qc_path = qc_path;
            app.Lamp_7.Color = 'g';
        end

        % Callback function
        function PathEditFieldValueChanged(app, event)
                filepath = app.PathEditField.Value;
%                 assignin('base',"path",path);
                if filepath(end) ~= '\'
                    filepath = append(filepath,'\');
                end
                app.path = path;
        end

        % Callback function
        function QClogpathEditFieldValueChanged(app, event)
            qc_path = app.QClogpathEditField.Value;
%             assignin('base',"qc_path",qc_path)
            if qc_path(end) ~= '\'
                qc_path = append(qc_path,'\');
            end
            app.qc_path = qc_path;            
        end

        % Value changed function: RecoveryDatePicker
        function RecoveryDatePickerValueChanged(app, event)
            DateRecovery = app.RecoveryDatePicker.Value;
%             assignin('base',"DateDeployment",DateDeployment)
            app.DateRecovery = DateRecovery;
        end

        % Value changed function: FileDurationEditField
        function FileDurationEditFieldValueChanged(app, event)
            filedur = app.FileDurationEditField.Value;
%             assignin('base',"filedur",filedur)
            app.filedur = filedur;
        end

        % Button pushed function: GetfromfirstfileButton
        function GetfromfirstfileButtonPushed(app, event)
            flist = dir([app.path '*.wav']);      
            for ii = 1:length(flist)
                filedur = audioinfo([app.path flist(ii).name]).Duration;
                if filedur < 540
                    continue
                else
                    app.filedur = filedur;
                    break
                end
            end
%           filedur = audioinfo([app.path flist(1).name]).Duration;
%             assignin('base',"filedur",filedur)
            
            app.FileDurationEditField.Value = filedur;
%             app.filedur=filedur;
%             filedur = app.FileDurationEditField.Value;
        end

        % Value changed function: HourSpinner
        function HourSpinnerValueChanged(app, event)
            value = app.HourSpinner.Value;
            app.DateDeploymentHour = value;
        end

        % Value changed function: HourSpinner_2
        function HourSpinner_2ValueChanged(app, event)
            value = app.HourSpinner_2.Value;
            app.DateRecoveryHour = value;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            DateD = datetime(app.DateDeployment)+hours(app.DateDeploymentHour);
            DateR = datetime(app.DateRecovery)+hours(app.DateRecoveryHour);
            disp('Now performing Quality Control ...')
            [app.QC,app.duration,app.meanv,app.sr,app.stdrms,app.ts,app.ftime] = QualityChecks(app.station,app.path,app.qc_path,DateD,DateR,app.filedur,app.interval,app.Jahr,app.Monat,app.Tag,app.Stunde,app.Minute,app.Sekunde,app.dtf);
            
            disp('Writing results to output pdf ... ')
            [app.log] = qc2pdf(app.QC,app.duration,app.meanv,app.sr,app.stdrms,app.ts,app.ftime,app.path,app.qc_path,app.station,DateD,DateR,app.filedur,app.interval)
            set(app.ViewLogFileButton, 'Enable', 'on')
            set(app.FlagFilesButton, 'Enable', 'on')
            
        end

        % Callback function
        function ProcessButtonPushed(app, event)
            
        end

        % Value changed function: DutyCycleSwitch
        function DutyCycleSwitchValueChanged(app, event)
            value = app.DutyCycleSwitch.Value;
            app.DC = value;
            if ( strcmpi(value,'Yes') )
                  app.IntervalsecEditField.Enable = 'On';
                  app.IntervalsecEditField.Editable = 'On';
            else
                  app.IntervalsecEditField.Enable = 'Off';
                  app.IntervalsecEditField.Editable = 'Off';
            end
        end

        % Callback function
        function FileDurationEditField_2ValueChanged(app, event)
            value = app.FileDurationEditField_2.Value;
            app.filedur = value;
        end

        % Value changed function: IntervalsecEditField
        function IntervalsecEditFieldValueChanged(app, event)
            value = app.IntervalsecEditField.Value;
            if value <= app.filedur
                errordlg('Interval must be larger than individual file duration')
            else
                app.interval = value;
                app.DC_Text.Text = ['Record for a period of ' num2str(app.filedur/60) ' min. once every ' num2str(app.interval/60)  ' min.']
            end
            
        end

        % Value changed function: SeaDropDown
        function SeaDropDownValueChanged(app, event)
            value = app.SeaDropDown.Value;
            if strcmp(value,'Select Sea') == 0
            app.sea = value
            app.Lamp_3.Color = 'g';
            end
        end

        % Value changed function: StationDropDown
        function StationDropDownValueChanged(app, event)
            value = app.StationDropDown.Value;
            if strcmp(value,'Select Station') == 0
            app.station = value;
            app.Lamp_4.Color = 'g';
            end
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            if strcmp(value,'Select Channel') == 0
            app.channel = value;
            app.Lamp_5.Color = 'g';
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.sourcedir = uigetdir('X:\Meereskunde\Unterwasserschall-Archiv\Import02\');
            app.sourcedir = [app.sourcedir '\'];
            drawnow;
            figure(app.UIFigure)
            app.Lamp.Color = 'g';

        end

        % Callback function
        function SettargetdirectoryButtonPushed(app, event)
            f=warndlg('If in any doubt please use the SPACESNIFFER to check if enough memory is available in the target directory!')
            waitfor(f);
            app.targetdir = uigetdir('X:\Meereskunde\Unterwasserschall-Archiv\Data10\');
            drawnow;
            figure(app.UIFigure)
            app.Lamp2.Color = 'g';
            %% enable 
            app.FiledateEditField.Enable = 'On';
            app.FiledateEditField.Editable = 'On';
        end

        % Value changed function: FiledateEditField
        function FiledateEditFieldValueChanged(app, event)
            value = app.FiledateEditField.Value;
            y = strfind(value,'yy');
            m = strfind(value,'mm');
            d = strfind(value,'dd');
            flist = dir([app.sourcedir '*.wav']);
            yy = flist(1).name(y:y+1);
            mm = flist(1).name(m:m+1);
            dd = flist(1).name(d:d+1);
            textLabel = [{'The data of the first file in the source directory is:'},...
                        {[dd,'.',mm,'.20',yy]}];
            app.TEXT.Text = textLabel;
            app.yy = y;
            app.mm = m;
            app.dd = d;

        end

        % Button pushed function: COPYButton
        function COPYButtonPushed(app, event)
            f = warndlg('Are you really sure you want to start copying ??? This might impair your network performance!');
            waitfor(f)
            drawnow;
            figure(app.UIFigure)
            status = copycheck(app.sourcedir,app.targetdir,app.sea,app.station,app.channel);
            if status == 1
                app.TL = C2A(app.sea,app.station,app.channel,app.sourcedir,app.targetdir,app.yy,app.mm,app.dd)
                app.ViewTransferlogButton.Enable = 'On';
            else
                h = warndlg('One or more files are allreay existent in target directory!')
                waitfor(h)
            end

        end

        % Value changed function: 
        % FiledateoffirstfileiscorrectCheckBox
        function FiledateoffirstfileiscorrectCheckBoxValueChanged(app, event)
            value = app.FiledateoffirstfileiscorrectCheckBox.Value;
            if value
                app.Lamp_2.Color = 'g';
                app.COPYButton.Enable = 'On';
            else
                app.Lamp_2.Color = 'r';
                app.COPYButton.Enable = 'Off';
            end
        end

        % Button pushed function: ViewLogFileButton
        function ViewLogFileButtonPushed(app, event)
%             rptview(app.log,'pdf')
winopen(app.log)
        end

        % Button pushed function: FlagFilesButton
        function FlagFilesButtonPushed(app, event)
addQCprefix(app.QC,app.path)
        end

        % Button pushed function: RemoveFlagsButton
        function RemoveFlagsButtonPushed(app, event)
removeQCprefix(app.path)            
        end

        % Image clicked function: Image3
        function SpaceSnifferButtonPushed(app, event)
            ! X:\Meereskunde\Unterwasserschall\Software\SpaceSniffer.exe
        end

        % Value changed function: TargetFolderDropDown
        function TargetFolderDropDownValueChanged(app, event)
            value = app.TargetFolderDropDown.Value;
            app.targetdir = ['X:\Meereskunde\Unterwasserschall-Archiv\' value];
            f = warndlg('Please ensure, that enough memory is available in the target directory. Use the SPACEScniffer to the right.')
            waitfor(f);
            if ~isequal(value,'Select...')
                app.Lamp2.Color = 'g';
            else 
                app.Lamp2.Color = 'r';
                app.COPYButton.Enable = 'Off';
                app.FiledateEditField.Enable = 'Off';
            end
            app.FiledateEditField.Enable = 'On';
            app.FiledateEditField.Editable = 'On';
        end

        % Button pushed function: ViewTransferlogButton
        function ViewTransferlogButtonPushed(app, event)
            winopen(app.TL)
            app.Lamp_8.Color = 'g';
            app.SURESwitch.Enable = 'On';

        end

        % Callback function
        function SwitchValueChanged(app, event)
            
        end

        % Callback function
        function Switch_4ValueChanged(app, event)
            
        end

        % Value changed function: SURESwitch
        function SURESwitchValueChanged(app, event)
            value = app.SURESwitch.Value;
            if isequal(value,'Yes')
                app.Lamp_9.Color = 'y';
                app.DELETEButton.Enable = 'On';
            else
                app.Lamp_9.Color = 'r';
                app.DELETEButton.Enable = 'Off';
            end
        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
            clear_import(app.sourcedir,app.targetdir,app.sea,app.station,app.channel,app.yy,app.mm,app.dd) 
        end

        % Button pushed function: SearchButton_2
        function SearchButton_2Pushed(app, event)
            [file,path] = uigetfile({'*wav','Audio Files (*wav)';'*.*','All Files (*.*)'}, ...
            'Select File','X:\Meereskunde\Unterwasserschall\AMSO23');   
            drawnow;
            app.Lamp_10.Color = 'g';
            signal = audioread([path file]);
            info = audioinfo([path file]);
            sr=info.SampleRate;
            xwerte = [1:length(signal)]/sr;
            plot(app.UIAxes,xwerte(1:100:end),signal(1:100:end));
            h=msgbox('Please click a valid start end time of the calibation signal in the graphic!');
            waitfor(h)
            gui_ginputax(2,app.UIAxes)


%             temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
%             start = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
%             xline(app.UIAxes,start(1),'r')
%             temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
%             ende = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
%             xline(app.UIAxes,ende(1),'r')
            
        end

        % Button pushed function: OpenBSoundHButton
        function OpenBSoundHButtonPushed(app, event)
            ! C:\SOUND\env379\Scripts\python.exe C:\SOUND\env\Lib\site-packages\bsoundh\app.py
        end

        % Value changed function: FiledateEditField_2
        function FiledateEditField_2ValueChanged(app, event)
            value = app.FiledateEditField_2.Value;
            y = strfind(value,'yy');
            m = strfind(value,'MM');
            d = strfind(value,'dd');
            H = strfind(value,'HH');
            M = strfind(value,'mm');
            S = strfind(value,'ss');
            flist = dir([app.path '*.wav']);
            yy = flist(1).name(y:y+1);
            mm = flist(1).name(m:m+1);
            dd = flist(1).name(d:d+1);
            HH = flist(1).name(H:H+1);
            MM = flist(1).name(M:M+1);
            SS = flist(1).name(S:S+1);
            textLabel = [{'The data of the first file in the source directory is:'},...
                        {[dd,'.',mm,'.20',yy,' ', HH,':',MM,':',SS]}];
            app.TEXT_2.Text = textLabel;
            app.Jahr = y;
            app.Monat = m;
            app.Tag = d;
            app.Stunde = H;
            app.Minute = M;
            app.Sekunde = S;
            app.dtf = value(y:S+1);
        end

        % Value changed function: 
        % FiledateoffirstfileiscorrectCheckBox_2
        function FiledateoffirstfileiscorrectCheckBox_2ValueChanged(app, event)
            value = app.FiledateoffirstfileiscorrectCheckBox_2.Value;
            if value ==1
                app.Lamp_11.Color = 'g';
                app.RUNButton.Enable = 'On';
            else
                app.Lamp_11.Color = 'r';
                app.RUNButton.Enable = 'Off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.QCTab = uitab(app.TabGroup);
            app.QCTab.Title = 'QC';
            app.SettingsPanel = uipanel(app.QCTab);
            app.SettingsPanel.Title = 'Settings';
            app.RawDataButton = uibutton(app.SettingsPanel, 'push');
            app.RawDataButton.ButtonPushedFcn = createCallbackFcn(app, @RawDataButtonPushed, true);
            app.RawDataButton.Tooltip = {'select path to raw .wav files'};
            app.RawDataButton.Text = 'Raw Data';
            app.DeploymentDatePickerLabel = uilabel(app.SettingsPanel);
            app.DeploymentDatePickerLabel.Text = 'Deployment';
            app.DeploymentDatePicker = uidatepicker(app.SettingsPanel);
            app.DeploymentDatePicker.Limits = [datetime([2013 1 12]) datetime([2099 12 31])];
            app.DeploymentDatePicker.ValueChangedFcn = createCallbackFcn(app, @DeploymentDatePickerValueChanged, true);
            app.DeploymentDatePicker.Tooltip = {'select date, when hydrophone was deployed'};
            app.RecoveryDatePickerLabel = uilabel(app.SettingsPanel);
            app.RecoveryDatePickerLabel.Text = 'Recovery';
            app.RecoveryDatePicker = uidatepicker(app.SettingsPanel);
            app.RecoveryDatePicker.ValueChangedFcn = createCallbackFcn(app, @RecoveryDatePickerValueChanged, true);
            app.RecoveryDatePicker.Tooltip = {'select date, when hydrophone was recovered'};
            app.StationnameDropDownLabel = uilabel(app.SettingsPanel);
            app.StationnameDropDownLabel.Text = 'Station name';
            app.StationnameDropDown = uidropdown(app.SettingsPanel);
            app.StationnameDropDown.Items = {'Select Staion', 'ES1', 'FN1', 'FN3'};
            app.StationnameDropDown.ValueChangedFcn = createCallbackFcn(app, @StationnameDropDownValueChanged, true);
            app.StationnameDropDown.Tooltip = {'select hyfrophone station'};
            app.StationnameDropDown.Value = 'Select Staion';
            app.FileDurationEditFieldLabel = uilabel(app.SettingsPanel);
            app.FileDurationEditFieldLabel.Text = 'File Duration';
            app.FileDurationEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.FileDurationEditField.Limits = [0 Inf];
            app.FileDurationEditField.ValueDisplayFormat = '%.2f';
            app.FileDurationEditField.ValueChangedFcn = createCallbackFcn(app, @FileDurationEditFieldValueChanged, true);
            app.FileDurationEditField.Tooltip = {'enter duration of each recording in seconds'};
            app.GetfromfirstfileButton = uibutton(app.SettingsPanel, 'push');
            app.GetfromfirstfileButton.ButtonPushedFcn = createCallbackFcn(app, @GetfromfirstfileButtonPushed, true);
            app.GetfromfirstfileButton.Tooltip = {'get duration from first file in Path > 5min - is done automatically if File Duration is set to 0'};
            app.GetfromfirstfileButton.Text = 'Get from first file';
            app.QCfilesButton = uibutton(app.SettingsPanel, 'push');
            app.QCfilesButton.ButtonPushedFcn = createCallbackFcn(app, @QCfilesButtonPushed, true);
            app.QCfilesButton.Tooltip = {'select path, where qc-log  .pdf shall be stored'};
            app.QCfilesButton.Text = 'QC files';
            app.HourSpinnerLabel = uilabel(app.SettingsPanel);
            app.HourSpinnerLabel.Text = 'Hour';
            app.HourSpinner = uispinner(app.SettingsPanel);
            app.HourSpinner.Limits = [0 24];
            app.HourSpinner.ValueChangedFcn = createCallbackFcn(app, @HourSpinnerValueChanged, true);
            app.HourSpinner.Tooltip = {'select hour of the day (0-24), when hydrophone was deployed'};
            app.HourSpinner_2Label = uilabel(app.SettingsPanel);
            app.HourSpinner_2Label.Text = 'Hour';
            app.HourSpinner_2 = uispinner(app.SettingsPanel);
            app.HourSpinner_2.Limits = [0 24];
            app.HourSpinner_2.ValueChangedFcn = createCallbackFcn(app, @HourSpinner_2ValueChanged, true);
            app.HourSpinner_2.Tooltip = {'select hour of the day (0-24), when hydrophone was recovered'};
            app.Lamp_6 = uilamp(app.SettingsPanel);
            app.Lamp_7 = uilamp(app.SettingsPanel);
            app.DeploymentDatePickerLabel_2 = uilabel(app.SettingsPanel);
            app.DeploymentDatePickerLabel_2.Text = 'Select path';
            app.DeploymentDatePickerLabel_3 = uilabel(app.SettingsPanel);
            app.DeploymentDatePickerLabel_3.Text = 'Select path';
            app.DutyCyclePanel = uipanel(app.QCTab);
            app.DutyCyclePanel.Title = 'Duty Cycle';
            app.DutyCycleSwitchLabel = uilabel(app.DutyCyclePanel);
            app.DutyCycleSwitchLabel.Text = 'Duty Cycle';
            app.DutyCycleSwitch = uiswitch(app.DutyCyclePanel, 'slider');
            app.DutyCycleSwitch.Items = {'Yes', 'No'};
            app.DutyCycleSwitch.ValueChangedFcn = createCallbackFcn(app, @DutyCycleSwitchValueChanged, true);
            app.DutyCycleSwitch.Value = 'No';
            app.IntervalsecEditFieldLabel = uilabel(app.DutyCyclePanel);
            app.IntervalsecEditFieldLabel.Text = 'Interval (sec)';
            app.IntervalsecEditField = uieditfield(app.DutyCyclePanel, 'numeric');
            app.IntervalsecEditField.Limits = [0 Inf];
            app.IntervalsecEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalsecEditFieldValueChanged, true);
            app.IntervalsecEditField.Editable = 'off';
            app.IntervalsecEditField.Enable = 'off';
            app.IntervalsecEditField.Tooltip = {'enter measurement interval in seconds'};
            app.DC_Text = uilabel(app.DutyCyclePanel);
            app.DC_Text.Text = 'Record for a period of ... min once every ... min';
            app.Image = uiimage(app.QCTab);
            app.Image.ImageSource = 'index2.png';
            app.RUNButton = uibutton(app.QCTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Tooltip = {'run quality control and save results to pdf'};
            app.RUNButton.Text = 'RUN';
            app.FlagFilesButton = uibutton(app.QCTab, 'push');
            app.FlagFilesButton.ButtonPushedFcn = createCallbackFcn(app, @FlagFilesButtonPushed, true);
            app.FlagFilesButton.Enable = 'off';
            app.FlagFilesButton.Text = 'Flag Files';
            app.ViewLogFileButton = uibutton(app.QCTab, 'push');
            app.ViewLogFileButton.ButtonPushedFcn = createCallbackFcn(app, @ViewLogFileButtonPushed, true);
            app.ViewLogFileButton.Enable = 'off';
            app.ViewLogFileButton.Text = 'View Log File';
            app.RemoveFlagsButton = uibutton(app.QCTab, 'push');
            app.RemoveFlagsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFlagsButtonPushed, true);
            app.RemoveFlagsButton.Enable = 'off';
            app.RemoveFlagsButton.Text = 'Remove Flags';
            app.GetdatetimePanel_2 = uipanel(app.QCTab);
            app.GetdatetimePanel_2.Title = 'Get datetime';
            app.TEXT_2 = uilabel(app.GetdatetimePanel_2);
            app.TEXT_2.Text = {'The data of the first file in the source directory is:'; 'yymmddHHMMSS'};
            app.FiledateoffirstfileiscorrectCheckBox_2 = uicheckbox(app.GetdatetimePanel_2);
            app.FiledateoffirstfileiscorrectCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @FiledateoffirstfileiscorrectCheckBox_2ValueChanged, true);
            app.FiledateoffirstfileiscorrectCheckBox_2.Text = 'Filedate of first file is correct ';
            app.Lamp_11 = uilamp(app.GetdatetimePanel_2);
            app.FiledateEditField_2Label = uilabel(app.GetdatetimePanel_2);
            app.FiledateEditField_2Label.Text = 'Filedate';
            app.FiledateEditField_2 = uieditfield(app.GetdatetimePanel_2, 'text');
            app.FiledateEditField_2.ValueChangedFcn = createCallbackFcn(app, @FiledateEditField_2ValueChanged, true);
            app.FiledateEditField_2.Value = '-----yyMMddHHmmss.wav';
            app.Copy2ArchiveTab = uitab(app.TabGroup);
            app.Copy2ArchiveTab.Title = 'Copy 2 Archive';
            app.SettingsPanel_2 = uipanel(app.Copy2ArchiveTab);
            app.SettingsPanel_2.Title = 'Settings';
            app.SeaDropDownLabel = uilabel(app.SettingsPanel_2);
            app.SeaDropDownLabel.Text = 'Sea';
            app.SeaDropDown = uidropdown(app.SettingsPanel_2);
            app.SeaDropDown.Items = {'Select Sea', 'NORDSEE', 'OSTSEE'};
            app.SeaDropDown.ValueChangedFcn = createCallbackFcn(app, @SeaDropDownValueChanged, true);
            app.SeaDropDown.Value = 'Select Sea';
            app.StationDropDownLabel = uilabel(app.SettingsPanel_2);
            app.StationDropDownLabel.Text = 'Station';
            app.StationDropDown = uidropdown(app.SettingsPanel_2);
            app.StationDropDown.Items = {'Select Station', 'ES1', 'FN1', 'FN3'};
            app.StationDropDown.ValueChangedFcn = createCallbackFcn(app, @StationDropDownValueChanged, true);
            app.StationDropDown.Value = 'Select Station';
            app.ChannelDropDownLabel = uilabel(app.SettingsPanel_2);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.SettingsPanel_2);
            app.ChannelDropDown.Items = {'Select Channel', 'H1', 'H2', 'STEREO'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'Select Channel';
            app.SearchButton = uibutton(app.SettingsPanel_2, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.Lamp = uilamp(app.SettingsPanel_2);
            app.Lamp2 = uilamp(app.SettingsPanel_2);
            app.Lamp_3 = uilamp(app.SettingsPanel_2);
            app.Lamp_4 = uilamp(app.SettingsPanel_2);
            app.TargetFolderDropDownLabel = uilabel(app.SettingsPanel_2);
            app.TargetFolderDropDownLabel.Text = 'Target Folder';
            app.TargetFolderDropDown = uidropdown(app.SettingsPanel_2);
            app.TargetFolderDropDown.Items = {'Select...', 'Data09', 'Data10', 'Data11', 'Data12'};
            app.TargetFolderDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetFolderDropDownValueChanged, true);
            app.TargetFolderDropDown.Value = 'Select...';
            app.Lamp_5 = uilamp(app.SettingsPanel_2);
            app.TargetDirectoryDropDownLabel_2 = uilabel(app.SettingsPanel_2);
            app.TargetDirectoryDropDownLabel_2.Text = 'Source Directory';
            app.COPYButton = uibutton(app.Copy2ArchiveTab, 'push');
            app.COPYButton.ButtonPushedFcn = createCallbackFcn(app, @COPYButtonPushed, true);
            app.COPYButton.Enable = 'off';
            app.COPYButton.Text = 'COPY';
            app.ControlPanel = uipanel(app.Copy2ArchiveTab);
            app.ControlPanel.Title = 'Control';
            app.FiledateoffirstfileiscorrectCheckBox = uicheckbox(app.ControlPanel);
            app.FiledateoffirstfileiscorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @FiledateoffirstfileiscorrectCheckBoxValueChanged, true);
            app.FiledateoffirstfileiscorrectCheckBox.Text = 'Filedate of first file is correct ';
            app.Lamp_2 = uilamp(app.ControlPanel);
            app.Image2 = uiimage(app.Copy2ArchiveTab);
            app.Image2.ImageSource = 'index2.png';
            app.GetdatetimePanel = uipanel(app.Copy2ArchiveTab);
            app.GetdatetimePanel.Title = 'Get datetime';
            app.FiledateEditFieldLabel = uilabel(app.GetdatetimePanel);
            app.FiledateEditFieldLabel.Text = 'Filedate';
            app.FiledateEditField = uieditfield(app.GetdatetimePanel, 'text');
            app.FiledateEditField.ValueChangedFcn = createCallbackFcn(app, @FiledateEditFieldValueChanged, true);
            app.FiledateEditField.Editable = 'off';
            app.FiledateEditField.Enable = 'off';
            app.FiledateEditField.Value = '-----yymmdd------.wav';
            app.TEXT = uilabel(app.GetdatetimePanel);
            app.TEXT.Text = {'The data of the first file in the source directory is:'; 'yymmdd'};
            app.ClearoriginaldataPanel = uipanel(app.Copy2ArchiveTab);
            app.ClearoriginaldataPanel.Title = 'Clear original data';
            app.ViewTransferlogButton = uibutton(app.ClearoriginaldataPanel, 'push');
            app.ViewTransferlogButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTransferlogButtonPushed, true);
            app.ViewTransferlogButton.Enable = 'off';
            app.ViewTransferlogButton.Text = 'View Transferlog';
            app.DELETEButton = uibutton(app.ClearoriginaldataPanel, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Enable = 'off';
            app.DELETEButton.Tooltip = {'THIS WILL DELETE THE ORIGINAL DATA'};
            app.DELETEButton.Text = 'DELETE ';
            app.Lamp_8 = uilamp(app.ClearoriginaldataPanel);
            app.Lamp_9 = uilamp(app.ClearoriginaldataPanel);
            app.SURESwitchLabel = uilabel(app.ClearoriginaldataPanel);
            app.SURESwitchLabel.Text = 'SURE ???';
            app.SURESwitch = uiswitch(app.ClearoriginaldataPanel, 'toggle');
            app.SURESwitch.Items = {'No', 'Yes'};
            app.SURESwitch.ValueChangedFcn = createCallbackFcn(app, @SURESwitchValueChanged, true);
            app.SURESwitch.Value = 'No';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.Panel = uipanel(app.CalibrationTab);
            app.Panel.Title = 'Panel';
            app.SearchButton_2 = uibutton(app.Panel, 'push');
            app.SearchButton_2.ButtonPushedFcn = createCallbackFcn(app, @SearchButton_2Pushed, true);
            app.SearchButton_2.Text = 'Search';
            app.TargetDirectoryDropDownLabel_3 = uilabel(app.Panel);
            app.TargetDirectoryDropDownLabel_3.Text = 'Source Directory';
            app.Lamp_10 = uilamp(app.Panel);
            app.UIAxes = uiaxes(app.CalibrationTab);
            title(app.UIAxes, 'Calibration Signal')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'amplitude')
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.YTick = [-1 -0.5 0 0.5 1];
            app.UIAxes.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'};
            app.BSoundHTab = uitab(app.TabGroup);
            app.BSoundHTab.Title = 'BSoundH';
            app.OpenBSoundHButton = uibutton(app.BSoundHTab, 'push');
            app.OpenBSoundHButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBSoundHButtonPushed, true);
            app.OpenBSoundHButton.Text = 'Open BSoundH';
            app.Image4 = uiimage(app.BSoundHTab);
            app.Image4.ImageSource = 'rolf.png';
            app.TEXT_3 = uilabel(app.BSoundHTab);
            app.TEXT_3.Text = 'Processing in BSoundH';
            app.TEXT_4 = uilabel(app.BSoundHTab);
            app.TEXT_4.Text = 'Remember the big 5';
            app.TEXT_5 = uilabel(app.BSoundHTab);
            app.TEXT_5.Text = '1. Import one month of data by selecting the folder';
            app.TEXT_6 = uilabel(app.BSoundHTab);
            app.TEXT_6.Text = '2. Set start date from file name and set files as fixed and alligned';
            app.TEXT_7 = uilabel(app.BSoundHTab);
            app.TEXT_7.Text = '3. Set calibration factor';
            app.TEXT_8 = uilabel(app.BSoundHTab);
            app.TEXT_8.Text = '4. Start Ambient Evaluation';
            app.TEXT_9 = uilabel(app.BSoundHTab);
            app.TEXT_9.Text = '5. Export data (don''t forget to validate)';
            % Create 
            app. = uitab(app.TabGroup);
            app.SurenessGaugeLabel = uilabel(app.UIFigure);
            app.SurenessGaugeLabel.Text = 'Sureness [%]';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @SpaceSnifferButtonPushed, true);
            app.Image3.Tooltip = {'SPACESNIFFER'};
            app.Image3.ImageSource = 'snifferdog.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end